classdef App_Ent_Backpropagation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        EntrenamientoTab                matlab.ui.container.Tab
        ErrorGeneralEditField           matlab.ui.control.NumericEditField
        PrecisionClaficicacinLabel      matlab.ui.control.Label
        SeleccionarrutaButton           matlab.ui.control.Button
        PrecisinEditField               matlab.ui.control.NumericEditField
        PrecisinEditFieldLabel          matlab.ui.control.Label
        BetaEditFieldLabel              matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.NumericEditField
        AlphaEditField                  matlab.ui.control.NumericEditField
        AlphaEditField_2Label           matlab.ui.control.Label
        SalidasdeseadasEditField        matlab.ui.control.EditField
        SalidasdeseadasEditFieldLabel   matlab.ui.control.Label
        EntradasEditField               matlab.ui.control.EditField
        EntradasEditFieldLabel          matlab.ui.control.Label
        ErrorFinalEditField             matlab.ui.control.NumericEditField
        ErrorFinalEditFieldLabel        matlab.ui.control.Label
        pocasEditField                  matlab.ui.control.NumericEditField
        pocasEditFieldLabel             matlab.ui.control.Label
        CasoaEntrenarDropDown           matlab.ui.control.DropDown
        CasoaEntrenarDropDownLabel      matlab.ui.control.Label
        MensajesdeestadoLabel           matlab.ui.control.Label
        GuardarPesosenEditField         matlab.ui.control.EditField
        GuardarPesosenEditFieldLabel    matlab.ui.control.Label
        PesosInicialesEditFieldLabel    matlab.ui.control.Label
        PesosInicialesEditField         matlab.ui.control.EditField
        ArquitecturaEditField           matlab.ui.control.EditField
        ArquitecturaEditFieldLabel      matlab.ui.control.Label
        ErrorvspocasLabel               matlab.ui.control.Label
        PesosvspocasLabel               matlab.ui.control.Label
        ArquitecturaLabel               matlab.ui.control.Label
        EntrenarButton                  matlab.ui.control.Button
        SeleccionararchivoButton_3      matlab.ui.control.Button
        SeleccionararchivoButton_2      matlab.ui.control.Button
        SeleccionararchivoButton        matlab.ui.control.Button
        CargadearchivosporfavorseleccioneunarchivoLabel  matlab.ui.control.Label
        DatosdeentrenamientoLabel       matlab.ui.control.Label
        UniversidaddeCundinamarcaLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        EntrenamientoLaboratorio3InteligenciaArtificialLabel  matlab.ui.control.Label
        VerArquitectura                 matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        AplicacinTab                    matlab.ui.container.Tab
        CasoaEstudiarrDropDown          matlab.ui.control.DropDown
        CasoaEstudiarrDropDownLabel     matlab.ui.control.Label
        PesosEditField                  matlab.ui.control.EditField
        PesosEditFieldLabel             matlab.ui.control.Label
        EntradasEditField_2             matlab.ui.control.EditField
        EntradasEditField_2Label        matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        ImagencargadaLabel              matlab.ui.control.Label
        EjecutarButton                  matlab.ui.control.Button
        SeleccionararchivoButton_5      matlab.ui.control.Button
        SeleccionararchivoButton_4      matlab.ui.control.Button
        UniversidaddeCundinamarcaLabel_2  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        AplicacinLaboratorio3InteligenciaArtificialLabel  matlab.ui.control.Label
        ImagenCargada                   matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        NeuralNetwork       BP_neuronal_network % Instancia de la red
        XTrainData          % Datos de entrada para entrenamiento [NumFeatures x NumPatrones]
        YTrainTargets       % Salidas deseadas para entrenamiento [NumOutputs x NumPatrones]
        
        % Parámetros (pueden leerse de la UI directamente, pero tenerlos aquí es útil)
        CurrentArchitecture % Arquitectura actual [1 x NumCapas]
        CurrentAlpha
        CurrentBeta
        CurrentPrecision
        
        ImageSize = [28 28]; % Tamaño al que se redimensionarán las imágenes
        UseRGB = false;      % Usar imágenes en escala de grises por defecto
        NumFeatures         % Calculado en base a ImageSize y UseRGB
        NumOutputs          % Calculado en base a Vowel+Color o solo Vowel
        NumClassesVowel = 5; 
        NumClassesColor = 3; % Rojo, Verde, Azul
        VowelMap = {'A', 'E', 'I', 'O', 'U'}; 
        ColorMap = {'Rojo', 'Verde', 'Azul'};
            
        TrainingHistory     % Para guardar historial completo si es necesario
        ErrorLine           % Handle a la línea animada para el error
        TrainingHistoryError
        TrainingHistoryPesoCells
        TrainingHistorySesgoCells
        SavedEpochs
        AppInputVector
        AppMatrixVector
        
        % Paths (leídos de la UI)
        InputFolderTrain    % Carpeta de imágenes (Entrenamiento Imagen)
        InputFileTrain      % Archivo de entrada (Entrenamiento Matriz)
        TargetFileTrain     % Archivo de salidas deseadas .txt (Ambos entrenamientos)
        InitialWeightsFileTrain % Archivo de pesos iniciales opcional (Entrenamiento)
        WeightsOutputFileTrain  % Archivo donde guardar pesos entrenados (Entrenamiento)
                
        InputFileApp        % Archivo de entrada (Aplicación)
        WeightsFileApp      % Archivo de pesos a cargar (Aplicación)
        LoadedAppNetwork    BP_neuronal_network % Red cargada en la pestaña Aplicació
        PlottedArchitecture = []; % Almacena la última arquitectura dibujada en VerArquitectura
    end
    
    methods (Access = private)
        
        % --- Helper: Mostrar Alerta de Error (CORREGIDO) ---
        function showErrorAlert(app, errorInfo, title)
            if nargin < 3; title = 'Error'; end 
            
            errorMessage = 'Ocurrió un error desconocido.'; % Default
            stackTraceAvailable = false;
            stackInfo = struct('name', 'N/A', 'line', 0); % Default

            % --- LÓGICA REVISADA Y CORRECTA ---
            if isa(errorInfo, 'MException') % SI ES UN OBJETO DE ERROR ESTÁNDAR
                if isprop(errorInfo, 'message') && ~isempty(errorInfo.message) % Verificar propiedad message
                    errorMessage = errorInfo.message;
                else
                     fprintf('WARN: MException no tiene mensaje o está vacío!\n'); 
                end
                % Verificar stack 
                if isprop(errorInfo, 'stack') && ~isempty(errorInfo.stack) 
                    stackTraceAvailable = true;
                    stackInfo = errorInfo.stack(1);
                end
            elseif isstruct(errorInfo) && isfield(errorInfo, 'message') % SI ES EL STRUCT MANUAL
                 if ~isempty(errorInfo.message)
                     errorMessage = errorInfo.message;
                 end
                 % No hay stack para structs manuales
            else % Otro tipo, intentar convertir
                 try errorMessage = char(errorInfo); catch; end 
            end
            % --- FIN LÓGICA REVISADA ---

            fprintf('ERROR DETECTADO (%s): %s\n', title, errorMessage); % Imprimir error real
            if stackTraceAvailable
                 try fprintf('  en %s (línea %d)\n', stackInfo.name, stackInfo.line); catch; fprintf('  (Stack info error)\n'); end
            else
                 fprintf('  (Sin stack disponible)\n');
            end

            % Actualizar etiquetas de estado (LLAMA A setStatus CORRECTAMENTE)
            app.setStatus(app.MensajesdeestadoLabel, ['Error: ' errorMessage]); % Actualiza MensajesdeestadoLabel
            app.setStatus(app.Label_5, ['Error: ' errorMessage]); % Actualiza Label_5 (puede ser redundante, pero seguro)
            try % Mostrar uialert con el mensaje correcto
                if isvalid(app.UIFigure); uialert(app.UIFigure, errorMessage, title, 'Icon','error', 'Modal', true); else; errordlg(errorMessage, title); end
            catch; errordlg(errorMessage, title); end
            
            % Actualizar etiquetas de estado
            try; if isprop(app, 'MensajesdeestadoLabel') && isvalid(app.MensajesdeestadoLabel); app.setStatus(app.MensajesdeestadoLabel, ['Error: ' errorMessage]); end; catch; end 
            try; if isprop(app, 'Label_5') && isvalid(app.Label_5); app.setStatus(app.Label_5, ['Error: ' errorMessage]); end; catch; end 
            
        end

        % --- Helper: Actualizar Status Label --- %%% ASEGÚRATE DE TENER ESTA TAMBIÉN %%%
        function setStatus(app, labelHandle, message) 
             % labelHandle aquí DEBE ser el objeto componente real (e.g., app.MensajesdeestadoLabel)
             if isgraphics(labelHandle) && isvalid(labelHandle) 
                 labelHandle.Text = message;
             else
                 disp(['Status (Label no válido): ', message]); 
             end
             drawnow('limitrate'); 
        end

        % --- Helper: Mostrar Alertas de Error ---
        function showError(app, message, title)
            if nargin < 3; title = 'Error'; end
            uialert(app.UIFigure, message, title, 'Icon', 'error');
            app.MensajesdeestadoLabel.Text = ['Error: ' message]; % Mostrar en etiqueta de estado
            drawnow; % Asegurar que se muestre
        end
    
        % --- Helper: Mostrar Mensajes de Estado ---
         function showStatus(app, message, tab) % tab es 'entrenamiento' o 'aplicacion'
             if strcmpi(tab, 'entrenamiento')
                 labelHandle = app.MensajesdeestadoLabel; %%% USA MensajesdeestadoLabel
             elseif strcmpi(tab, 'aplicacion')
                 labelHandle = app.Label_5; %%% USA Label_5
             else
                 disp(message); return; % Fallback
             end
        
             if isgraphics(labelHandle) && isvalid(labelHandle) 
                 labelHandle.Text = message;
             else
                 disp(['Status (Label no válido): ', message]); 
             end
             drawnow('limitrate'); 
         end

         function calculateNetworkIOFeatures(app, mode) 
            % Calcula NumFeatures y NumOutputs basado en modo y propiedades
            if nargin < 2 % Si no se especifica modo, usar el del DropDown de Entrenamiento
                try % Puede que se llame antes de que el dropdown exista
                    mode = app.CasoaEntrenarDropDown.Value; 
                catch
                    mode = 'Imagenes'; % Default inicial
                end
            end 
            
            try % Usar try-catch por si UseRGB no existe aún
                 use_rgb_flag = app.UseRGB;
            catch
                 use_rgb_flag = false; % Default a gris si la propiedad no existe
            end

            % Calcular NumFeatures según el modo
            if strcmpi(mode, 'Imagenes')
            if use_rgb_flag; numChannels =3; else; numChannels =1; end
                 
                app.NumFeatures = prod(app.ImageSize) * numChannels;
            elseif strcmpi(mode, 'Matriz')
                app.NumFeatures = app.MatrixInputSize; % Usa el tamaño definido para matriz
            else
                 app.NumFeatures = NaN; % Modo desconocido
                 warning('Modo "%s" no reconocido para calcular NumFeatures.', mode);
            end

            % Calcular NumOutputs (igual para ambos modos en este diseño)
            app.NumOutputs = app.NumClassesVowel + app.NumClassesColor; 
            
            % Mostrar en consola (opcional)
            % fprintf('Calculado para modo "%s": %d Features Entrada, %d Outputs.\n', mode, app.NumFeatures, app.NumOutputs);

        end
    
        % --- Helper: Preprocesamiento de Imagen (existente, asegurar NumFeatures) ---
        function imgVector = preprocessImage(app, filepath) 
            imgVector = []; % Inicializar vacío
            fprintf('--- Entrando a preprocessImage para: %s ---\n', filepath); 
            try
                img = imread(filepath);
                fprintf('Imagen leída OK (Tamaño original: %s).\n', mat2str(size(img))); 
            catch ME
                error('No se pudo leer imagen: %s. %s', filepath, ME.message); 
            end
            
            try % Englobar redimension/conversión
                imgResized = imresize(img, app.ImageSize); 
                imgDouble = im2double(imgResized);
                fprintf('Imagen redimensionada y convertida a double (Tamaño: %s).\n', mat2str(size(imgDouble)));
            catch ME_proc
                 error('Error en imresize o im2double para %s: %s', filepath, ME_proc.message);
            end

            % --- Lógica RGB/Gris y Cálculo de Features ---
            finalMatrixToReshape = []; 
            expectedNumFeatures = 0; % Variable local para comparación

            try % Englobar lógica de canales
                if app.UseRGB % ESPERANDO RGB (3 canales)
                    expectedNumFeatures = prod(app.ImageSize) * 3;
                    currentChannels = size(imgDouble, 3);
                    fprintf('Modo RGB: Esperando %d features. Canales leídos: %d.\n', expectedNumFeatures, currentChannels); % DEBUG
                    
                    if currentChannels == 1 % SI ES GRIS -> Convertir a RGB
                        fprintf('Convirtiendo imagen gris a RGB...\n'); % DEBUG
                        finalMatrixToReshape = cat(3, imgDouble, imgDouble, imgDouble); 
                    elseif currentChannels == 3 % SI YA ES RGB -> Usar directamente
                         finalMatrixToReshape = imgDouble;
                    else 
                        error('Canales inesperados (%d) para modo RGB.', currentChannels); 
                    end
                else % ESPERANDO GRISESCALA (1 canal)
                    expectedNumFeatures = prod(app.ImageSize) * 1;
                    currentChannels = size(imgDouble, 3);
                     fprintf('Modo Grisescala: Esperando %d features. Canales leídos: %d.\n', expectedNumFeatures, currentChannels); % DEBUG

                     if currentChannels == 3 % SI ES COLOR -> Convertir a Gris
                         fprintf('Convirtiendo imagen RGB a Grisescala...\n'); % DEBUG
                         finalMatrixToReshape = rgb2gray(imgDouble); 
                     elseif currentChannels == 1 % SI YA ES GRIS -> Usar directamente
                          finalMatrixToReshape = imgDouble;
                     else
                         error('Canales inesperados (%d) para modo Grisescala.', currentChannels); 
                     end
                end
            catch ME_chan
                 error('Error manejando canales de imagen: %s', ME_chan.message);
            end

            % --- VERIFICACIÓN ANTES DE RESHAPE ---
            numElementsActual = numel(finalMatrixToReshape);
            fprintf('DEBUG Pre-Reshape: numel(matriz final)=%d vs expectedNumFeatures=%d\n', numElementsActual, expectedNumFeatures); % DEBUG
            
            if numElementsActual ~= expectedNumFeatures
                 msg = sprintf('¡DISCREPANCIA! #Elementos (%d) != Features Esperados (%d). Revisar ImageSize [%d %d] y modo UseRGB=%d.', ...
                                numElementsActual, expectedNumFeatures, app.ImageSize(1), app.ImageSize(2), app.UseRGB);
                 error(msg); % Lanzar error claro
            end
            % Si los tamaños coinciden, actualizamos la propiedad global ANTES de reshape
            app.NumFeatures = expectedNumFeatures; 
            % --- FIN VERIFICACIÓN ---

            try % Englobar reshape final
                imgVector = reshape(finalMatrixToReshape, app.NumFeatures, 1); 
                fprintf('--- preprocessImage: Reshape OK. Tamaño vector salida: %d x %d ---\n', size(imgVector,1), size(imgVector,2)); 
            catch ME_reshape
                 % Este catch ahora es menos probable si la verificación anterior pasa
                 fprintf('ERROR INESPERADO EN RESHAPE: %s\n', ME_reshape.message);
                 fprintf('Intentando reshape(matriz(numel=%d), [%d, 1])\n', numel(finalMatrixToReshape), app.NumFeatures);
                 rethrow(ME_reshape); % Relanzar para que lo capture processImageFolder
            end
        end
        
        % --- Helper: Cargar y Validar Datos de Entrenamiento (IMAGEN) ---
        function [success, XData, YData, nFeatures, nOutputs] = loadImageData(app)
            success = false; XData = []; YData = []; nFeatures = 0; nOutputs = 0;
            
            folderPath = app.InputFolderTrain; % Usar propiedad de la app
            targetPath = app.TargetFileTrain; % Usar propiedad
            
            if isempty(folderPath) || ~isfolder(folderPath)
                 app.showError('Por favor, selecciona una carpeta válida con las imágenes de entrada.'); return;
            end
             if isempty(targetPath) || ~isfile(targetPath)
                 app.showError('Por favor, selecciona un archivo válido con las salidas deseadas.'); return;
             end
            
            imgFiles = [dir(fullfile(folderPath, '*.png')); dir(fullfile(folderPath, '*.jpg')); dir(fullfile(folderPath, '*.jpeg'))];
            if isempty(imgFiles)
                 app.showError('No se encontraron imágenes (.png, .jpg, .jpeg) en la carpeta seleccionada.'); return;
            end
            
            try
                Y_desired_matrix = readmatrix(targetPath);
                numTargets = size(Y_desired_matrix, 1);
                nOutputs = size(Y_desired_matrix, 2); % Número de columnas es el número de salidas
                
                if numTargets ~= length(imgFiles)
                    app.showError(sprintf('El número de imágenes (%d) no coincide con el número de filas en el archivo de salidas deseadas (%d).', length(imgFiles), numTargets));
                    return;
                end
                
                app.showStatus(sprintf('Cargando y procesando %d imágenes...', length(imgFiles)), 'entrenamiento');
                
                % Pre-alocar memoria (si es posible)
                firstImgVector = app.preprocessImage(fullfile(imgFiles(1).folder, imgFiles(1).name));
                nFeatures = length(firstImgVector);
                XData = zeros(nFeatures, length(imgFiles));
                YData = zeros(nOutputs, length(imgFiles)); % Usar Y leída directamente
                
                XData(:, 1) = firstImgVector;
                YData(:, 1) = Y_desired_matrix(1, :)'; % Transponer fila a columna
                
                for i = 2:length(imgFiles)
                     if mod(i,10) == 0; app.showStatus(sprintf('Procesando imagen %d de %d...', i, length(imgFiles)), 'entrenamiento'); end
                     imgPath = fullfile(imgFiles(i).folder, imgFiles(i).name);
                     XData(:, i) = app.preprocessImage(imgPath);
                     YData(:, i) = Y_desired_matrix(i, :)'; % Transponer fila a columna
                     % Verificar consistencia de características (opcional pero bueno)
                     if size(XData, 1) ~= nFeatures
                         app.showError(sprintf('Inconsistencia en tamaño de vector de imagen para %s.', imgFiles(i).name)); return;
                     end
                end
                
                app.NumFeatures = nFeatures; % Actualizar por si acaso
                app.NumOutputs = nOutputs;   % Actualizar con lo leído del archivo
                success = true;
                app.showStatus(sprintf('Carga de %d imágenes y %d salidas completada.', size(XData,2), size(YData,2)), 'entrenamiento');
                
            catch ME
                app.showError(['Error cargando datos de imagen/objetivo: ' ME.message]);
                success = false;
            end
        end
    
        % --- Helper: Cargar y Validar Datos de Entrenamiento (MATRIZ) ---
        function [success, XData, YData, nFeatures, nOutputs] = loadMatrixData(app)
            success = false; XData = []; YData = []; nFeatures = 0; nOutputs = 0;
            
            inputFile = app.InputFileTrain; % Usar propiedad
            targetFile = app.TargetFileTrain; % Usar propiedad
            
            if isempty(inputFile) || ~isfile(inputFile)
                app.showError('Por favor, selecciona un archivo .txt válido con las entradas (vectores binarios).'); return;
            end
            if isempty(targetFile) || ~isfile(targetFile)
                app.showError('Por favor, selecciona un archivo .txt válido con las salidas deseadas.'); return;
            end
            
            try
                % Leer entradas (strings binarios)
                lines = readlines(inputFile);
                lines = lines(~startsWith(lines, '%') & strlength(lines) > 0); % Ignorar comentarios y líneas vacías
                numPatterns = length(lines);
                if numPatterns == 0
                    app.showError('El archivo de entrada de matrices está vacío o no contiene datos válidos.'); return;
                end
                nFeatures = strlength(lines(1)); % Longitud del primer vector determina características
                
                % Convertir strings a matriz numérica
                XData = zeros(nFeatures, numPatterns);
                for i = 1:numPatterns
                    if strlength(lines(i)) ~= nFeatures
                        app.showError(sprintf('Error en archivo de entrada: La línea %d tiene %d caracteres, se esperaban %d.', i, strlength(lines(i)), nFeatures)); return;
                    end
                    XData(:, i) = double(lines(i)' == '1'); % Convertir '0'/'1' a 0/1 numérico
                end
                
                % Leer salidas deseadas
                YDataMatrix = readmatrix(targetFile);
                if size(YDataMatrix, 1) ~= numPatterns
                     app.showError(sprintf('El número de patrones de entrada (%d) no coincide con las filas de salidas deseadas (%d).', numPatterns, size(YDataMatrix,1))); return;
                end
                nOutputs = size(YDataMatrix, 2);
                YData = YDataMatrix'; % Transponer a [nOutputs x numPatterns]
                
                app.NumFeatures = nFeatures;
                app.NumOutputs = nOutputs;
                success = true;
                app.showStatus(sprintf('Carga de %d vectores de entrada y %d salidas completada.', size(XData,2), size(YData,2)), 'entrenamiento');
                
            catch ME
                 app.showError(['Error cargando datos de matriz/objetivo: ' ME.message]);
                 success = false;
            end
        end
        
        % --- Helper: Configurar Funciones de Activación ---
        % Necesitamos una forma de definir qué funciones usar.
        % Podríamos tener DropDowns en la GUI o definirlo aquí basado en la capa.
        function [funcHandles, derivHandles] = getActivationFunctions(app, numHiddenLayers)
            % Ejemplo: Tanh para ocultas, Sigmoid (logsig) para salida
            % Asume numHiddenLayers = obj.NumCapas - 2
            
            funcHandles = cell(1, numHiddenLayers + 1);
            derivHandles = cell(1, numHiddenLayers + 1);
            
            % Funciones para capas ocultas (si existen)
            for i = 1:numHiddenLayers
                funcHandles{i} = '@(net) tansig(net)'; 
                % Derivada de tansig(net) en función de la *activación* a = tansig(net)
                % d(tansig)/dnet = 1 - tansig(net)^2 = 1 - a^2
                derivHandles{i} = '@(act) 1 - act.^2'; 
            end
            
            % Función para la capa de salida
            funcHandles{numHiddenLayers + 1} = '@(net) logsig(net)';
            % Derivada de logsig(net) en función de la *activación* a = logsig(net)
            % d(logsig)/dnet = logsig(net) * (1 - logsig(net)) = a .* (1 - a)
            derivHandles{numHiddenLayers + 1} = '@(act) act .* (1 - act)';
            
            fprintf('Funciones de Activación Config.: %s (Ocultas), %s (Salida)\n', funcHandles{1}, funcHandles{end});
        end
    
        % --- Helper: Visualizar Arquitectura (adaptado de tu código) ---
        function plotArchitecture(app, axesHandle, architecture)
            if isempty(architecture) || ~isgraphics(axesHandle)
                cla(axesHandle); title(axesHandle, 'Arquitectura No Definida'); axis(axesHandle, 'off');
                return;
            end
            cla(axesHandle);
            hold(axesHandle, 'on');
            numLayers = length(architecture);
            layerNodes = architecture;
            xCoords = 1:numLayers;
            yMax = max(layerNodes);
            nodeCoords = cell(1, numLayers);
            colors = {'#7E2F8E', '#0072BD', '#EDB120', '#77AC30', '#4DBEEE'}; 
        
            % --- Dibujar Nodos (igual que antes) ---
            for i = 1:numLayers
                numNodes = layerNodes(i);
                yCoords = linspace(0.1*yMax, 0.9*yMax, numNodes);
                if numNodes == 1; yCoords = 0.5*yMax; end
                nodeCoords{i} = [repmat(xCoords(i), numNodes, 1), yCoords'];
                colorIdx = mod(i-1, length(colors)) + 1;
                plot(axesHandle, nodeCoords{i}(:,1), nodeCoords{i}(:,2), 'o', 'MarkerSize', 8, 'MarkerFaceColor', colors{colorIdx}, 'MarkerEdgeColor', 'k'); % Tamaño marcador reducido opcionalmente
            end
        
            % Solo dibujar conexiones a partir de la PRIMERA capa oculta (índice i=2)
            % hacia adelante.
            fprintf('Dibujando conexiones optimizadas...\n'); % Mensaje para saber que entra aquí
            for i = 2:(numLayers - 1) % Empezar bucle en i=2 (primera oculta)
                nodesFrom = nodeCoords{i};   % Nodos de la capa actual (oculta i-1)
                nodesTo = nodeCoords{i+1}; % Nodos de la capa siguiente (oculta i o salida)
                maxLinesToPlot = 1000; % Límite para evitar lentitud incluso entre capas ocultas/salida
                numLinesPossible = size(nodesFrom, 1) * size(nodesTo, 1);
        
                if numLinesPossible > maxLinesToPlot && numLinesPossible > 0 % Evitar división por cero si no hay nodos
                    fprintf('INFO: Demasiadas conexiones entre capa %d y %d (%d). Submostreando para visualización.\n', i, i+1, numLinesPossible);
                    % Estrategia simple: Tomar una fracción de los nodos origen/destino
                    stepFrom = max(1, floor(size(nodesFrom, 1) / sqrt(maxLinesToPlot)));
                    stepTo = max(1, floor(size(nodesTo, 1) / sqrt(maxLinesToPlot)));
                    nodesFromIdx = 1:stepFrom:size(nodesFrom, 1);
                    nodesToIdx = 1:stepTo:size(nodesTo, 1);
                    fprintf('Mostrando conexiones cada %d nodos origen y cada %d nodos destino.\n', stepFrom, stepTo);
                else
                    nodesFromIdx = 1:size(nodesFrom, 1);
                    nodesToIdx = 1:size(nodesTo, 1);
                end
                for n1_idx = 1:length(nodesFromIdx)
                    n1 = nodesFromIdx(n1_idx); % Índice real en nodesFrom
                    for n2_idx = 1:length(nodesToIdx)
                        n2 = nodesToIdx(n2_idx); % Índice real en nodesTo
                        % Dibujar la línea
                        line(axesHandle, [nodesFrom(n1, 1), nodesTo(n2, 1)], [nodesFrom(n1, 2), nodesTo(n2, 2)], 'Color', [0.8 0.8 0.8], 'LineWidth', 0.5);
                    end
                end
                 drawnow('limitrate'); % Permitir actualizaciones UI durante dibujo largo (si aplica)
            end
            fprintf('...Conexiones dibujadas.\n');
            % --- Fin Dibujar Conexiones ---
        
            % --- Configuración final del eje (igual que antes) ---
            layerLabels = cell(1, numLayers);
            layerLabels{1} = sprintf('Entrada\n(%d)', layerNodes(1));
            for i = 2:numLayers-1; layerLabels{i} = sprintf('Oculta %d\n(%d)', i-1, layerNodes(i)); end % Etiqueta corregida
            layerLabels{numLayers} = sprintf('Salida\n(%d)', layerNodes(end));
        
            xticks(axesHandle, xCoords);
            xticklabels(axesHandle, layerLabels);
            ylim(axesHandle, [0 yMax * 1.1]);
            set(axesHandle, 'YTick', []);
            title(axesHandle, 'Arquitectura de la Red (Conexiones Optimizadas)');
            hold(axesHandle, 'off');
        end

        
        % --- Helper: Plotear Historial de Pesos (Norma) (existente) ---
         function plotWeightHistory(app, axesHandle, history_pesos, history_sesgos, savedEpochs)
            % Limpia y prepara
            cla(axesHandle); hold(axesHandle,'on'); grid(axesHandle,'on');
            title(axesHandle, 'Evolución Norma de Pesos y Sesgos');
            xlabel(axesHandle, 'Época'); ylabel(axesHandle, 'Norma');
        
            nW = numel(history_pesos);
            nB = numel(history_sesgos);
            if isempty(savedEpochs) || (nW==0 && nB==0)
                text(0.5,0.5,'No hay historial','HorizontalAlignment','center');
                return;
            end
        
            % Colores automáticos
            colors = lines(max(nW>0)*numel(history_pesos{1}) + max(nB>0)*numel(history_sesgos{1}));
        
            legendEntries = {};
        
            % --- Graficar Pesos ---
            if nW>0 && ~isempty(history_pesos{1})
                nLayersW = numel(history_pesos{1});
                Wnorms = nan(nW,nLayersW);
                for k=1:nW
                    for l=1:nLayersW
                        W = history_pesos{k}{l};
                        if ~isempty(W)
                            Wnorms(k,l) = norm(W,'fro');
                        end
                    end
                end
                plot(axesHandle, savedEpochs, Wnorms, '.-', 'LineWidth',1);
                for l=1:nLayersW
                    legendEntries{end+1} = sprintf('||W_%d||_F', l);
                end
            end
        
            % --- Graficar Sesgos ---
            if nB>0 && ~isempty(history_sesgos{1})
                nLayersB = numel(history_sesgos{1});
                Bnorms   = nan(nB,nLayersB);
                for k=1:nB
                    for l=1:nLayersB
                        b = history_sesgos{k}{l};
                        if ~isempty(b)
                            Bnorms(k,l) = norm(b);  % Euclídea
                        end
                    end
                end
                % Empezamos desde el color index tras W
                offset = nW>0 * nLayersW;
                for l=1:nLayersB
                    idxColor = offset + l;
                    plot(axesHandle, savedEpochs, Bnorms(:,l), '--o', 'LineWidth',1);
                    legendEntries{end+1} = sprintf('||b_%d||_2', l);
                end
            end
        
            legend(axesHandle, legendEntries, 'Location','best');
            axesHandle.Box = 'on';                  % Caja completa
            axesHandle.XAxis.TickDirection = 'out';
            axesHandle.YAxis.TickDirection = 'out';
            x0 = savedEpochs(1);
            x1 = savedEpochs(end);
            axesHandle.XLim = [x0 x1];
            axesHandle.XTick = round(linspace(x0, x1, 10));
            axesHandle.YLimMode = 'auto';
            axesHandle.YTickMode = 'auto';
            hold(axesHandle,'off');
        end
        
        % --- Helper: Visualizar Vector como Matriz (Para modo Matriz) ---
        function displayVectorAsMatrix(app, axesHandle, vectorData)
             cla(axesHandle); % Limpiar ejes
             if isempty(vectorData) || ~isnumeric(vectorData) || numel(vectorData) ~= 35
                 title(axesHandle, 'Entrada Matriz No Válida (Req. 35 elem)');
                 axis(axesHandle, 'off');
                 return;
             end
             
             try
                 % Intentar reshape (asumiendo 7 filas, 5 columnas -> 7x5)
                 matrixData = reshape(vectorData, [7, 5]); 
                 
                 % --- MODIFICACIÓN 1: Transponer UNA VEZ antes de usar ---
                 % Ahora matrixToPlot será 5x7 (5 filas, 7 columnas)
                 matrixToPlot = matrixData'; 
                 
                 % Usar la matriz transpuesta directamente en imagesc
                 % imagesc mostrará las filas como Y, columnas como X
                 imagesc(axesHandle, matrixToPlot); 
                 colormap(axesHandle, 'gray');    
                 axis(axesHandle, 'image');       
                 axis(axesHandle, 'off');         
                 % El título ahora refleja las dimensiones VISUALIZADAS (5 filas x 7 cols)
                 title(axesHandle, 'Entrada (Vector 5x7)'); 
                 
                 % Añadir texto de 0s y 1s sobre la imagen
                 hold(axesHandle, 'on');
                 
                 % --- MODIFICACIÓN 2: Obtener tamaño de la matriz ya transpuesta ---
                 [rows, cols] = size(matrixToPlot); % rows = 5, cols = 7
                 
                 for r = 1:rows % Iterar sobre las 5 filas de matrixToPlot
                      for c = 1:cols % Iterar sobre las 7 columnas de matrixToPlot
                          % --- MODIFICACIÓN 3: Indexar la matriz YA transpuesta ---
                          % Acceder al elemento (r,c) de la matriz 5x7
                          valueStr = num2str(matrixToPlot(r, c)); 
                          
                          % Dibujar texto en la posición (columna, fila) de los ejes
                          % text(handle, x_pos, y_pos, string, ...)
                          text(axesHandle, c, r, valueStr, ... 
                               'HorizontalAlignment', 'center', ...
                               'VerticalAlignment', 'middle', ...
                               'Color', 'red', 'FontSize', 8); 
                      end
                 end
                 hold(axesHandle, 'off');
        
             catch ME
                 cla(axesHandle); % Limpiar en caso de error también
                 title(axesHandle, 'Error al visualizar matriz');
                 % Mostrar más detalles del error puede ayudar
                 errorText = sprintf('Error: %s\nFile: %s\nLine: %d', ME.message, ME.stack(1).file, ME.stack(1).line);
                 text(axesHandle, 0.1, 0.5, errorText, 'FontSize', 8, 'Interpreter', 'none');
                 axis(axesHandle, 'off');
                 % También es útil relanzar el error a la consola para depuración
                 fprintf('Error en displayVectorAsMatrix: %s\n', ME.message);
                 rethrow(ME); 
             end
        end
        function [funcStringsRaw, derivStringsRaw, success] = readFunctionsFromFiles(app, funcPath, derivPath, numFuncsNeeded)
            funcStringsRaw = {};
            derivStringsRaw = {};
            success = false;
        
            try
                % leerFunciones devuelve los strings tal cual están en el archivo
                funcStringsRaw = BP_neuronal_network.leerFunciones(funcPath);
                derivStringsRaw = BP_neuronal_network.leerFunciones(derivPath);
                % Validar número de funciones leídas
                if length(funcStringsRaw) < numFuncsNeeded || length(derivStringsRaw) < numFuncsNeeded
                    app.showError(sprintf('Se necesitan %d funciones/derivadas, pero se leyeron %d funciones y %d derivadas de los archivos.', ...
                        numFuncsNeeded, length(funcStringsRaw), length(derivStringsRaw)));
                    % Limpiar para asegurar que no se devuelvan datos parciales
                    funcStringsRaw = {};
                    derivStringsRaw = {};
                    return; 
                end
                
                % Tomar solo las necesarias (si se leyeron de más)
                if length(funcStringsRaw) > numFuncsNeeded
                    funcStringsRaw = funcStringsRaw(1:numFuncsNeeded);
                end
                if length(derivStringsRaw) > numFuncsNeeded
                    derivStringsRaw = derivStringsRaw(1:numFuncsNeeded);
                end
                
                fprintf('Strings RAW leídos desde archivos de funciones (se pasarán así al constructor):\n');
                disp(funcStringsRaw');
                disp(derivStringsRaw');
                success = true;
                app.showStatus('Funciones de activación (raw) leídas desde archivos.', 'entrenamiento');
        
            catch ME
                app.showError(sprintf('Error leyendo archivos de funciones: %s', ME.message));
                funcStringsRaw = {}; % Asegurar salida vacía en error
                derivStringsRaw = {};
                success = false;
            end
        end

        % --- Interpretar Salida de la Red --- %%% AÑADIR ESTA FUNCIÓN %%%
        function [letra, colorStr] = interpretOutput(app, outputVector)

            % --- Validación de Entrada REVISADA ---
            isValid = true; % Flag inicial
            if ~isnumeric(outputVector) % Comprobar si es numérico
                warning('interpretOutput: outputVector no es numérico.');
                isValid = false;
            elseif isempty(outputVector) % Comprobar si está vacío
                 warning('interpretOutput: outputVector está vacío.');
                 isValid = false;
            elseif ndims(outputVector) > 2 || size(outputVector, 2) ~= 1 % Comprobar si NO es vector columna
                 warning('interpretOutput: outputVector no es un vector columna (Tamaño: %s).', mat2str(size(outputVector)));
                 isValid = false;
            elseif size(outputVector, 1) ~= app.NumOutputs % Comprobar número de filas (salidas)
                 warning('interpretOutput: Longitud de outputVector (%d) no coincide con NumOutputs (%d).', size(outputVector,1), app.NumOutputs);
                 isValid = false;
            end

            if ~isValid % Si alguna validación falló
                letra = 'Error'; colorStr = 'Inválido'; 
                return; % Salir de la función
            end
            % --- Fin Validación ---
            try % Añadir try-catch para la indexación por si acaso
                 % Dividir el vector de salida en la parte de vocales y colores
                 vowelScores = outputVector(1:app.NumClassesVowel);
                 colorScores = outputVector(app.NumClassesVowel+1 : end); % El resto son colores

                 % Encontrar el índice con el valor máximo para las vocales
                 [~, vowelIndex] = max(vowelScores);
                 % Asegurarse que el índice es válido para el mapa
                 if vowelIndex >= 1 && vowelIndex <= length(app.VowelMap)
                     letra = app.VowelMap{vowelIndex};
                 else
                     letra = sprintf('IdxV? (%d)', vowelIndex); % Índice vocal inválido
                 end

                 % Encontrar el índice con el valor máximo para los colores
                 [~, colorIndex] = max(colorScores);
                  % Asegurarse que el índice es válido para el mapa
                 if colorIndex >= 1 && colorIndex <= length(app.ColorMap)
                     colorStr = app.ColorMap{colorIndex};
                 else
                     colorStr = sprintf('IdxC? (%d)', colorIndex); % Índice color inválido
                 end
            catch ME_interpret
                 app.showErrorAlert(ME_interpret, 'Error Interpretando Salida');
                 letra = 'Error'; colorStr = 'Proceso';
            end

        end

        % --- Procesar Carpeta de Imágenes (SOLO X) --- %%% AÑADIR ESTA FUNCIÓN %%%
        function processImageFolder(app)
             % Procesa las imágenes en app.InputFolderTrain y guarda los vectores en app.XTrainData
             % ASUME que app.YTrainTargets se cargará por separado desde un archivo .txt
             
             if isempty(app.InputFolderTrain) || ~isfolder(app.InputFolderTrain)
                 error('processImageFolder: Carpeta de imágenes no válida o no seleccionada.'); 
             end
             
             imgFiles = [dir(fullfile(app.InputFolderTrain, '*.jpg')); ...
                         dir(fullfile(app.InputFolderTrain, '*.jpeg')); ...
                         dir(fullfile(app.InputFolderTrain, '*.png'))];
             
             if isempty(imgFiles)
                 error('No se encontraron imágenes (.jpg, .jpeg, .png) en: %s', app.InputFolderTrain);
             end
             
             numFiles = length(imgFiles);
             app.XTrainData = []; % Reiniciar datos X
             app.setStatus(app.MensajesdeestadoLabel, sprintf('Procesando %d imágenes...', numFiles)); % Usa MensajesdeestadoLabel
             drawnow;
             
             % Pre-calcular tamaño para eficiencia (asegura NumFeatures)
             app.calculateNetworkIOFeatures('Imagenes'); 
             if isnan(app.NumFeatures) || app.NumFeatures <= 0
                  error('processImageFolder: No se pudo determinar el número de características para el modo Imágenes.');
             end
              tempX = zeros(app.NumFeatures, numFiles); 
             
             validCount = 0;
             for k = 1:numFiles
                 filepath = fullfile(imgFiles(k).folder, imgFiles(k).name);
                 try
                     imgVector = app.preprocessImage(filepath); % Obtiene vector columna
                     % Verificar tamaño devuelto por preprocessImage
                     if ~isempty(imgVector) && size(imgVector,1) == app.NumFeatures && size(imgVector,2) == 1
                         validCount = validCount + 1;
                         tempX(:, validCount) = imgVector;
                     else
                          warning('Vector inválido devuelto por preprocessImage para %s. Saltando.', imgFiles(k).name);
                     end
                 catch ME
                      warning('Error procesando %s: %s. Saltando archivo.', imgFiles(k).name, ME.message);
                 end
                 % Actualizar estado periódicamente
                 if mod(k, 20) == 0 || k == numFiles 
                      app.setStatus(app.MensajesdeestadoLabel, sprintf('Procesando %d / %d imágenes...', k, numFiles)); 
                 end
             end
             
             % Redimensionar matriz X al número de imágenes válidas procesadas
             if validCount > 0
                 app.XTrainData = tempX(:, 1:validCount);
                 app.setStatus(app.MensajesdeestadoLabel, sprintf('Procesadas %d imágenes válidas. Datos X listos. Cargue las Salidas Deseadas.', validCount));
             else
                 app.XTrainData = []; % Asegurar que esté vacío si no hubo válidas
                 error('No se pudieron procesar imágenes válidas de la carpeta.');
             end
        end
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Limpiar ejes al inicio
             cla(app.VerArquitectura); axis(app.VerArquitectura, 'off'); title(app.VerArquitectura, 'Arquitectura');
             cla(app.UIAxes2); axis(app.UIAxes2, 'off'); title(app.UIAxes2, 'Error vs Épocas');
             cla(app.UIAxes3); axis(app.UIAxes3, 'off'); title(app.UIAxes3, 'Norma Pesos vs Épocas');
             cla(app.ImagenCargada); axis(app.ImagenCargada, 'off'); title(app.ImagenCargada, 'Imagen Aplicación');
             
             % --- Cargar Configuración usando el Método Estático ---
             configFilePath = fullfile(fileparts(mfilename('fullpath')), 'config.txt'); 
             config = BP_neuronal_network.leerConfig(configFilePath); % Llamada al método estático
        
             % --- Establecer Valores en UI (desde config o defaults) ---
             % Usar isfield para comprobar si la clave se leyó correctamente
             if isfield(config, 'alpha')
                app.AlphaEditField.Value = config.alpha;
             else
                app.AlphaEditField.Value = 0.02; % Default
                app.showStatus('Alpha no definido en config.txt, usando default.', 'entrenamiento'); % Mensaje opcional
             end
            
             if isfield(config, 'beta')
                app.BetaEditField.Value = config.beta;
             else
                app.BetaEditField.Value = 0.1;  % Default
             end
        
             if isfield(config, 'precision')
                app.PrecisinEditField.Value = config.precision;
             else
                app.PrecisinEditField.Value = 0.005; % Default
             end
        
             defaultArchNum = []; 
             if isfield(config, 'arquitectura')
                try
                    app.ArquitecturaEditField.Value = mat2str(config.arquitectura);
                    defaultArchNum = config.arquitectura; 
                catch
                    warning('Error al convertir arquitectura leída a string. Usando placeholder.');
                    app.ArquitecturaEditField.Value = '[Error Lectura Config]';
                end
             else
                app.ArquitecturaEditField.Value = '[784 15 8]'; % Default string
                defaultArchNum = [784 15 8]; % Default numérico
             end
        
             % --- Intentar Plotear Arquitectura Inicial ---
             app.PlottedArchitecture = []; % Resetear arquitectura ploteada
             if ~isempty(defaultArchNum)
                 try
                     % Llamar a plotArchitecture directamente (ya no se redibuja si no cambia)
                     app.plotArchitecture(app.VerArquitectura, defaultArchNum); 
                     app.PlottedArchitecture = defaultArchNum; % Guardar como ploteada
                     app.showStatus('Arquitectura inicial cargada/por defecto mostrada.', 'entrenamiento');
                 catch ME_plot
                     warning('No se pudo plotear la arquitectura inicial');
                     title(app.VerArquitectura, 'Arquitectura (Definir y Entrenar)');
                 end
             else
                 title(app.VerArquitectura, 'Arquitectura (No definida en config)');
             end
             app.GuardarPesosenEditField.Value = 'pesos_entrenados.txt';
             app.PesosEditField.Value = 'pesos_entrenados.txt';
    
             % Mostrar imagen por defecto en pestaña Aplicación
             try
                defaultImagePath = fullfile(fileparts(mfilename('fullpath')), 'VOCALES_IMAGE.png');
                if isfile(defaultImagePath)
                    imgData = imread(defaultImagePath);
                    imshow(imgData, 'Parent', app.ImagenCargada);
                    axis(app.ImagenCargada, 'image'); axis(app.ImagenCargada, 'off');
                    title(app.ImagenCargada, 'Imagen de Referencia');
                else
                     title(app.ImagenCargada, 'Imagen Defecto No Encontrada');
                end
             catch % Ignorar errores de imagen por defecto
             end
             app.showStatus('Listo.', 'entrenamiento');
             app.showStatus('Listo.', 'aplicacion');

        end

        % Button pushed function: SeleccionararchivoButton
        function SeleccionararchivoButtonPushed(app, event)
            mode = app.CasoaEntrenarDropDown.Value; 
            app.XTrainData = []; app.YTrainTargets = []; 
            app.InputFolderTrain = ''; app.InputFileTrain = '';   
            app.EntradasEditField.Value = ''; 
            app.setStatus(app.MensajesdeestadoLabel, sprintf('Seleccionando entrada para modo %s...', mode)); %%% CORREGIDO AQUÍ (Línea ~795)
        
            try 
                if strcmpi(mode, 'Imagenes')
                    folderName = uigetdir('', 'Selecciona la CARPETA...'); 
                     if isequal(folderName, 0)
                        app.setStatus(app.MensajesdeestadoLabel, 'Selección carpeta cancelada.'); %%% CORREGIDO
                        return; 
                    else
                        app.EntradasEditField.Value = folderName; 
                        app.InputFolderTrain = folderName; 
                        app.InputFileTrain = ''; 
                        app.setStatus(app.MensajesdeestadoLabel, 'Carpeta seleccionada. Procesando...'); %%% CORREGIDO
                        app.processImageFolder(); 
                        % setStatus se llama dentro de processImageFolder, asegúrate que use app.MensajesdeestadoLabel
                    end
        
                elseif strcmpi(mode, 'Matriz')
                    [fileName, pathName] = uigetfile({'*.txt','Archivos TXT'}, 'Selecciona archivo TXT...');
                    if isequal(fileName, 0)
                        app.setStatus(app.MensajesdeestadoLabel, 'Selección archivo cancelada.'); %%% CORREGIDO
                         return; 
                    else
                        fullPath = fullfile(pathName, fileName);
                        app.EntradasEditField.Value = fullPath;
                         app.InputFileTrain = fullPath; 
                        app.InputFolderTrain = ''; 
                        app.setStatus(app.MensajesdeestadoLabel, 'Archivo seleccionado. Cargando vectores...'); %%% CORREGIDO
                        app.loadMatrixInputFile(fullPath); % Asume que este helper existe y usa app.MensajesdeestadoLabel para status
                        app.setStatus(app.MensajesdeestadoLabel, [app.MensajesdeestadoLabel.Text, ' ¡Cargue Salidas!']); %%% CORREGIDO
                     end
                else
                     app.showErrorAlert(struct('message',sprintf('Modo "%s" no reconocido.', mode)), 'Error Modo'); % showErrorAlert ya usa MensajesdeestadoLabel
                end
            catch ME 
                app.showErrorAlert(ME, sprintf('Error Procesando Entrada (%s)', mode)); % showErrorAlert ya usa MensajesdeestadoLabel
                app.XTrainData = []; app.YTrainTargets = [];
                app.EntradasEditField.Value = ''; app.InputFolderTrain = ''; app.InputFileTrain = '';
            end
                
        end

        % Button pushed function: SeleccionararchivoButton_2
        function SeleccionararchivoButton_2Pushed(app, event)
            filter = {'*.txt', 'Archivos de Texto (*.txt)'};
            dialogTitle = 'Selecciona el archivo .TXT con las salidas deseadas';
            [fileName, pathName] = uigetfile(filter, dialogTitle);
             if isequal(fileName, 0)
                app.showStatus('Selección cancelada.', 'entrenamiento');
                app.SalidasdeseadasEditField.Value = '';
                app.TargetFileTrain = '';
            else
                fullPath = fullfile(pathName, fileName);
                app.SalidasdeseadasEditField.Value = fullPath;
                app.TargetFileTrain = fullPath;
                app.showStatus(['Archivo de salidas deseadas: ' fullPath], 'entrenamiento');
            end
        end

        % Button pushed function: SeleccionararchivoButton_3
        function SeleccionararchivoButton_3Pushed(app, event)
             filter = {'*.txt', 'Archivos de Texto (*.txt)'};
            dialogTitle = 'Selecciona el archivo .TXT con los pesos iniciales (Opcional)';
            [fileName, pathName] = uigetfile(filter, dialogTitle);
             if isequal(fileName, 0)
                app.showStatus('Selección de pesos iniciales cancelada.', 'entrenamiento');
                app.PesosInicialesEditField.Value = '';
                app.InitialWeightsFileTrain = '';
            else
                fullPath = fullfile(pathName, fileName);
                app.PesosInicialesEditField.Value = fullPath;
                app.InitialWeightsFileTrain = fullPath;
                app.showStatus(['Archivo de pesos iniciales: ' fullPath], 'entrenamiento');
            end
        end

        % Button pushed function: EntrenarButton
        function EntrenarButtonPushed(app, event)
            app.showStatus('Iniciando proceso de entrenamiento...', 'entrenamiento');
            % Limpiar resultados anteriores
            app.pocasEditField.Value = 0;
            % *** CORRECCIÓN: Usar -1 en lugar de NaN como indicador inicial/error ***
            app.ErrorFinalEditField.Value = -1;
            app.ErrorGeneralEditField.Value = -1;
            drawnow;

            % --- Determinar Modo y Configurar RGB ---
            mode = app.CasoaEntrenarDropDown.Value;
            if strcmpi(mode, 'Imagenes'); app.UseRGB = true; fprintf('Modo Imágenes detectado. Procesando como RGB.\n');
            else; app.UseRGB = false; fprintf('Modo Matriz detectado. Procesando como Grisescala/Binario.\n'); end

            % --- 1. Leer y Validar Parámetros de la UI ---
            try
                app.CurrentAlpha = app.AlphaEditField.Value;
                app.CurrentBeta = app.BetaEditField.Value;
                app.CurrentPrecision = app.PrecisinEditField.Value;
                architectureStr = app.ArquitecturaEditField.Value;
                app.WeightsOutputFileTrain = app.GuardarPesosenEditField.Value;
                app.InitialWeightsFileTrain = app.PesosInicialesEditField.Value;

                if ~isscalar(app.CurrentAlpha) || ~isfinite(app.CurrentAlpha) || app.CurrentAlpha <= 0; app.showError('Alpha debe ser un número finito > 0.'); return; end
                if ~isscalar(app.CurrentBeta) || ~isfinite(app.CurrentBeta) || app.CurrentBeta < 0 || app.CurrentBeta >= 1; app.showError('Beta debe ser un número finito en [0, 1).'); return; end
                if ~isscalar(app.CurrentPrecision) || ~isfinite(app.CurrentPrecision) || app.CurrentPrecision <= 0; app.showError('Precisión debe ser un número finito > 0.'); return; end
                if isempty(app.WeightsOutputFileTrain)
                    app.WeightsOutputFileTrain = fullfile(pwd, 'pesos_entrenados_default.txt');
                    app.GuardarPesosenEditField.Value = app.WeightsOutputFileTrain;
                    app.showStatus('Usando nombre default para guardar pesos.', 'entrenamiento');
                end

                if isempty(architectureStr) || ~matches(architectureStr, regexpPattern('^\[\s*\d+(\s+\d+)+\s*\]$'))
                    app.showError('Formato arquitectura inválido. Usa formato como [784 15 8].'); return;
                end
                tempArch = str2num(architectureStr); %#ok<ST2NM>
                if isempty(tempArch) || ~isvector(tempArch) || any(~isfinite(tempArch)) || any(tempArch <= 0) || any(mod(tempArch,1)~=0)
                    app.showError('Arquitectura debe contener enteros finitos positivos.'); return;
                end

            catch ME; app.showError(['Error leyendo parámetros UI: ' ME.message]); return; end

            % --- 2. Cargar Datos según el Modo ---
            successLoad = false;
            app.XTrainData = []; app.YTrainTargets = [];
            app.NumFeatures = NaN; app.NumOutputs = NaN; % Usar NaN internamente está bien

            if strcmpi(mode, 'Imagenes')
                [successLoad, app.XTrainData, app.YTrainTargets, app.NumFeatures, app.NumOutputs] = app.loadImageData();
            elseif strcmpi(mode, 'Matriz')
                 [successLoad, app.XTrainData, app.YTrainTargets, app.NumFeatures, app.NumOutputs] = app.loadMatrixData();
                 if successLoad && (~isscalar(app.NumFeatures) || app.NumFeatures ~= 35) % Añadir isscalar check
                     app.showError(sprintf('Modo Matriz: Se esperaban 35 características de entrada, pero se cargaron %d.', app.NumFeatures));
                     successLoad = false;
                 end
            else
                 app.showError(sprintf('Modo "%s" no soportado para carga de datos.', mode)); return;
            end

            if ~successLoad
                app.showError('Fallo al cargar los datos de entrenamiento. Revisa los archivos y la consola.');
                return;
            end

            % --- 3. Validar Arquitectura Final contra Datos Cargados ---
            try
                app.CurrentArchitecture = str2num(app.ArquitecturaEditField.Value); %#ok<ST2NM>
                if isempty(app.CurrentArchitecture); error('Arquitectura no pudo ser convertida a números (revisar formato).'); end

                 % Asegurarse que NumFeatures y NumOutputs sean escalares antes de comparar
                if ~isscalar(app.NumFeatures) || isnan(app.NumFeatures) || app.CurrentArchitecture(1) ~= app.NumFeatures
                    error('La 1ra capa de la arquitectura [%s] (%d neuronas) no coincide con las características de entrada cargadas (%s).', ...
                          mat2str(app.CurrentArchitecture), app.CurrentArchitecture(1), mat2str(app.NumFeatures));
                 end
                if ~isscalar(app.NumOutputs) || isnan(app.NumOutputs) || app.CurrentArchitecture(end) ~= app.NumOutputs
                    error('La última capa de la arquitectura [%s] (%d neuronas) no coincide con las características de salida cargadas (%s).', ...
                          mat2str(app.CurrentArchitecture), app.CurrentArchitecture(end), mat2str(app.NumOutputs));
                 end

                if strcmpi(mode, 'Imagenes') && app.NumOutputs ~= 8
                     warning('Modo Imágenes usualmente tiene 8 salidas (5 vocales + 3 colores), pero se detectaron %d. Procediendo...', app.NumOutputs);
                 end
                 if strcmpi(mode, 'Matriz') && app.NumOutputs ~= 5
                      warning('Modo Matriz usualmente tiene 5 salidas (vocales), pero se detectaron %d. Procediendo...', app.NumOutputs);
                  end

                app.showStatus(sprintf('Arquitectura validada: [%s] vs Datos (%d Entradas, %d Salidas)', num2str(app.CurrentArchitecture), app.NumFeatures, app.NumOutputs), 'entrenamiento');

                 if ~isequal(app.CurrentArchitecture, app.PlottedArchitecture)
                    app.showStatus('Actualizando diagrama arquitectura...', 'entrenamiento'); drawnow;
                    try
                        app.plotArchitecture(app.VerArquitectura, app.CurrentArchitecture);
                        app.PlottedArchitecture = app.CurrentArchitecture;
                    catch ME_plot
                        warning('Error dibujando arquitectura: %s', ME_plot.message);
                        title(app.VerArquitectura, 'Error al dibujar Arq.');
                    end
                 end
            catch ME; app.showError(['Error validando arquitectura vs datos: ' ME.message]); return; end

            % --- 4. Leer Funciones y Inicializar Red Neuronal ---
            initialWeightsLoaded = false;
            try
                numFuncLayers = length(app.CurrentArchitecture) - 1;
                if numFuncLayers < 1; error('La arquitectura debe tener al menos 2 capas (entrada y salida).'); end

                basePath = fileparts(mfilename('fullpath'));
                funcFilePath = fullfile(basePath, 'funciones_activacion.txt');
                derivFilePath = fullfile(basePath, 'derivadas_activacion.txt');
                [funcStringsRaw, derivStringsRaw, successReadFuncs] = app.readFunctionsFromFiles(funcFilePath, derivFilePath, numFuncLayers);
                if ~successReadFuncs; error('No se pudieron leer las funciones/derivadas desde los archivos txt.'); end

                app.showStatus('Inicializando red neuronal...', 'entrenamiento'); drawnow;
                app.NeuralNetwork = BP_neuronal_network(app.CurrentArchitecture, funcStringsRaw, derivStringsRaw);

                if ~isempty(app.InitialWeightsFileTrain) && isfile(app.InitialWeightsFileTrain)
                    app.showStatus(sprintf('Intentando cargar pesos iniciales desde %s...', app.InitialWeightsFileTrain), 'entrenamiento'); drawnow;
                    try
                         tempNet = BP_neuronal_network();
                         [tempNet, loadedArch] = tempNet.cargarPesos(app.InitialWeightsFileTrain);

                         if ~isequal(loadedArch, app.CurrentArchitecture)
                              error('La arquitectura en el archivo de pesos iniciales [%s] no coincide con la arquitectura actual [%s].', ...
                                    mat2str(loadedArch), mat2str(app.CurrentArchitecture));
                         end

                         initialPesos = tempNet.Pesos;
                         initialSesgos = tempNet.Sesgos;
                         app.NeuralNetwork = app.NeuralNetwork.setInitialWeights(initialPesos, initialSesgos);
                         initialWeightsLoaded = true;
                         app.showStatus('Pesos iniciales cargados y establecidos desde archivo.', 'entrenamiento');

                    catch ME_loadW
                        warning('Fallo la carga/validación de pesos iniciales desde "%s": %s. Se usarán pesos aleatorios.', app.InitialWeightsFileTrain, ME_loadW.message);
                        app.showStatus('Fallo carga pesos iniciales, usando aleatorios.', 'entrenamiento');
                        initialWeightsLoaded = false;
                    end
                end
                 if ~initialWeightsLoaded
                     app.showStatus('Usando pesos iniciales aleatorios (no se cargó archivo o falló).', 'entrenamiento');
                 end
            catch ME; app.showError(['Error durante inicialización de la red: ' ME.message]); return; end

            % --- 5. Entrenar la Red ---
            try
                app.showStatus('Iniciando entrenamiento (puede tardar)...', 'entrenamiento'); drawnow;

                % Preparar Ejes UIAxes2 (Error)
                cla(app.UIAxes2); title(app.UIAxes2, 'Error MSE/2 vs Épocas');
                xlabel(app.UIAxes2, 'Época'); ylabel(app.UIAxes2, 'Error (MSE/2)');
                grid(app.UIAxes2,'on'); hold(app.UIAxes2, 'on');

                % Preparar Ejes UIAxes3 (Pesos)
                cla(app.UIAxes3); title(app.UIAxes3, 'Norma Pesos/Sesgos vs Épocas');
                grid(app.UIAxes3, 'on'); hold(app.UIAxes3, 'off');
                drawnow;

                % Llamar entrenamiento
                 [app.NeuralNetwork, finalEpoch, finalError, history_error, history_pesos, history_sesgos, savedEpochs] = ...
                    app.NeuralNetwork.entrenar(app.XTrainData, app.YTrainTargets, ...
                                               app.CurrentAlpha, app.CurrentPrecision, app.CurrentBeta,...
                                               app.UIAxes2 );

                 % Guardar historial
                app.TrainingHistoryError = history_error;
                app.TrainingHistoryPesoCells = history_pesos;
                app.TrainingHistorySesgoCells = history_sesgos;
                app.SavedEpochs = savedEpochs;

                % --- 6. Mostrar Resultados, Evaluar y Guardar Config ---

                % Validar finalEpoch
                validatedEpoch = 0; % Default
                if isscalar(finalEpoch) && isfinite(finalEpoch) && finalEpoch >= 0
                    validatedEpoch = finalEpoch;
                else
                    warning('Valor de finalEpoch no válido (%s). Estableciendo a 0.', mat2str(finalEpoch));
                end
                app.pocasEditField.Value = validatedEpoch;

                % Validar finalError y asignar
                validatedError = -1; % Default para error/inválido
                if isscalar(finalError) && isfinite(finalError)
                     validatedError = finalError;
                     app.showStatus(sprintf('Entrenamiento finalizado en %d épocas con error MSE/2 = %.6f', validatedEpoch, validatedError), 'entrenamiento');
                else
                     % validatedError ya es -1
                     app.showStatus(sprintf('Entrenamiento finalizado en %d épocas con error final NO VÁLIDO (%s).', validatedEpoch, mat2str(finalError)), 'entrenamiento');
                     warning('Valor de finalError no válido (%s). Estableciendo a -1.', mat2str(finalError));
                end
                app.ErrorFinalEditField.Value = validatedError; % Asignar valor validado o -1
                drawnow;

                 % --- Ajustes Finales para UIAxes2 (Error Plot) ---
                 hold(app.UIAxes2, 'off');
                 currentFinalEpoch = app.pocasEditField.Value;
                 if currentFinalEpoch > 1
                     app.UIAxes2.XLim = [1 currentFinalEpoch];
                     app.UIAxes2.XTick = unique(round(linspace(1, currentFinalEpoch, min(currentFinalEpoch, 10))));
                 elseif currentFinalEpoch == 1
                     app.UIAxes2.XLim = [0.5 1.5];
                     app.UIAxes2.XTick = 1;
                 else
                      app.UIAxes2.XLimMode = 'auto';
                      app.UIAxes2.XTickMode = 'auto';
                 end
                 app.UIAxes2.YLimMode = 'auto';
                 app.UIAxes2.YTickMode = 'auto';
                 app.UIAxes2.Box = 'on';
                 app.UIAxes2.XAxisLocation = 'bottom';
                 app.UIAxes2.YAxisLocation = 'left';
                 app.UIAxes2.LineWidth = 0.5;
                 app.UIAxes2.XColor = [0.15 0.15 0.15];
                 app.UIAxes2.YColor = [0.15 0.15 0.15];

                 % *** NUEVO: Forzar Visibilidad de Ejes y Rejilla ***
                 app.UIAxes2.XAxis.Visible = 'on';
                 app.UIAxes2.YAxis.Visible = 'on';
                 grid(app.UIAxes2, 'on'); % Re-asegurar la rejilla
                 % *** FIN NUEVO ***

                 drawnow; % Actualizar figura

                % --- EVALUACIÓN DE CLASIFICACIÓN ---
                accuracyValue = -1; % Usar -1 en lugar de NaN
                app.ErrorGeneralEditField.Value = -1; % Poner -1 mientras se calcula
                try
                     app.showStatus(sprintf('%s Calculando precisión de clasificación...', app.MensajesdeestadoLabel.Text), 'entrenamiento'); drawnow;
                    [~, ConfMatCounts] = app.NeuralNetwork.evaluarClasificacion(app.XTrainData, app.YTrainTargets);

                    if ~isempty(ConfMatCounts)
                        totalSum = sum(ConfMatCounts(:));
                        if totalSum > 0
                            accuracy = sum(diag(ConfMatCounts)) / totalSum * 100;
                               if isscalar(accuracy) && isfinite(accuracy)
                                   accuracyValue = accuracy; % Guardar valor válido
                                   app.showStatus(sprintf('Entrenamiento finalizado. Precisión: %.2f%%', accuracyValue), 'entrenamiento');
                               else
                                    warning('Cálculo de precisión resultó en un valor no finito/escalar (%s).', mat2str(accuracy));
                                    % accuracyValue ya es -1
                                    app.showStatus('Entrenamiento finalizado. Precisión no calculable (cálculo inválido).', 'entrenamiento');
                               end
                        else
                            warning('Matriz de confusión suma cero. No se puede calcular precisión.');
                            accuracyValue = 0; % Si la suma es 0, la precisión es 0%
                            app.showStatus('Entrenamiento finalizado. Precisión: 0%% (matriz conf. vacía/cero).', 'entrenamiento');
                        end
                    else
                        warning('Matriz de confusión vacía devuelta por evaluarClasificacion.');
                         % accuracyValue ya es -1
                        app.showStatus('Entrenamiento finalizado. Precisión no calculable (matriz conf. vacía).', 'entrenamiento');
                    end
                catch ME_eval
                    warning('Error durante evaluación de clasificación: %s', ME_eval.message);
                     % accuracyValue ya es -1
                     app.showStatus(sprintf('Entrenamiento finalizado. Error en cálculo de precisión: %s', ME_eval.message), 'entrenamiento');
                end
                app.ErrorGeneralEditField.Value = accuracyValue; % Asignar valor validado o -1
                drawnow;

                % --- Guardar Pesos Entrenados ---
                if ~isempty(app.WeightsOutputFileTrain)
                    try
                        [folder, name, ext] = fileparts(app.WeightsOutputFileTrain);
                        if isempty(ext); ext = '.txt'; end
                        if isempty(folder); folder = pwd; end
                        if ~isfolder(folder); mkdir(folder); end
                        savePath = fullfile(folder, [name ext]);
                        app.NeuralNetwork.guardarPesos(savePath);
                        app.showStatus(sprintf('%s Pesos guardados en: %s', app.MensajesdeestadoLabel.Text, savePath), 'entrenamiento');
                    catch ME_save
                        app.showError(sprintf('Error guardando pesos entrenados en "%s": %s', app.WeightsOutputFileTrain, ME_save.message));
                    end
                else
                     app.showStatus(sprintf('%s (Pesos no guardados, ruta vacía)', app.MensajesdeestadoLabel.Text), 'entrenamiento');
                end

                % --- Guardar Configuración Usada ---
                try
                    lastConfig = struct();
                    lastConfig.alpha = app.CurrentAlpha;
                    lastConfig.beta = app.CurrentBeta;
                    lastConfig.precision = app.CurrentPrecision;
                    lastConfig.arquitectura = app.CurrentArchitecture;

                    configFilePath = fullfile(fileparts(mfilename('fullpath')), 'config.txt');
                    BP_neuronal_network.guardarConfig(configFilePath, lastConfig);
                    app.showStatus(sprintf('%s Configuración guardada.', app.MensajesdeestadoLabel.Text), 'entrenamiento');
                catch ME_saveConfig
                    warning('No se pudo guardar config.txt: %s', ME_saveConfig.message);
                     app.showStatus(sprintf('%s (Error guardando config.txt)', app.MensajesdeestadoLabel.Text), 'entrenamiento');
                end

                % --- Graficar Historial de Pesos/Sesgos (Norma) en UIAxes3 ---
                if ~isempty(app.TrainingHistoryPesoCells) && ~isempty(app.SavedEpochs)
                     app.showStatus(sprintf('%s Graficando historial de pesos...', app.MensajesdeestadoLabel.Text), 'entrenamiento'); drawnow;
                    try
                        % *** Llamamos a la función MODIFICADA plotWeightHistory ***
                        app.plotWeightHistory( ...
                            app.UIAxes3, ...
                            app.TrainingHistoryPesoCells, ...
                            app.TrainingHistorySesgoCells, ...
                            app.SavedEpochs );
                         app.showStatus(sprintf('%s Historial pesos graficado.', app.MensajesdeestadoLabel.Text), 'entrenamiento');
                    catch ME_plotW
                         cla(app.UIAxes3); title(app.UIAxes3, 'Error Graf. Pesos');
                         warning('Error graficando historial de pesos: %s', ME_plotW.message);
                          app.showStatus(sprintf('%s Error graficando pesos.', app.MensajesdeestadoLabel.Text), 'entrenamiento');
                    end
                else
                    cla(app.UIAxes3);
                    title(app.UIAxes3, 'Norma Pesos/Sesgos vs Épocas');
                    text(app.UIAxes3, 0.5, 0.5, 'No hay historial de pesos guardado', 'HorizontalAlignment', 'center');
                     app.showStatus(sprintf('%s (Sin historial pesos)', app.MensajesdeestadoLabel.Text), 'entrenamiento');
                end
                % --- Fin del bloque try principal ---

            catch ME
                app.showError(['Error durante el proceso de entrenamiento: ' ME.message]);
                app.pocasEditField.Value = 0;
                app.ErrorFinalEditField.Value = -1; % Usar -1
                app.ErrorGeneralEditField.Value = -1; % Usar -1
                 cla(app.UIAxes2); title(app.UIAxes2, 'Error vs Épocas (Error/Cancelado)'); grid(app.UIAxes2,'on'); app.UIAxes2.XAxis.Visible='on'; app.UIAxes2.YAxis.Visible='on'; % También aquí por si acaso
                 cla(app.UIAxes3); title(app.UIAxes3, 'Pesos vs Épocas (Error/Cancelado)'); grid(app.UIAxes3,'on'); app.UIAxes3.XAxis.Visible='on'; app.UIAxes3.YAxis.Visible='on'; % También aquí
            end
        end

        % Button pushed function: SeleccionararchivoButton_5
        function SeleccionararchivoButton_5Pushed(app, event)
            mode = app.CasoaEstudiarrDropDown.Value; % Usar el DropDown de la pestaña Aplicación
            if strcmpi(mode, 'Imagenes')
                filter = {'*.png;*.jpg;*.jpeg', 'Archivos de Imagen (*.png, *.jpg, *.jpeg)'};
                dialogTitle = 'Selecciona la IMAGEN de entrada';
            elseif strcmpi(mode, 'Matriz')
                filter = {'*.txt', 'Archivo de Texto (*.txt)'};
                 dialogTitle = 'Selecciona el archivo .TXT con el vector de entrada';
            else
                return; % Modo no reconocido
            end
            
            [fileName, pathName] = uigetfile(filter, dialogTitle);
             if isequal(fileName, 0)
                app.showStatus('Selección de entrada cancelada.', 'aplicacion');
                app.EntradasEditField_2.Value = '';
                app.InputFileApp = '';
                cla(app.ImagenCargada); axis(app.ImagenCargada,'off'); title(app.ImagenCargada, 'Imagen/Entrada'); % Limpiar visualización
            else
                fullPath = fullfile(pathName, fileName);
                app.EntradasEditField_2.Value = fullPath;
                app.InputFileApp = fullPath;
                app.showStatus(['Archivo de entrada listo: ' fullPath], 'aplicacion');
                
                % --- Mostrar la imagen/matriz cargada ---
                 try
                     if strcmpi(mode, 'Imagenes')
                        imgData = imread(fullPath);
                        imshow(imgData,'Parent',app.ImagenCargada);
                        title(app.ImagenCargada, fileName);
                        % ← Aquí guardo el vector preprocesado
                        app.AppInputVector = app.preprocessImage(fullPath);
                    elseif strcmpi(mode, 'Matriz')
                        lines = readlines(fullPath);
                        lines = lines(strlength(lines)>0 & ~startsWith(lines,'%'));
                        vectorStr   = lines{1};
                        vectorData  = double(vectorStr'=='1');
                        app.displayVectorAsMatrix(app.ImagenCargada, vectorData);
                        % ← Y aquí
                        app.AppInputVector = vectorData;
                        app.AppMatrixVector = vectorData;  % opcional, si lo usas separado
                    end
                 catch ME_disp
                      app.showError(['Error mostrando entrada: ' ME_disp.message], 'aplicacion');
                      cla(app.ImagenCargada); axis(app.ImagenCargada,'off'); title(app.ImagenCargada, 'Error al Visualizar');
                 end
            end
        end

        % Button pushed function: SeleccionararchivoButton_4
        function SeleccionararchivoButton_4Pushed(app, event)
            filter = {'*.txt', 'Archivos de Texto (*.txt)'};
            dialogTitle = 'Selecciona el archivo .TXT con los pesos entrenados';
            [fileName, pathName] = uigetfile(filter, dialogTitle);
             if isequal(fileName, 0)
                app.showStatus('Selección de pesos cancelada.', 'aplicacion');
                % No limpiar campo, podría querer usar el default
             else
                fullPath = fullfile(pathName, fileName);
                app.PesosEditField.Value = fullPath; % Actualizar campo UI
                app.WeightsFileApp = fullPath;       % Actualizar propiedad
                app.showStatus(['Archivo de pesos seleccionado: ' fullPath], 'aplicacion');
                app.LoadedAppNetwork = BP_neuronal_network();
             end
        end

        % Button pushed function: SeleccionarrutaButton
        function SeleccionarRutaButtonPushed(app, event)
            defaultName = app.GuardarPesosenEditField.Value;
             if isempty(defaultName); defaultName = 'pesos_entrenados.txt'; end
             
             filter = {'*.txt', 'Archivos de Texto (*.txt)'};
             dialogTitle = 'Guardar pesos entrenados como...';
             [fileName, pathName] = uiputfile(filter, dialogTitle, defaultName);
             
             if isequal(fileName, 0)
                app.showStatus('Guardado de pesos cancelado.', 'entrenamiento');
                % No limpiar el campo, mantener el valor anterior o por defecto
             else
                fullPath = fullfile(pathName, fileName);
                app.GuardarPesosenEditField.Value = fullPath;
                app.WeightsOutputFileTrain = fullPath;
                app.showStatus(['Archivo para guardar pesos: ' fullPath], 'entrenamiento');
             end
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            mode = app.CasoaEstudiarrDropDown.Value;
             inputVector = [];
             %%% --- DEBUGGING --- %%%
             fprintf('DEBUG (Ejecutar): Verificando app.AppInputVector. Tamaño: %d x %d\n', size(app.AppInputVector,1), size(app.AppInputVector,2));
             if isempty(app.AppInputVector)
                  fprintf('DEBUG (Ejecutar): ¡app.AppInputVector está VACÍO!\n');
             end
              %%% --- FIN DEBUGGING --- %%%
             % Obtener vector de entrada correcto según el modo
             if strcmp(mode, 'Imagenes') && ~isempty(app.AppInputVector); inputVector = app.AppInputVector;
             elseif strcmp(mode, 'Matriz') && ~isempty(app.AppMatrixVector); inputVector = app.AppMatrixVector; end % Asume AppMatrixVector se carga correctamente
             
             if isempty(inputVector); app.showErrorAlert(struct('message','Carga entrada (Imagen o Matriz) primero.'),'Entrada Faltante'); return; end
             
             % Determinar qué archivo de pesos usar
             pesosAUsar = '';
             if ~isempty(app.WeightsFileApp) && isfile(app.WeightsFileApp)
                pesosAUsar = app.WeightsFileApp;
                app.setStatus(app.Label_5, 'Usando pesos seleccionados en la aplicación...');
            elseif ~isempty(app.WeightsOutputFileTrain) && isfile(app.WeightsOutputFileTrain)
                pesosAUsar = app.WeightsOutputFileTrain;
                app.setStatus(app.Label_5, 'Usando pesos del último entrenamiento...');
            else
                app.showErrorAlert(struct('message','No se encontró ningún archivo de pesos entrenados.'),'Pesos Faltantes');
                return;
            end
                         drawnow;

             % Cargar Red y Ejecutar
             try 
                 % Crear instancia VACÍA para cargar
                 tempNet = BP_neuronal_network(); 
                 
                 % Cargar pesos Y OBTENER arquitectura desde el archivo
                 [tempNet, loadedArch] = tempNet.cargarPesos(pesosAUsar); 
                 
                 % --- IMPORTANTE: Establecer Funciones de Activación ---
                 % Usaremos Sigmoide para todas basado en discusión previa
                 numLayersToActivate = length(loadedArch) - 1;
                 funcActStr = repmat({'1 ./ (1 + exp(-net))'}, 1, numLayersToActivate); 
                 derivActStr = repmat({'act .* (1 - act)'}, 1, numLayersToActivate);
                 % Re-crear handles en la red cargada
                 tempNet.FuncionesActivacion = cell(1, numLayersToActivate);
                 tempNet.DerivadasActivacion = cell(1, numLayersToActivate);
                 for ii = 1:numLayersToActivate
                      tempNet.FuncionesActivacion{ii} = str2func(['@(net) ' funcActStr{ii}]);
                      tempNet.DerivadasActivacion{ii} = str2func(['@(act) ' derivActStr{ii}]);
                 end
                 app.setStatus(app.Label_5, sprintf('Red cargada (Arq: [%s]). Ejecutando...', num2str(loadedArch))); drawnow;
                 
                 % Validar entrada vs ARQUITECTURA CARGADA
                 if size(inputVector, 1) ~= loadedArch(1)
                      error('Entrada (%d caract) != red cargada (%d caract)', size(inputVector, 1), loadedArch(1)); 
                 end
                 
                 % Ejecutar feedforward
                 outputVector = tempNet.feedforward(inputVector);
                 
                 % Interpretar y mostrar resultados
                 [letra, colorStr] = app.interpretOutput(outputVector); 
                 app.Label_4.Text = ['Letra: ', letra]; % Usa Tag Label_4
                 app.Label_3.Text = ['Color: ', colorStr]; % Usa Tag Label_3
                 app.setStatus(app.Label_5, 'Ejecución completada.'); 
                 
             catch ME
                 app.setStatus(app.Label_5, ['Error ejecución/carga: ' ME.message]); 
                 app.showErrorAlert(ME, 'Error en Aplicación');
                 app.Label_4.Text = 'Letra: Error'; 
                 app.Label_3.Text = 'Color: Error'; 
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EntrenamientoTab = uitab(app.TabGroup);
            app.EntrenamientoTab.Title = 'Entrenamiento';
            app.UIAxes3 = uiaxes(app.EntrenamientoTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.EntrenamientoTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.VerArquitectura = uiaxes(app.EntrenamientoTab);
            title(app.VerArquitectura, 'Title')
            xlabel(app.VerArquitectura, 'X')
            ylabel(app.VerArquitectura, 'Y')
            zlabel(app.VerArquitectura, 'Z')
            app.EntrenamientoLaboratorio3InteligenciaArtificialLabel = uilabel(app.EntrenamientoTab);
            app.EntrenamientoLaboratorio3InteligenciaArtificialLabel.Text = 'Entrenamiento Laboratorio #3 Inteligencia Artificial';
            app.Image = uiimage(app.EntrenamientoTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMAGOTIPO VERTICAL COLOR.png');
            app.Label = uilabel(app.EntrenamientoTab);
            app.Label.Text = {'Presentado por Juan Esteban Fuentes Rojas, Duvan Santiago Matallana Jiménez y'; ' Laura Estefania Latorre Pachon'};
            app.UniversidaddeCundinamarcaLabel = uilabel(app.EntrenamientoTab);
            app.UniversidaddeCundinamarcaLabel.Text = 'Universidad de Cundinamarca';
            app.DatosdeentrenamientoLabel = uilabel(app.EntrenamientoTab);
            app.DatosdeentrenamientoLabel.Text = 'Datos de entrenamiento';
            app.CargadearchivosporfavorseleccioneunarchivoLabel = uilabel(app.EntrenamientoTab);
            app.CargadearchivosporfavorseleccioneunarchivoLabel.Text = 'Carga de archivos, por favor seleccione un archivo';
            app.SeleccionararchivoButton = uibutton(app.EntrenamientoTab, 'push');
            app.SeleccionararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButtonPushed, true);
            app.SeleccionararchivoButton.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_2 = uibutton(app.EntrenamientoTab, 'push');
            app.SeleccionararchivoButton_2.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_2Pushed, true);
            app.SeleccionararchivoButton_2.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_3 = uibutton(app.EntrenamientoTab, 'push');
            app.SeleccionararchivoButton_3.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_3Pushed, true);
            app.SeleccionararchivoButton_3.Text = 'Seleccionar archivo';
            app.EntrenarButton = uibutton(app.EntrenamientoTab, 'push');
            app.EntrenarButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarButtonPushed, true);
            app.EntrenarButton.Text = 'Entrenar';
            app.ArquitecturaLabel = uilabel(app.EntrenamientoTab);
            app.ArquitecturaLabel.Text = 'Arquitectura';
            app.PesosvspocasLabel = uilabel(app.EntrenamientoTab);
            app.PesosvspocasLabel.Text = 'Pesos vs Épocas';
            app.ErrorvspocasLabel = uilabel(app.EntrenamientoTab);
            app.ErrorvspocasLabel.Text = 'Error vs Épocas';
            app.ArquitecturaEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.ArquitecturaEditFieldLabel.Text = 'Arquitectura';
            app.ArquitecturaEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.ArquitecturaEditField.Placeholder = '5 4 2';
            app.PesosInicialesEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.PesosInicialesEditField.Placeholder = 'C:/Users/folder';
            app.PesosInicialesEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.PesosInicialesEditFieldLabel.Text = {'Pesos'; 'Iniciales'};
            app.GuardarPesosenEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.GuardarPesosenEditFieldLabel.Text = {'Guardar '; 'Pesos en'};
            app.GuardarPesosenEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.GuardarPesosenEditField.Value = 'pesos_entrenados_vocales.txt';
            app.MensajesdeestadoLabel = uilabel(app.EntrenamientoTab);
            app.MensajesdeestadoLabel.Text = '';
            app.CasoaEntrenarDropDownLabel = uilabel(app.EntrenamientoTab);
            app.CasoaEntrenarDropDownLabel.Text = 'Caso a Entrenar';
            app.CasoaEntrenarDropDown = uidropdown(app.EntrenamientoTab);
            app.CasoaEntrenarDropDown.Items = {'Imagenes', 'Matriz'};
            app.CasoaEntrenarDropDown.Value = 'Imagenes';
            app.pocasEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.pocasEditFieldLabel.Text = 'Épocas';
            app.pocasEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.pocasEditField.Editable = 'off';
            app.ErrorFinalEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.ErrorFinalEditFieldLabel.Text = 'Error Final';
            app.ErrorFinalEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.ErrorFinalEditField.Editable = 'off';
            app.EntradasEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.EntradasEditFieldLabel.Text = 'Entradas';
            app.EntradasEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.EntradasEditField.Placeholder = 'C:/Users/folder';
            app.SalidasdeseadasEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.SalidasdeseadasEditFieldLabel.Text = {'Salidas'; 'deseadas'};
            app.SalidasdeseadasEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.SalidasdeseadasEditField.Placeholder = 'C:/Users/folder';
            app.AlphaEditField_2Label = uilabel(app.EntrenamientoTab);
            app.AlphaEditField_2Label.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.AlphaEditField.Value = 0.01;
            app.BetaEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.BetaEditField.Value = 0.01;
            app.BetaEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.PrecisinEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.PrecisinEditFieldLabel.Text = 'Precisión';
            app.PrecisinEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.PrecisinEditField.Value = 0.01;
            app.SeleccionarrutaButton = uibutton(app.EntrenamientoTab, 'push');
            app.SeleccionarrutaButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarRutaButtonPushed, true);
            app.SeleccionarrutaButton.Text = 'Seleccionar ruta';
            app.PrecisionClaficicacinLabel = uilabel(app.EntrenamientoTab);
            app.PrecisionClaficicacinLabel.Text = {'Precision '; 'Clasificación %'};
            app.ErrorGeneralEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.ErrorGeneralEditField.Editable = 'off';
            app.AplicacinTab = uitab(app.TabGroup);
            app.AplicacinTab.Title = 'Aplicación';
            app.ImagenCargada = uiaxes(app.AplicacinTab);
            title(app.ImagenCargada, 'Title')
            zlabel(app.ImagenCargada, 'Z')
            app.AplicacinLaboratorio3InteligenciaArtificialLabel = uilabel(app.AplicacinTab);
            app.AplicacinLaboratorio3InteligenciaArtificialLabel.Text = 'Aplicación Laboratorio #3 Inteligencia Artificial';
            app.Image_2 = uiimage(app.AplicacinTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'IMAGOTIPO VERTICAL COLOR.png');
            app.Label_2 = uilabel(app.AplicacinTab);
            app.Label_2.Text = {'Presentado por Juan Esteban Fuentes Rojas, Duvan Santiago Matallana Jiménez y'; ' Laura Estefania Latorre Pachon'};
            app.UniversidaddeCundinamarcaLabel_2 = uilabel(app.AplicacinTab);
            app.UniversidaddeCundinamarcaLabel_2.Text = 'Universidad de Cundinamarca';
            app.SeleccionararchivoButton_4 = uibutton(app.AplicacinTab, 'push');
            app.SeleccionararchivoButton_4.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_4Pushed, true);
            app.SeleccionararchivoButton_4.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_5 = uibutton(app.AplicacinTab, 'push');
            app.SeleccionararchivoButton_5.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_5Pushed, true);
            app.SeleccionararchivoButton_5.Text = 'Seleccionar archivo';
            app.EjecutarButton = uibutton(app.AplicacinTab, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.ImagencargadaLabel = uilabel(app.AplicacinTab);
            app.ImagencargadaLabel.Text = 'Imagen cargada';
            app.Label_4 = uilabel(app.AplicacinTab);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.AplicacinTab);
            app.Label_3.Text = '';
            app.Label_5 = uilabel(app.AplicacinTab);
            app.Label_5.Text = '';
            app.EntradasEditField_2Label = uilabel(app.AplicacinTab);
            app.EntradasEditField_2Label.Text = 'Entradas';
            app.EntradasEditField_2 = uieditfield(app.AplicacinTab, 'text');
            app.PesosEditFieldLabel = uilabel(app.AplicacinTab);
            app.PesosEditFieldLabel.Text = 'Pesos';
            app.PesosEditField = uieditfield(app.AplicacinTab, 'text');
            app.CasoaEstudiarrDropDownLabel = uilabel(app.AplicacinTab);
            app.CasoaEstudiarrDropDownLabel.Text = 'Caso a Estudiarr';
            app.CasoaEstudiarrDropDown = uidropdown(app.AplicacinTab);
            app.CasoaEstudiarrDropDown.Items = {'Imagenes', 'Matriz'};
            app.CasoaEstudiarrDropDown.Value = 'Imagenes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Ent_Backpropagation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end