classdef App_Ent_Backpropagation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        EntrenamientoTab                matlab.ui.container.Tab
        ErrorGeneralEditField           matlab.ui.control.NumericEditField
        PrecisionClaficicacinLabel      matlab.ui.control.Label
        SeleccionarrutaButton           matlab.ui.control.Button
        PrecisinEditField               matlab.ui.control.NumericEditField
        PrecisinEditFieldLabel          matlab.ui.control.Label
        BetaEditFieldLabel              matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.NumericEditField
        AlphaEditField                  matlab.ui.control.NumericEditField
        AlphaEditField_2Label           matlab.ui.control.Label
        SalidasdeseadasEditField        matlab.ui.control.EditField
        SalidasdeseadasEditFieldLabel   matlab.ui.control.Label
        EntradasEditField               matlab.ui.control.EditField
        EntradasEditFieldLabel          matlab.ui.control.Label
        ErrorFinalEditField             matlab.ui.control.NumericEditField
        ErrorFinalEditFieldLabel        matlab.ui.control.Label
        pocasEditField                  matlab.ui.control.NumericEditField
        pocasEditFieldLabel             matlab.ui.control.Label
        CasoaEntrenarDropDown           matlab.ui.control.DropDown
        CasoaEntrenarDropDownLabel      matlab.ui.control.Label
        MensajesdeestadoLabel           matlab.ui.control.Label
        GuardarPesosenEditField         matlab.ui.control.EditField
        GuardarPesosenEditFieldLabel    matlab.ui.control.Label
        PesosInicialesEditFieldLabel    matlab.ui.control.Label
        PesosInicialesEditField         matlab.ui.control.EditField
        ArquitecturaEditField           matlab.ui.control.EditField
        ArquitecturaEditFieldLabel      matlab.ui.control.Label
        ErrorvspocasLabel               matlab.ui.control.Label
        PesosvspocasLabel               matlab.ui.control.Label
        ArquitecturaLabel               matlab.ui.control.Label
        EntrenarButton                  matlab.ui.control.Button
        SeleccionararchivoButton_3      matlab.ui.control.Button
        SeleccionararchivoButton_2      matlab.ui.control.Button
        SeleccionararchivoButton        matlab.ui.control.Button
        CargadearchivosporfavorseleccioneunarchivoLabel  matlab.ui.control.Label
        DatosdeentrenamientoLabel       matlab.ui.control.Label
        UniversidaddeCundinamarcaLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        EntrenamientoLaboratorio3InteligenciaArtificialLabel  matlab.ui.control.Label
        VerArquitectura                 matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        AplicacinTab                    matlab.ui.container.Tab
        CasoaEstudiarrDropDown          matlab.ui.control.DropDown
        CasoaEstudiarrDropDownLabel     matlab.ui.control.Label
        PesosEditField                  matlab.ui.control.EditField
        PesosEditFieldLabel             matlab.ui.control.Label
        EntradasEditField_2             matlab.ui.control.EditField
        EntradasEditField_2Label        matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        ImagencargadaLabel              matlab.ui.control.Label
        EjecutarButton                  matlab.ui.control.Button
        SeleccionararchivoButton_5      matlab.ui.control.Button
        SeleccionararchivoButton_4      matlab.ui.control.Button
        UniversidaddeCundinamarcaLabel_2  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        AplicacinLaboratorio3InteligenciaArtificialLabel  matlab.ui.control.Label
        ImagenCargada                   matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        NeuralNetwork       BP_neuronal_network % Instancia de la red
        XTrainData          % Datos de entrada para entrenamiento [NumFeatures x NumPatrones]
        YTrainTargets       % Salidas deseadas para entrenamiento [NumOutputs x NumPatrones]

        % Parámetros (pueden leerse de la UI directamente, pero tenerlos aquí es útil)
        CurrentArchitecture % Arquitectura actual [1 x NumCapas]
        CurrentAlpha
        CurrentBeta
        CurrentPrecision

        ImageSize = [28 28]; % Tamaño al que se redimensionarán las imágenes
        UseRGB = false;      % Usar imágenes en escala de grises por defecto
        NumFeatures         % Calculado en base a ImageSize y UseRGB
        NumOutputs          % Calculado en base a Vowel+Color o solo Vowel
        NumClassesVowel = 5;
        NumClassesColor = 3; % Rojo, Verde, Azul
        VowelMap = {'A', 'E', 'I', 'O', 'U'};
        ColorMap = {'Rojo', 'Verde', 'Azul'};

        TrainingHistory     % Para guardar historial completo si es necesario
        ErrorLine           % Handle a la línea animada para el error
        TrainingHistoryError
        TrainingHistoryPesoCells
        TrainingHistorySesgoCells
        SavedEpochs
        AppInputVector
        AppMatrixVector

        % Paths (leídos de la UI)
        InputFolderTrain    % Carpeta de imágenes (Entrenamiento Imagen)
        InputFileTrain      % Archivo de entrada (Entrenamiento Matriz)
        TargetFileTrain     % Archivo de salidas deseadas .txt (Ambos entrenamientos)
        InitialWeightsFileTrain % Archivo de pesos iniciales opcional (Entrenamiento)
        WeightsOutputFileTrain  % Archivo donde guardar pesos entrenados (Entrenamiento)

        InputFileApp        % Archivo de entrada (Aplicación)
        WeightsFileApp      % Archivo de pesos a cargar (Aplicación)
        LoadedAppNetwork    BP_neuronal_network % Red cargada en la pestaña Aplicació
        PlottedArchitecture = []; % Almacena la última arquitectura dibujada en VerArquitectura
    end

    methods (Access = private)

        function showErrorAlert(app, errorInfo, title)
            if nargin < 3; title = 'Error'; end
            errorMessage = 'Ocurrió un error desconocido.'; % Default
            stackInfo = struct('name', 'N/A', 'line', 0); stackTraceAvailable = false;

            if isa(errorInfo, 'MException') % Objeto de error estándar MException
                if isprop(errorInfo, 'message') && ~isempty(errorInfo.message); errorMessage = errorInfo.message; end
                if isprop(errorInfo, 'stack') && ~isempty(errorInfo.stack); stackInfo = errorInfo.stack(1); stackTraceAvailable = true; end
            elseif isstruct(errorInfo) && isfield(errorInfo, 'message') % Struct manual
                if ~isempty(errorInfo.message); errorMessage = errorInfo.message; end
            else % Otro tipo
                try; errorMessage = char(errorInfo); catch; end
            end

            % Log error to command window for debugging
            fprintf(2,'ERROR App (%s): %s\n', title, errorMessage); % Use fprintf(2,...) for errors
            if stackTraceAvailable; fprintf(2,'  en %s (línea %d)\n', stackInfo.name, stackInfo.line); end

            % Update status labels first
            try; app.setStatus(app.MensajesdeestadoLabel, ['Error: ' errorMessage]); catch; end
            try; app.setStatus(app.Label_5, ['Error: ' errorMessage]); catch; end % For Application tab

            % Show visual alert
            try
                if isvalid(app.UIFigure); uialert(app.UIFigure, errorMessage, title, 'Icon','error', 'Modal', true); else; errordlg(errorMessage, title); end
            catch
                errordlg(errorMessage, title); % Fallback error dialog
            end
        end

        function setStatus(app, labelHandle, message)
            if isgraphics(labelHandle) && isvalid(labelHandle)
                labelHandle.Text = message;
            else
                disp(['Status (Label no válido): ', message]); % Fallback a consola
            end
            drawnow('limitrate'); % Actualizar UI
        end

        % Simple error display using uialert and status label
        function showError(app, message, title)
            if nargin < 3; title = 'Error'; end
            try; if isvalid(app.UIFigure); uialert(app.UIFigure, message, title, 'Icon', 'error'); else; errordlg(message, title); end; catch; errordlg(message, title); end
            try; app.setStatus(app.MensajesdeestadoLabel, ['Error: ' message]); catch; end % Actualiza etiqueta principal
            drawnow;
        end

        % Simplified status update based on tab context
        function showStatus(app, message, tab)
            labelHandle = []; % Inicializar
            if strcmpi(tab, 'entrenamiento')
                labelHandle = app.MensajesdeestadoLabel;
            elseif strcmpi(tab, 'aplicacion')
                labelHandle = app.Label_5;
            end

            if ~isempty(labelHandle) && isgraphics(labelHandle) && isvalid(labelHandle)
                labelHandle.Text = message;
            else
                disp(message); % Fallback a consola si no hay label o tab inválido
            end
            drawnow('limitrate');
        end

        % Calcula características de entrada/salida según modo
        function calculateNetworkIOFeatures(app, mode)
            if nargin < 2
                try; mode = app.CasoaEntrenarDropDown.Value; catch; mode = 'Imagenes'; end % Usa DropDown o default
            end
            try; use_rgb_flag = app.UseRGB; catch; use_rgb_flag = false; end % Default a gris

            % Calcular NumFeatures
            if strcmpi(mode, 'Imagenes')
                numChannels = 1; if use_rgb_flag; numChannels = 3; end
                app.NumFeatures = prod(app.ImageSize) * numChannels;
            elseif strcmpi(mode, 'Matriz')
                app.NumFeatures = 35; % Asumir fijo para Matriz 7x5
            else
                app.NumFeatures = NaN;
                warning('Modo "%s" no reconocido para calcular NumFeatures.', mode);
            end
            % Calcular NumOutputs (Asume fijo por ahora)
            app.NumOutputs = app.NumClassesVowel + app.NumClassesColor;
        end

        % Preprocesa una imagen: lee, redimensiona, ajusta canales, vectoriza
        function imgVector = preprocessImage(app, filepath)
            imgVector = [];
            try
                img = imread(filepath);
            catch ME
                error('No se pudo leer imagen: %s. %s', filepath, ME.message);
            end

            try
                imgResized = imresize(img, app.ImageSize);
                imgDouble = im2double(imgResized);
            catch ME_proc
                error('Error en imresize/im2double para %s: %s', filepath, ME_proc.message);
            end

            finalMatrixToReshape = [];
            expectedNumFeatures = 0;
            try
                if app.UseRGB % Modo RGB
                    expectedNumFeatures = prod(app.ImageSize) * 3;
                    currentChannels = size(imgDouble, 3);
                    if currentChannels == 1; finalMatrixToReshape = cat(3, imgDouble, imgDouble, imgDouble); % Gris a RGB
                    elseif currentChannels == 3; finalMatrixToReshape = imgDouble; % Ya es RGB
                    else; error('Canales inesperados (%d) para modo RGB.', currentChannels); end
                else % Modo Grisescala
                    expectedNumFeatures = prod(app.ImageSize) * 1;
                    currentChannels = size(imgDouble, 3);
                    if currentChannels == 3; finalMatrixToReshape = rgb2gray(imgDouble); % Color a Gris
                    elseif currentChannels == 1; finalMatrixToReshape = imgDouble; % Ya es Gris
                    else; error('Canales inesperados (%d) para modo Grisescala.', currentChannels); end
                end
            catch ME_chan
                error('Error manejando canales de imagen: %s', ME_chan.message);
            end

            % Validar antes de reshape
            if numel(finalMatrixToReshape) ~= expectedNumFeatures
                error('Discrepancia elementos (%d) vs Features Esperados (%d). Revisar ImageSize/UseRGB.', numel(finalMatrixToReshape), expectedNumFeatures);
            end
            app.NumFeatures = expectedNumFeatures; % Actualizar propiedad global

            try % Reshape final
                imgVector = reshape(finalMatrixToReshape, app.NumFeatures, 1);
            catch ME_reshape
                error('Error inesperado en reshape final: %s', ME_reshape.message);
            end
        end

        % Carga datos de IMAGEN (X: imágenes procesadas, Y: desde archivo .txt)
        function [success, XData, YData, nFeatures, nOutputs] = loadImageData(app)
            success = false; XData = []; YData = []; nFeatures = NaN; nOutputs = NaN; % Usar NaN como default
            folderPath = app.InputFolderTrain;
            targetPath = app.TargetFileTrain;

            % Validaciones iniciales de rutas
            if isempty(folderPath) || ~isfolder(folderPath); app.showError('Selecciona una carpeta válida para imágenes.'); return; end
            if isempty(targetPath) || ~isfile(targetPath); app.showError('Selecciona un archivo .txt válido para salidas deseadas.'); return; end

            imgFiles = [dir(fullfile(folderPath, '*.png')); dir(fullfile(folderPath, '*.jpg')); dir(fullfile(folderPath, '*.jpeg'))];
            if isempty(imgFiles); app.showError('No se encontraron imágenes (.png, .jpg, .jpeg) en la carpeta.'); return; end

            try
                % Leer Salidas deseadas PRIMERO para saber nOutputs y nPatterns esperado
                Y_desired_matrix = readmatrix(targetPath);
                if isempty(Y_desired_matrix); error('El archivo de salidas deseadas está vacío o no es numérico.'); end
                numPatternsExpected = size(Y_desired_matrix, 1);
                nOutputs = size(Y_desired_matrix, 2);

                if numPatternsExpected ~= length(imgFiles)
                    app.showError(sprintf('Número imágenes (%d) != filas salidas (%d).', length(imgFiles), numPatternsExpected)); return;
                end

                app.showStatus(sprintf('Procesando %d imágenes...', length(imgFiles)), 'entrenamiento');
                drawnow;

                % Procesar primera imagen para determinar nFeatures
                firstImgVector = app.preprocessImage(fullfile(imgFiles(1).folder, imgFiles(1).name));
                nFeatures = length(firstImgVector);
                if isnan(nFeatures) || nFeatures <=0; error('No se pudo determinar nFeatures desde la primera imagen.'); end

                % Pre-alocar memoria
                XData = zeros(nFeatures, length(imgFiles));
                YData = zeros(nOutputs, length(imgFiles)); % Formato [nOutputs x nPatterns]

                XData(:, 1) = firstImgVector;
                YData(:, 1) = Y_desired_matrix(1, :)'; % Transponer fila a columna

                % Procesar resto de imágenes
                for i = 2:length(imgFiles)
                    imgPath = fullfile(imgFiles(i).folder, imgFiles(i).name);
                    imgVector = app.preprocessImage(imgPath);
                    if size(imgVector, 1) ~= nFeatures % Revalidar consistencia
                        error('Inconsistencia tamaño vector imagen para %s.', imgFiles(i).name);
                    end
                    XData(:, i) = imgVector;
                    YData(:, i) = Y_desired_matrix(i, :)';
                end

                app.NumFeatures = nFeatures; % Actualizar propiedades
                app.NumOutputs = nOutputs;
                success = true;
                app.showStatus(sprintf('Carga Imagen: %d patrones (%dx%d) OK.', size(XData,2), nFeatures, nOutputs), 'entrenamiento');

            catch ME
                app.showError(['Error cargando datos Imagen/Target: ' ME.message]);
                % Limpiar en caso de error
                success = false; XData = []; YData = []; app.NumFeatures = NaN; app.NumOutputs = NaN;
            end
        end

        % Carga datos de MATRIZ (X e Y desde archivos .txt usando readmatrix)
        function [success, XData, YData, nFeatures, nOutputs] = loadMatrixData(app)
            success = false; XData = []; YData = []; nFeatures = NaN; nOutputs = NaN;
            inputFile = app.InputFileTrain;
            targetFile = app.TargetFileTrain;

            % Validar que AMBOS archivos estén definidos ANTES de intentar leer
            if isempty(inputFile) || ~isfile(inputFile); app.showError('Selecciona un archivo .txt válido para ENTRADAS.'); return; end
            if isempty(targetFile) || ~isfile(targetFile); app.showError('Selecciona un archivo .txt válido para SALIDAS DESEADAS.'); return; end

            try
                % Leer Entradas (asumiendo formato números separados por espacios)
                app.showStatus(sprintf('Leyendo entrada %s...', inputFile), 'entrenamiento'); drawnow;
                X_read = readmatrix(inputFile, 'Delimiter', ' '); 
                if isempty(X_read); error('readmatrix devolvió matriz vacía para entradas.'); end
                [numPatterns, nFeatures] = size(X_read);
                if numPatterns <= 0 || nFeatures <= 0; error('Archivo de entrada parece vacío o inválido.'); end
                XData = X_read'; % Transponer a [nFeatures x numPatterns]
                % --- INICIO DEBUG NaN/Inf en XData ---
                if any(isnan(XData(:))) || any(isinf(XData(:)))
                    warning('¡PROBLEMA! Se detectaron NaN o Inf en XData justo después de leer y transponer el archivo de entrada.');
                    % Opcional: encontrar qué patrones (columnas) tienen el problema
                    columnas_con_nan = find(any(isnan(XData), 1));
                    columnas_con_inf = find(any(isinf(XData), 1));
                    if ~isempty(columnas_con_nan); fprintf(2,'Columnas con NaN en XData: %s\n', mat2str(columnas_con_nan)); end
                    if ~isempty(columnas_con_inf); fprintf(2,'Columnas con Inf en XData: %s\n', mat2str(columnas_con_inf)); end
                    % Puedes decidir detener la carga aquí si quieres:
                    % success = false; return;
                else
                    fprintf('DEBUG: Verificación NaN/Inf en XData pasada.\n');
                end
                % --- FIN DEBUG NaN/Inf en XData ---
                app.showStatus(sprintf('Entrada OK (%dx%d). Leyendo salidas %s...', nFeatures, numPatterns, targetFile), 'entrenamiento'); drawnow;

                % Leer Salidas Deseadas
                YDataMatrix = readmatrix(targetFile);
                if isempty(YDataMatrix); error('readmatrix devolvió matriz vacía para salidas.'); end

                % Validar coincidencia de patrones
                if size(YDataMatrix, 1) ~= numPatterns
                    error('Patrones entrada (%d) != Filas salidas (%d).', numPatterns, size(YDataMatrix,1));
                end
                nOutputs = size(YDataMatrix, 2);
                YData = YDataMatrix'; % Transponer a [nOutputs x numPatterns]

                app.NumFeatures = nFeatures; % Actualizar propiedades
                app.NumOutputs = nOutputs;
                success = true;
                app.showStatus(sprintf('Carga Matriz: %d patrones (%dx%d) OK.', size(XData,2), nFeatures, nOutputs), 'entrenamiento');

            catch ME
                app.showError(['Error cargando datos Matriz/Target: ' ME.message]);
                % Limpiar en caso de error
                success = false; XData = []; YData = []; app.NumFeatures = NaN; app.NumOutputs = NaN;
            end
        end

        % Visualiza la arquitectura de la red
        function plotArchitecture(app, axesHandle, architecture)
            if isempty(architecture) || ~isgraphics(axesHandle) || ~isvalid(axesHandle)
                if isgraphics(axesHandle) && isvalid(axesHandle); cla(axesHandle); title(axesHandle, 'Arq. No Definida'); axis(axesHandle, 'off'); end
                return;
            end
            cla(axesHandle); hold(axesHandle, 'on');
            numLayers = length(architecture); layerNodes = architecture; xCoords = 1:numLayers;
            yMax = max(layerNodes); if yMax==0; yMax=1; end % Evitar yMax=0
            nodeCoords = cell(1, numLayers);
            colors = {'#7E2F8E', '#0072BD', '#EDB120', '#77AC30', '#4DBEEE'}; % Colores para capas

            % Dibujar Nodos
            for i = 1:numLayers
                numNodes = layerNodes(i); yPad = 0.1*yMax;
                yCoords = linspace(yPad, yMax-yPad, numNodes); if numNodes == 1; yCoords = 0.5*yMax; end
                nodeCoords{i} = [repmat(xCoords(i), numNodes, 1), yCoords'];
                colorIdx = mod(i-1, length(colors)) + 1;
                plot(axesHandle, nodeCoords{i}(:,1), nodeCoords{i}(:,2), 'o', 'MarkerSize', 8, 'MarkerFaceColor', colors{colorIdx}, 'MarkerEdgeColor', 'k');
            end

            % Dibujar Conexiones (optimizadas para > 1000)
            maxLinesToPlot = 1000;
            for i = 1:(numLayers - 1) % Conectar capa i a i+1
                nodesFrom = nodeCoords{i}; nodesTo = nodeCoords{i+1};
                if isempty(nodesFrom) || isempty(nodesTo); continue; end % Saltar si no hay nodos
                numLinesPossible = size(nodesFrom, 1) * size(nodesTo, 1);
                nodesFromIdx = 1:size(nodesFrom, 1); nodesToIdx = 1:size(nodesTo, 1);

                % Submuestreo si hay demasiadas conexiones (solo para visualización)
                if numLinesPossible > maxLinesToPlot
                    stepFrom = max(1, floor(size(nodesFrom, 1) / sqrt(maxLinesToPlot)));
                    stepTo = max(1, floor(size(nodesTo, 1) / sqrt(maxLinesToPlot)));
                    nodesFromIdx = 1:stepFrom:size(nodesFrom, 1);
                    nodesToIdx = 1:stepTo:size(nodesTo, 1);
                end
                % Dibujar líneas (submuestreadas o todas)
                for n1_idx = nodesFromIdx; for n2_idx = nodesToIdx
                        line(axesHandle, [nodesFrom(n1_idx, 1), nodesTo(n2_idx, 1)], [nodesFrom(n1_idx, 2), nodesTo(n2_idx, 2)], 'Color', [0.8 0.8 0.8], 'LineWidth', 0.5);
                end; end
            end

            % Configuración final del eje
            layerLabels = cell(1, numLayers);
            layerLabels{1} = sprintf('Entrada\n(%d)', layerNodes(1));
            for i = 2:numLayers-1; layerLabels{i} = sprintf('Oculta %d\n(%d)', i-1, layerNodes(i)); end
            layerLabels{numLayers} = sprintf('Salida\n(%d)', layerNodes(end));

            xticks(axesHandle, xCoords); xticklabels(axesHandle, layerLabels);
            ylim(axesHandle, [0 yMax * 1.1]); set(axesHandle, 'YTick', []);
            title(axesHandle, 'Arquitectura de la Red');
            hold(axesHandle, 'off'); axis(axesHandle, 'tight'); % Ajustar ejes
        end

        % Plotea historial de normas de pesos/sesgos
        function plotWeightHistory(app, axesHandle, history_pesos, history_sesgos, savedEpochs)
            cla(axesHandle); hold(axesHandle,'on'); grid(axesHandle,'on');
            title(axesHandle, 'Evolución Norma Pesos y Sesgos'); xlabel(axesHandle, 'Época'); ylabel(axesHandle, 'Norma');
            axesHandle.Box = 'on';

            numWeightSaves = numel(history_pesos); numBiasSaves = numel(history_sesgos);
            if isempty(savedEpochs) || (numWeightSaves == 0 && numBiasSaves == 0)
                text(0.5,0.5,'No hay historial guardado','Parent', axesHandle, 'HorizontalAlignment','center'); hold(axesHandle,'off'); return;
            end

            legendEntries = {}; numLines = 0; % Contador para colores

            % Plot Pesos (Norma Frobenius)
            if numWeightSaves > 0 && ~isempty(history_pesos{1})
                nLayersW = numel(history_pesos{1}); Wnorms = nan(numWeightSaves, nLayersW);
                for k = 1:numWeightSaves; for l = 1:nLayersW
                        if ~isempty(history_pesos{k}{l}); Wnorms(k,l) = norm(history_pesos{k}{l},'fro'); end
                end; end
            plot(axesHandle, savedEpochs, Wnorms, '.-', 'LineWidth', 1);
            for l=1:nLayersW; legendEntries{end+1} = sprintf('||W_%d||_F', l); end
            numLines = numLines + nLayersW;
            end

            % Plot Sesgos (Norma Euclidiana)
            if numBiasSaves > 0 && ~isempty(history_sesgos{1})
                nLayersB = numel(history_sesgos{1}); Bnorms = nan(numBiasSaves, nLayersB);
                for k = 1:numBiasSaves; for l = 1:nLayersB
                        if ~isempty(history_sesgos{k}{l}); Bnorms(k,l) = norm(history_sesgos{k}{l}); end % Norma L2
                end; end
            % Usar 'lines' para colores y 'ColorOrderIndex' para continuar la secuencia
            originalIndex = axesHandle.ColorOrderIndex; axesHandle.ColorOrderIndex = mod(numLines, size(axesHandle.ColorOrder,1))+1;
            plot(axesHandle, savedEpochs, Bnorms, '--o', 'LineWidth', 1);
            axesHandle.ColorOrderIndex = originalIndex; % Restaurar por si acaso
            for l=1:nLayersB; legendEntries{end+1} = sprintf('||b_%d||_2', l); end
            end

            if ~isempty(legendEntries); legend(axesHandle, legendEntries, 'Location','best', 'Interpreter', 'tex'); end % Usar TeX para subíndices
            if numel(savedEpochs) > 1; axesHandle.XLim = [savedEpochs(1) savedEpochs(end)]; axesHandle.XTick = round(linspace(savedEpochs(1), savedEpochs(end), min(numel(savedEpochs),10) ));
            elseif numel(savedEpochs) == 1; axesHandle.XLim = savedEpochs(1) + [-0.5 0.5]; axesHandle.XTick = savedEpochs(1); end
            axesHandle.YLimMode = 'auto'; axesHandle.YTickMode = 'auto';
            hold(axesHandle,'off');
        end

        % Visualiza vector de 35 elem. como matriz 7x5 (corregido para mostrar 7 alto x 5 ancho)
        function displayVectorAsMatrix(app, axesHandle, vectorData)
            cla(axesHandle);
            if isempty(vectorData) || ~isnumeric(vectorData) || numel(vectorData) ~= 35
                title(axesHandle, 'Entrada No Válida (Req. 35 elem)'); axis(axesHandle, 'off'); return;
            end
            try
                matrixData = reshape(vectorData, [5, 7]); % Reshape a 7 filas x 5 columnas
                matrixData = matrixData';
                % NO transponer, usar matrixData directamente
                imagesc(axesHandle, matrixData); % Muestra 7 filas (Y) x 5 columnas (X)
                colormap(axesHandle, gray); axis(axesHandle, 'image', 'off');
                title(axesHandle, 'Entrada (Matriz 7x5)');

                % Añadir texto 0/1
                hold(axesHandle, 'on');
                [rows, cols] = size(matrixData); % rows=7, cols=5
                for r = 1:rows; for c = 1:cols
                        val = matrixData(r, c); % Valor 0 o 1
                        % Cambiar color del texto para contraste
                        textColor = 'red'; if val > 0.5; textColor = 'blue'; end
                        text(axesHandle, c, r, num2str(val), ...
                            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
                            'Color', textColor, 'FontSize', 8, 'FontWeight', 'bold');
                end; end
            hold(axesHandle, 'off');
            catch ME
                cla(axesHandle); title(axesHandle, 'Error Visualización Matriz'); axis(axesHandle, 'off');
                fprintf(2,'Error en displayVectorAsMatrix: %s\n', ME.message);
                % No relanzar error, solo mostrar mensaje en figura
                text(axesHandle, 0.1, 0.5, 'Error al mostrar', 'FontSize', 9, 'Color', 'red');
            end
        end

        % Lee strings de funciones/derivadas desde archivos txt
        function [funcStringsRaw, derivStringsRaw, success] = readFunctionsFromFiles(app, funcPath, derivPath, numFuncsNeeded)
            funcStringsRaw = {}; derivStringsRaw = {}; success = false;
            try
                if ~isfile(funcPath); error('Archivo funciones "%s" no encontrado.', funcPath); end
                if ~isfile(derivPath); error('Archivo derivadas "%s" no encontrado.', derivPath); end

                funcStringsRaw = BP_neuronal_network.leerFunciones(funcPath); % Llama método estático de la clase
                derivStringsRaw = BP_neuronal_network.leerFunciones(derivPath);

                if length(funcStringsRaw) < numFuncsNeeded || length(derivStringsRaw) < numFuncsNeeded
                    error('Se necesitan %d funciones/derivadas, leídas %d func, %d deriv.', numFuncsNeeded, length(funcStringsRaw), length(derivStringsRaw));
                end

                % Tomar solo las necesarias (si se leyeron de más)
                funcStringsRaw = funcStringsRaw(1:numFuncsNeeded);
                derivStringsRaw = derivStringsRaw(1:numFuncsNeeded);
                success = true;
                % app.showStatus('Funciones activación leídas desde archivos.', 'entrenamiento'); % Opcional

            catch ME
                app.showError(sprintf('Error leyendo archivos funciones: %s', ME.message));
                funcStringsRaw = {}; derivStringsRaw = {}; success = false; % Asegurar salida vacía
            end
        end

        % Interpreta el vector de salida de la red en Letra y Color
        function [letra, colorStr] = interpretOutput(app, outputVector)
            if ~isnumeric(outputVector) || isempty(outputVector) || ~isvector(outputVector) || size(outputVector,2) ~= 1 % Asegura que sea vector COLUMNA no vacío
                warning('Entrada inválida para interpretOutput (debe ser vector columna numérico no vacío). Tamaño: %s', mat2str(size(outputVector)));
                letra = 'Error'; colorStr = 'Inválido'; return;
            end
            outputVector = outputVector(:); % Asegurar columna (redundante pero seguro)

            try % Bloque de interpretación (debería funcionar ahora)
                num_outputs_in_vector = length(outputVector); % Longitud real del vector

                % --- Interpretar Vocales ---
                num_vowel_classes = app.NumClassesVowel; % 5
                num_vowel_scores_to_process = min(num_outputs_in_vector, num_vowel_classes);

                letra = sprintf('IdxV? (%d scores)', num_vowel_scores_to_process); % Default
                if num_vowel_scores_to_process > 0
                    vowelScores = outputVector(1 : num_vowel_scores_to_process);
                    [~, vowelIndex] = max(vowelScores);
                    if isscalar(vowelIndex) && vowelIndex >= 1 && vowelIndex <= length(app.VowelMap)
                        letra = app.VowelMap{vowelIndex};
                    else
                        letra = sprintf('IdxV? (%s)', mat2str(vowelIndex));
                    end
                end
                colorStr = 'N/A'; % Default
                start_color_index = app.NumClassesVowel + 1; % 6

                if num_outputs_in_vector >= start_color_index % ¿Hay datos más allá de las vocales?
                    colorScores = outputVector(start_color_index : end);
                    colorStr = sprintf('IdxC? (%d scores)', length(colorScores)); % Default

                    if ~isempty(colorScores)
                        [~, colorIndex] = max(colorScores);
                        if isscalar(colorIndex) && colorIndex >= 1 && colorIndex <= length(app.ColorMap)
                            colorStr = app.ColorMap{colorIndex};
                        else
                            colorStr = sprintf('IdxC? (%s)', mat2str(colorIndex));
                        end
                    end
                end % Fin if colores

            catch ME_interpret
                app.showErrorAlert(ME_interpret, 'Error Durante Interpretación');
                letra = 'Error'; colorStr = 'Proceso'; % Indicar error en la salida
            end
        end % Fin interpretOutput

        % Procesa carpeta de imágenes para obtener matriz XData
        function processImageFolder(app)
            if isempty(app.InputFolderTrain) || ~isfolder(app.InputFolderTrain); error('Carpeta imágenes no válida.'); end

            imgFiles = [dir(fullfile(app.InputFolderTrain, '*.jpg')); dir(fullfile(app.InputFolderTrain, '*.jpeg')); dir(fullfile(app.InputFolderTrain, '*.png'))];
            if isempty(imgFiles); error('No se encontraron imágenes (.jpg, .jpeg, .png) en: %s', app.InputFolderTrain); end

            numFiles = length(imgFiles);
            app.XTrainData = []; % Reiniciar
            app.setStatus(app.MensajesdeestadoLabel, sprintf('Procesando %d imágenes...', numFiles)); drawnow;

            app.calculateNetworkIOFeatures('Imagenes'); % Calcular/Asegurar NumFeatures
            if isnan(app.NumFeatures) || app.NumFeatures <= 0; error('No se pudo determinar NumFeatures para Imágenes.'); end

            tempX = zeros(app.NumFeatures, numFiles); % Pre-alocar
            validCount = 0; failedFiles = {}; % Trackear fallos

            for k = 1:numFiles
                filepath = fullfile(imgFiles(k).folder, imgFiles(k).name);
                try
                    imgVector = app.preprocessImage(filepath);
                    if ~isempty(imgVector) && size(imgVector,1) == app.NumFeatures && size(imgVector,2) == 1
                        validCount = validCount + 1;
                        tempX(:, validCount) = imgVector;
                    else
                        warning('Vector inválido de preprocessImage para %s. Saltando.', imgFiles(k).name); failedFiles{end+1}=imgFiles(k).name;
                    end
                catch ME
                    warning('Error procesando %s: %s. Saltando.', imgFiles(k).name, ME.message); failedFiles{end+1}=imgFiles(k).name;
                end
            end

            if validCount > 0
                app.XTrainData = tempX(:, 1:validCount); % Redimensionar a válidas
                finalMsg = sprintf('Procesadas %d/%d imágenes válidas. Datos X listos.', validCount, numFiles);
                if ~isempty(failedFiles); finalMsg = [finalMsg, sprintf(' (%d fallaron)', length(failedFiles))]; end
                app.setStatus(app.MensajesdeestadoLabel, finalMsg);
            else
                app.XTrainData = [];
                error('No se pudieron procesar imágenes válidas de la carpeta.');
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Limpiar ejes al inicio
            cla(app.VerArquitectura); axis(app.VerArquitectura, 'off'); title(app.VerArquitectura, 'Arquitectura');
            cla(app.UIAxes2); axis(app.UIAxes2, 'off'); title(app.UIAxes2, 'Error vs Épocas');
            cla(app.UIAxes3); axis(app.UIAxes3, 'off'); title(app.UIAxes3, 'Norma Pesos vs Épocas');
            cla(app.ImagenCargada); axis(app.ImagenCargada, 'off'); title(app.ImagenCargada, 'Imagen Aplicación');

            % Cargar Configuración
            configFilePath = fullfile(fileparts(mfilename('fullpath')), 'config.txt');
            config = BP_neuronal_network.leerConfig(configFilePath); % Usa método estático

            % Establecer Valores en UI (desde config o defaults)
            if isfield(config, 'alpha'); app.AlphaEditField.Value = config.alpha; else; app.AlphaEditField.Value = 0.02; end % Default Alpha
            if isfield(config, 'beta'); app.BetaEditField.Value = config.beta; else; app.BetaEditField.Value = 0.1; end % Default Beta
            if isfield(config, 'precision'); app.PrecisinEditField.Value = config.precision; else; app.PrecisinEditField.Value = 0.005; end % Default Precision

            defaultArchNum = []; defaultArchStr = '[784 15 8]'; % Default Arch String
            if isfield(config, 'arquitectura')
                try
                    % Intenta usar la arquitectura de config.txt si es válida
                    if isnumeric(config.arquitectura) && isvector(config.arquitectura) && all(config.arquitectura>0)
                        app.ArquitecturaEditField.Value = mat2str(config.arquitectura); % Muestra sin brackets si usamos el nuevo parser
                        % Para mantener consistencia con el parser sin [], podríamos mostrarlo como string sin []:
                        % app.ArquitecturaEditField.Value = sprintf('%d ', config.arquitectura);
                        defaultArchNum = config.arquitectura;
                    else
                        warning('Arquitectura en config.txt no válida. Usando default.');
                        app.ArquitecturaEditField.Value = defaultArchStr; % Usar default string si la de config es inválida
                        defaultArchNum = str2num(defaultArchStr); %#ok<ST2NM>
                    end
                catch
                    warning('Error leyendo arquitectura de config. Usando default.');
                    app.ArquitecturaEditField.Value = defaultArchStr;
                    defaultArchNum = str2num(defaultArchStr); %#ok<ST2NM>
                end
            else
                app.ArquitecturaEditField.Value = defaultArchStr; % Default si no existe en config
                defaultArchNum = str2num(defaultArchStr); %#ok<ST2NM>
            end

            % Plotear Arquitectura Inicial (si es válida)
            app.PlottedArchitecture = []; % Resetear
            if ~isempty(defaultArchNum)
                try
                    app.plotArchitecture(app.VerArquitectura, defaultArchNum);
                    app.PlottedArchitecture = defaultArchNum;
                    app.showStatus('Arquitectura inicial mostrada.', 'entrenamiento');
                catch ME_plot
                    warning('No se pudo plotear la arquitectura inicial: %s', ME_plot.message);
                    title(app.VerArquitectura, 'Arquitectura (Definir y Entrenar)');
                end
            else
                title(app.VerArquitectura, 'Arquitectura (No definida)');
            end

            % Establecer rutas de archivo por defecto
            app.GuardarPesosenEditField.Value = 'pesos_entrenados.txt';
            app.PesosEditField.Value = 'pesos_entrenados.txt'; % Para pestaña aplicación

            % Mostrar imagen por defecto en pestaña Aplicación
            try
                defaultImagePath = fullfile(fileparts(mfilename('fullpath')), 'VOCALES_IMAGE.png');
                if isfile(defaultImagePath)
                    imgData = imread(defaultImagePath);
                    imshow(imgData, 'Parent', app.ImagenCargada);
                    axis(app.ImagenCargada, 'image'); axis(app.ImagenCargada, 'off');
                    title(app.ImagenCargada, 'Imagen de Referencia');
                else
                    title(app.ImagenCargada, 'Imagen Defecto No Encontrada');
                end
            catch % Ignorar errores si no se encuentra la imagen por defecto
            end

            app.showStatus('Listo.', 'entrenamiento');
            app.showStatus('Listo.', 'aplicacion');

        end

        % Button pushed function: SeleccionararchivoButton
        function SeleccionararchivoButtonPushed(app, event)
            mode = app.CasoaEntrenarDropDown.Value;
            app.XTrainData = []; app.YTrainTargets = []; % Limpiar datos previos
            app.InputFolderTrain = ''; app.InputFileTrain = '';
            app.EntradasEditField.Value = '';
            app.setStatus(app.MensajesdeestadoLabel, sprintf('Seleccionando entrada para modo %s...', mode));
            drawnow;

            try
                if strcmpi(mode, 'Imagenes')
                    folderName = uigetdir('', 'Selecciona la CARPETA con imágenes...');
                    % Chequeo robusto para cancelación o selección vacía
                    if isequal(folderName, 0) || isempty(folderName)
                        app.setStatus(app.MensajesdeestadoLabel, 'Selección carpeta cancelada.');
                        return;
                    end
                    app.EntradasEditField.Value = folderName;
                    app.InputFolderTrain = folderName;
                    app.InputFileTrain = '';
                    app.setStatus(app.MensajesdeestadoLabel, 'Carpeta seleccionada. Procesando imágenes...');
                    drawnow;
                    try
                        app.processImageFolder(); % Lee imágenes y genera XTrainData
                        if ~isempty(app.XTrainData)
                            app.setStatus(app.MensajesdeestadoLabel, sprintf('%d Imágenes procesadas. Seleccione salidas deseadas.', size(app.XTrainData, 2)));
                        else
                            app.setStatus(app.MensajesdeestadoLabel, 'No se procesaron imágenes válidas. Revise carpeta/consola.');
                            app.InputFolderTrain = ''; app.EntradasEditField.Value = ''; % Limpiar si no se procesó nada
                        end
                    catch ME_procImg
                        app.showErrorAlert(ME_procImg, 'Error procesando imágenes');
                        app.InputFolderTrain = ''; app.EntradasEditField.Value = ''; % Limpiar en error
                    end

                elseif strcmpi(mode, 'Matriz')
                    [fileName, pathName] = uigetfile({'*.txt','Archivos TXT (*.txt)'}, 'Selecciona archivo TXT de ENTRADA...');
                    % Chequeo robusto para cancelación
                    if isequal(fileName, 0) || isequal(pathName, 0)
                        app.setStatus(app.MensajesdeestadoLabel, 'Selección archivo entrada cancelada.');
                        return;
                    end
                    fullPath = fullfile(pathName, fileName);
                    app.EntradasEditField.Value = fullPath; % Actualizar UI
                    app.InputFileTrain = fullPath; % Guardar ruta para usarla LUEGO en Entrenar
                    app.InputFolderTrain = ''; % Limpiar ruta de carpeta
                    app.setStatus(app.MensajesdeestadoLabel, 'Archivo de entrada TXT seleccionado. Ahora seleccione salidas.');

                else % Modo no reconocido
                    app.showErrorAlert(struct('message',sprintf('Modo "%s" no reconocido.', mode)), 'Error Modo');
                end
            catch ME % Error general durante selección
                app.showErrorAlert(ME, sprintf('Error Procesando Selección de Entrada (%s)', mode));
                app.InputFolderTrain = ''; app.InputFileTrain = ''; app.EntradasEditField.Value = ''; % Limpiar rutas
            end
        end

        % Button pushed function: SeleccionararchivoButton_2
        function SeleccionararchivoButton_2Pushed(app, event)
            filter = {'*.txt', 'Archivos de Texto (*.txt)'};
            dialogTitle = 'Selecciona el archivo .TXT con las salidas deseadas';
            [fileName, pathName] = uigetfile(filter, dialogTitle);

            % Chequeo robusto para cancelación
            if isequal(fileName, 0) || isequal(pathName, 0)
                app.showStatus('Selección de archivo de salidas cancelada.', 'entrenamiento');
                app.SalidasdeseadasEditField.Value = ''; % Opcional: Limpiar campo si se cancela
                app.TargetFileTrain = '';
                return;
            end
            fullPath = fullfile(pathName, fileName);
            app.SalidasdeseadasEditField.Value = fullPath;
            app.TargetFileTrain = fullPath;
            app.showStatus(sprintf('Archivo salidas: %s', fileName), 'entrenamiento');
        end

        % Button pushed function: SeleccionararchivoButton_3
        function SeleccionararchivoButton_3Pushed(app, event)
            filter = {'*.txt', 'Archivos de Texto (*.txt)'};
            dialogTitle = 'Selecciona el archivo .TXT con pesos iniciales (Opcional)';
            [fileName, pathName] = uigetfile(filter, dialogTitle);

            % Chequeo robusto para cancelación
            if isequal(fileName, 0) || isequal(pathName, 0)
                app.showStatus('Selección de pesos iniciales cancelada.', 'entrenamiento');
                app.PesosInicialesEditField.Value = ''; % Limpiar campo si se cancela
                app.InitialWeightsFileTrain = '';
                return;
            end
            fullPath = fullfile(pathName, fileName);
            app.PesosInicialesEditField.Value = fullPath;
            app.InitialWeightsFileTrain = fullPath;
            app.showStatus(sprintf('Archivo pesos iniciales: %s', fileName), 'entrenamiento');
        end

        % Button pushed function: EntrenarButton
        function EntrenarButtonPushed(app, event)
            app.showStatus('Iniciando proceso de entrenamiento...', 'entrenamiento');
            app.pocasEditField.Value = 0;
            app.ErrorFinalEditField.Value = -1; % Usar -1 como indicador inicial/error
            app.ErrorGeneralEditField.Value = -1;
            drawnow;

            mode = app.CasoaEntrenarDropDown.Value;
            if strcmpi(mode, 'Imagenes'); app.UseRGB = true; else; app.UseRGB = false; end
            fprintf('Modo %s detectado.\n', mode);

            % --- Leer y Validar Parámetros UI ---
            try
                app.CurrentAlpha = app.AlphaEditField.Value;
                app.CurrentBeta = app.BetaEditField.Value;
                app.CurrentPrecision = app.PrecisinEditField.Value;
                architectureStr = app.ArquitecturaEditField.Value;
                app.WeightsOutputFileTrain = app.GuardarPesosenEditField.Value;
                app.InitialWeightsFileTrain = app.PesosInicialesEditField.Value;

                if ~(isscalar(app.CurrentAlpha) && isfinite(app.CurrentAlpha) && app.CurrentAlpha > 0); app.showError('Alpha debe ser un número finito > 0.'); return; end
                if ~(isscalar(app.CurrentBeta) && isfinite(app.CurrentBeta) && app.CurrentBeta >= 0 && app.CurrentBeta < 1); app.showError('Beta debe ser un número finito en [0, 1).'); return; end
                if ~(isscalar(app.CurrentPrecision) && isfinite(app.CurrentPrecision) && app.CurrentPrecision > 0); app.showError('Precisión debe ser un número finito > 0.'); return; end

                if isempty(app.WeightsOutputFileTrain)
                    app.WeightsOutputFileTrain = fullfile(pwd, 'pesos_entrenados_default.txt');
                    app.GuardarPesosenEditField.Value = app.WeightsOutputFileTrain;
                    app.showStatus('Usando nombre default para guardar pesos.', 'entrenamiento');
                end

                if isempty(strtrim(architectureStr)); app.showError('El campo Arquitectura no puede estar vacío.'); return; end
                tempArch = [];
                try
                    parsedArch = str2num(architectureStr); %#ok<ST2NM>
                    if isempty(parsedArch) || ~isnumeric(parsedArch)
                        numericCells = strsplit(strtrim(architectureStr));
                        parsedArch = str2double(numericCells);
                        if any(isnan(parsedArch)); error('La arquitectura contiene caracteres no numéricos.'); end
                    end
                    tempArch = parsedArch;
                catch ME_parseArch
                    app.showError(sprintf('Error al interpretar arquitectura "%s": %s. Use enteros positivos separados por espacio.', architectureStr, ME_parseArch.message));
                    return;
                end

                if isempty(tempArch) || ~isvector(tempArch) || any(~isfinite(tempArch)) || any(isnan(tempArch)) || any(tempArch <= 0) || any(mod(tempArch,1)~=0)
                    app.showError('Arquitectura debe ser secuencia de enteros positivos finitos, separados por espacios (ej: 35 15 5).');
                    return;
                end
                app.CurrentArchitecture = tempArch; % Guardar arquitectura validada

            catch ME
                app.showError(['Error leyendo parámetros UI: ' ME.message]); return;
            end

            % --- Cargar Datos según el Modo ---
            successLoad = false; app.XTrainData = []; app.YTrainTargets = []; app.NumFeatures = NaN; app.NumOutputs = NaN;
            try
                if strcmpi(mode, 'Imagenes')
                    [successLoad, app.XTrainData, app.YTrainTargets, app.NumFeatures, app.NumOutputs] = app.loadImageData();
                elseif strcmpi(mode, 'Matriz')
                    [successLoad, app.XTrainData, app.YTrainTargets, app.NumFeatures, app.NumOutputs] = app.loadMatrixData();
                    % Validación específica para Matriz (asumiendo 35 características)
                    if successLoad && (~isscalar(app.NumFeatures) || app.NumFeatures ~= 35)
                        app.showError(sprintf('Modo Matriz: Se esperaban 35 características de entrada, pero se cargaron %d.', app.NumFeatures));
                        successLoad = false; % Marcar como fallo
                    end
                else
                    app.showError(sprintf('Modo "%s" no soportado para carga de datos.', mode)); return;
                end
                if ~successLoad
                    error('Fallo al cargar los datos de entrenamiento. Revisa archivos/consola.'); % Lanza error para ir al catch
                end
            catch ME
                app.showError(['Error cargando datos: ' ME.message]); return;
            end


            % --- Validar Arquitectura Final contra Datos Cargados ---
            try
                if ~isscalar(app.NumFeatures) || isnan(app.NumFeatures) || app.CurrentArchitecture(1) ~= app.NumFeatures
                    error('Arquitectura inicial (%d neuronas) no coincide con características de entrada (%d).', app.CurrentArchitecture(1), app.NumFeatures);
                end
                if ~isscalar(app.NumOutputs) || isnan(app.NumOutputs) || app.CurrentArchitecture(end) ~= app.NumOutputs
                    error('Arquitectura final (%d neuronas) no coincide con características de salida (%d).', app.CurrentArchitecture(end), app.NumOutputs);
                end

                % Advertencias opcionales sobre número de salidas esperado
                if strcmpi(mode, 'Imagenes') && app.NumOutputs ~= 8; warning('App:NumOutputsMismatch', 'Modo Imágenes: Se esperaban 8 salidas, detectadas %d.', app.NumOutputs); end
                if strcmpi(mode, 'Matriz') && app.NumOutputs ~= 5; warning('App:NumOutputsMismatch', 'Modo Matriz: Se esperaban 5 salidas, detectadas %d.', app.NumOutputs); end

                app.showStatus(sprintf('Arq. validada: [%s] vs Datos (%d Ent, %d Sal)', num2str(app.CurrentArchitecture), app.NumFeatures, app.NumOutputs), 'entrenamiento');

                if ~isequal(app.CurrentArchitecture, app.PlottedArchitecture)
                    app.showStatus('Actualizando diagrama arquitectura...', 'entrenamiento'); drawnow;
                    try
                        app.plotArchitecture(app.VerArquitectura, app.CurrentArchitecture);
                        app.PlottedArchitecture = app.CurrentArchitecture;
                    catch ME_plot
                        warning('Error dibujando arquitectura: %s', ME_plot.message); title(app.VerArquitectura, 'Error Dibujo Arq.');
                    end
                end
            catch ME
                app.showError(['Error validando arquitectura vs datos: ' ME.message]); return;
            end

            % --- Leer Funciones e Inicializar Red ---
            try
                numFuncLayers = length(app.CurrentArchitecture) - 1;
                if numFuncLayers < 1; error('Arquitectura debe tener al menos 2 capas.'); end

                basePath = fileparts(mfilename('fullpath'));
                funcFilePath = fullfile(basePath, 'funciones_activacion.txt');
                derivFilePath = fullfile(basePath, 'derivadas_activacion.txt');
                [funcStringsRaw, derivStringsRaw, successReadFuncs] = app.readFunctionsFromFiles(funcFilePath, derivFilePath, numFuncLayers);
                if ~successReadFuncs; error('No se pudieron leer funciones/derivadas desde archivos txt.'); end

                app.showStatus('Inicializando red neuronal...', 'entrenamiento'); drawnow;
                app.NeuralNetwork = BP_neuronal_network(app.CurrentArchitecture, funcStringsRaw, derivStringsRaw);

                initialWeightsLoaded = false;
                if ~isempty(app.InitialWeightsFileTrain) && isfile(app.InitialWeightsFileTrain)
                    app.showStatus(sprintf('Cargando pesos iniciales desde %s...', app.InitialWeightsFileTrain), 'entrenamiento'); drawnow;
                    try
                        tempNet = BP_neuronal_network();
                        [tempNet, loadedArch] = tempNet.cargarPesos(app.InitialWeightsFileTrain);
                        if ~isequal(loadedArch, app.CurrentArchitecture)
                            error('Arq. pesos iniciales [%s] != Arq. actual [%s].', mat2str(loadedArch), mat2str(app.CurrentArchitecture));
                        end
                        app.NeuralNetwork = app.NeuralNetwork.setInitialWeights(tempNet.Pesos, tempNet.Sesgos);
                        initialWeightsLoaded = true;
                        app.showStatus('Pesos iniciales cargados desde archivo.', 'entrenamiento');
                    catch ME_loadW
                        warning('Fallo carga/validación pesos iniciales: %s. Usando aleatorios.', ME_loadW.message);
                        app.showStatus('Fallo carga pesos iniciales, usando aleatorios.', 'entrenamiento');
                    end
                end
                if ~initialWeightsLoaded; app.showStatus('Usando pesos iniciales aleatorios.', 'entrenamiento'); end

            catch ME
                app.showError(['Error inicialización red: ' ME.message]); return;
            end

            % --- Entrenar la Red ---
            try
                app.showStatus('Iniciando entrenamiento (puede tardar)...', 'entrenamiento'); drawnow;
                cla(app.UIAxes2); title(app.UIAxes2, 'Error MSE/2 vs Épocas'); xlabel(app.UIAxes2, 'Época'); ylabel(app.UIAxes2, 'Error (MSE/2)'); grid(app.UIAxes2,'on'); hold(app.UIAxes2, 'on'); app.UIAxes2.Box='on'; app.UIAxes2.XAxis.Visible='on'; app.UIAxes2.YAxis.Visible='on';
                cla(app.UIAxes3); title(app.UIAxes3, 'Norma Pesos/Sesgos vs Épocas'); grid(app.UIAxes3, 'on'); hold(app.UIAxes3, 'off'); app.UIAxes3.Box='on'; app.UIAxes3.XAxis.Visible='on'; app.UIAxes3.YAxis.Visible='on';
                drawnow;
                if gpuDeviceCount > 0 % Solo intentar activar si hay GPU
                    app.NeuralNetwork.UseGPU = true; % Activar la propiedad en el objeto red
                    fprintf('Intentando usar GPU...\n');
                else
                    app.NeuralNetwork.UseGPU = false;
                    fprintf('No se detectó GPU compatible.\n');
                end

                [app.NeuralNetwork, finalEpoch, finalError, history_error, history_pesos, history_sesgos, savedEpochs] = ...
                    app.NeuralNetwork.entrenar(app.XTrainData, app.YTrainTargets, ...
                    app.CurrentAlpha, app.CurrentPrecision, app.CurrentBeta,...
                    app.UIAxes2 ); % Pasar handle para plot animado

                app.TrainingHistoryError = history_error;
                app.TrainingHistoryPesoCells = history_pesos;
                app.TrainingHistorySesgoCells = history_sesgos;
                app.SavedEpochs = savedEpochs;

                % --- Mostrar Resultados, Evaluar y Guardar ---
                validatedEpoch = 0; if isscalar(finalEpoch) && isfinite(finalEpoch) && finalEpoch >= 0; validatedEpoch = finalEpoch; else; warning('finalEpoch inválido (%s).', mat2str(finalEpoch)); end
                app.pocasEditField.Value = validatedEpoch;

                validatedError = -1; statusMsg = sprintf('Entrenamiento finalizado en %d épocas', validatedEpoch);
                if isscalar(finalError) && isfinite(finalError)
                    validatedError = finalError; statusMsg = sprintf('%s con error MSE/2 = %.6f', statusMsg, validatedError);
                else
                    statusMsg = sprintf('%s con error final NO VÁLIDO (%s).', statusMsg, mat2str(finalError)); warning('finalError inválido (%s).', mat2str(finalError));
                end
                app.ErrorFinalEditField.Value = validatedError;
                app.showStatus(statusMsg, 'entrenamiento'); drawnow;

                % Ajustes finales ejes error
                hold(app.UIAxes2, 'off');
                if validatedEpoch > 1; app.UIAxes2.XLim = [1 validatedEpoch]; app.UIAxes2.XTick = unique(round(linspace(1, validatedEpoch, min(validatedEpoch, 10))));
                elseif validatedEpoch == 1; app.UIAxes2.XLim = [0.5 1.5]; app.UIAxes2.XTick = 1;
                else; app.UIAxes2.XLimMode = 'auto'; app.UIAxes2.XTickMode = 'auto'; end
                app.UIAxes2.YLimMode = 'auto'; app.UIAxes2.YTickMode = 'auto';
                drawnow;

                % Evaluación Clasificación
                accuracyValue = -1; app.ErrorGeneralEditField.Value = -1; drawnow;
                try
                    app.showStatus(sprintf('%s Calculando precisión...', statusMsg), 'entrenamiento'); drawnow;
                    [~, ConfMatCounts] = app.NeuralNetwork.evaluarClasificacion(app.XTrainData, app.YTrainTargets);
                    if ~isempty(ConfMatCounts) && sum(ConfMatCounts(:)) > 0
                        accuracy = sum(diag(ConfMatCounts)) / sum(ConfMatCounts(:)) * 100;
                        if isscalar(accuracy) && isfinite(accuracy); accuracyValue = accuracy; else; warning('Precisión calculada inválida (%s).', mat2str(accuracy)); end
                    elseif ~isempty(ConfMatCounts) && sum(ConfMatCounts(:)) == 0
                        accuracyValue = 0; % Si la matriz suma 0, precisión es 0
                        warning('Matriz de confusión suma cero.');
                    else
                        warning('Matriz de confusión vacía/inválida.');
                    end
                catch ME_eval
                    warning('Error durante evaluación: %s', ME_eval.message);
                end
                app.ErrorGeneralEditField.Value = accuracyValue;
                if accuracyValue >= 0; statusMsg = sprintf('%s Precisión: %.2f%%.', statusMsg, accuracyValue); else; statusMsg = sprintf('%s Precisión no calculada.', statusMsg); end
                app.showStatus(statusMsg, 'entrenamiento'); drawnow;

                % Guardar Pesos
                if ~isempty(app.WeightsOutputFileTrain)
                    try
                        [folder, name, ext] = fileparts(app.WeightsOutputFileTrain);
                        if isempty(ext); ext = '.txt'; end; if isempty(folder); folder = pwd; end
                        if ~isfolder(folder); mkdir(folder); end
                        savePath = fullfile(folder, [name ext]);
                        app.NeuralNetwork.guardarPesos(savePath);
                        statusMsg = sprintf('%s Pesos guardados: %s', statusMsg, savePath); app.showStatus(statusMsg, 'entrenamiento');
                    catch ME_save
                        app.showError(sprintf('Error guardando pesos en "%s": %s', app.WeightsOutputFileTrain, ME_save.message));
                    end
                else
                    statusMsg = sprintf('%s (Pesos no guardados, ruta vacía)', statusMsg); app.showStatus(statusMsg, 'entrenamiento');
                end

                % Guardar Configuración
                try
                    lastConfig = struct('alpha', app.CurrentAlpha, 'beta', app.CurrentBeta, 'precision', app.CurrentPrecision, 'arquitectura', app.CurrentArchitecture);
                    configFilePath = fullfile(fileparts(mfilename('fullpath')), 'config.txt');
                    BP_neuronal_network.guardarConfig(configFilePath, lastConfig);
                    statusMsg = sprintf('%s Configuración guardada.', statusMsg); app.showStatus(statusMsg, 'entrenamiento');
                catch ME_saveConfig
                    warning('No se pudo guardar config.txt: %s', ME_saveConfig.message);
                    statusMsg = sprintf('%s (Error guardando config)', statusMsg); app.showStatus(statusMsg, 'entrenamiento');
                end

                % Graficar Historial Pesos
                if ~isempty(app.TrainingHistoryPesoCells) && ~isempty(app.SavedEpochs)
                    app.showStatus(sprintf('%s Graficando historial pesos...', statusMsg), 'entrenamiento'); drawnow;
                    try
                        app.plotWeightHistory(app.UIAxes3, app.TrainingHistoryPesoCells, app.TrainingHistorySesgoCells, app.SavedEpochs );
                        app.showStatus(sprintf('%s Historial pesos OK.', statusMsg), 'entrenamiento');
                    catch ME_plotW
                        cla(app.UIAxes3); title(app.UIAxes3, 'Error Graf. Pesos'); warning('Error graficando pesos: %s', ME_plotW.message);
                        app.showStatus(sprintf('%s Error graf. pesos.', statusMsg), 'entrenamiento');
                    end
                else
                    cla(app.UIAxes3); title(app.UIAxes3, 'Norma Pesos/Sesgos vs Épocas'); text(app.UIAxes3, 0.5, 0.5, 'No hay historial pesos', 'HorizontalAlignment', 'center');
                    app.showStatus(sprintf('%s (Sin hist. pesos)', statusMsg), 'entrenamiento');
                end

            catch ME % Catch para el bloque de entrenamiento/resultados
                app.showError(['Error durante entrenamiento/resultados: ' ME.message]);
                app.pocasEditField.Value = 0; app.ErrorFinalEditField.Value = -1; app.ErrorGeneralEditField.Value = -1;
                cla(app.UIAxes2); title(app.UIAxes2, 'Error vs Épocas (Fallo)'); grid(app.UIAxes2,'on');
                cla(app.UIAxes3); title(app.UIAxes3, 'Pesos vs Épocas (Fallo)'); grid(app.UIAxes3,'on');
            end
        end

        % Button pushed function: SeleccionararchivoButton_5
        function SeleccionararchivoButton_5Pushed(app, event)
            mode = app.CasoaEstudiarrDropDown.Value;
            app.AppInputVector = []; % Limpiar vector previo
            app.AppMatrixVector = []; % Limpiar vector previo

            if strcmpi(mode, 'Imagenes')
                filter = {'*.png;*.jpg;*.jpeg', 'Imágenes (*.png, *.jpg, *.jpeg)'};
                dialogTitle = 'Selecciona la IMAGEN de entrada';
            elseif strcmpi(mode, 'Matriz')
                filter = {'*.txt', 'Archivo Texto (*.txt)'};
                dialogTitle = 'Selecciona el archivo .TXT con el vector de entrada (núms/espacios)'; % Actualizar si cambió formato
            else
                app.showError('Modo no reconocido en pestaña Aplicación.'); return;
            end

            [fileName, pathName] = uigetfile(filter, dialogTitle);
            if isequal(fileName, 0) || isequal(pathName, 0)
                app.showStatus('Selección de entrada cancelada.', 'aplicacion');
                app.EntradasEditField_2.Value = ''; app.InputFileApp = '';
                cla(app.ImagenCargada); axis(app.ImagenCargada,'off'); title(app.ImagenCargada, 'Imagen/Entrada');
                return;
            end

            fullPath = fullfile(pathName, fileName);
            app.EntradasEditField_2.Value = fullPath;
            app.InputFileApp = fullPath;
            app.Label_4.Text = ''; % Borrar etiqueta de Letra anterior
            app.Label_3.Text = ''; % Borrar etiqueta de Color anterior
            app.showStatus(['Entrada lista: ' fileName], 'aplicacion');

            % Mostrar la imagen/matriz cargada y PREPROCESARLA
            cla(app.ImagenCargada); % Limpiar antes de mostrar/procesar
            try
                if strcmpi(mode, 'Imagenes')
                    imgData = imread(fullPath);
                    imshow(imgData,'Parent',app.ImagenCargada); title(app.ImagenCargada, fileName);
                    app.AppInputVector = app.preprocessImage(fullPath); % Preprocesar y guardar vector
                    app.AppMatrixVector = []; % Asegurar que el otro esté vacío
                elseif strcmpi(mode, 'Matriz')
                    % Asumiendo formato nuevo (números separados por espacio)
                    vectorData = readmatrix(fullPath);
                    if isempty(vectorData) || size(vectorData,1) ~= 1 % Espera UNA fila
                        error('El archivo debe contener una única fila de números separados por espacios.');
                    end
                    vectorData = vectorData(:); % Asegurar que sea vector columna

                    if numel(vectorData) ~= 35 % Validación específica
                        error('El vector leído no tiene 35 elementos.');
                    end
                    app.displayVectorAsMatrix(app.ImagenCargada, vectorData); % Mostrar como matriz 7x5
                    app.AppMatrixVector = vectorData; % Guardar vector
                    app.AppInputVector = []; % Asegurar que el otro esté vacío

                    % Si aún usas el formato string '0011...' en aplicación, descomenta esto y comenta lo anterior
                    % lines = readlines(fullPath); lines = lines(strlength(lines)>0 & ~startsWith(lines,'%'));
                    % if isempty(lines); error('Archivo de matriz vacío o sin datos válidos.'); end
                    % vectorStr = lines{1}; vectorData = double(vectorStr'=='1');
                    % if numel(vectorData)~=35; error('Vector de entrada no tiene 35 elementos.'); end
                    % app.displayVectorAsMatrix(app.ImagenCargada, vectorData);
                    % app.AppMatrixVector = vectorData; app.AppInputVector = [];
                end
                app.showStatus(sprintf('Entrada "%s" cargada y procesada.', fileName), 'aplicacion');
            catch ME_disp
                app.showError(['Error mostrando/procesando entrada: ' ME_disp.message], 'aplicacion');
                cla(app.ImagenCargada); axis(app.ImagenCargada,'off'); title(app.ImagenCargada, 'Error Visualización');
                app.AppInputVector = []; app.AppMatrixVector = []; % Limpiar en error
            end
        end

        % Button pushed function: SeleccionararchivoButton_4
        function SeleccionararchivoButton_4Pushed(app, event)
            filter = {'*.txt', 'Archivos de Texto (*.txt)'};
            dialogTitle = 'Selecciona el archivo .TXT con los pesos entrenados';
            [fileName, pathName] = uigetfile(filter, dialogTitle);

            if isequal(fileName, 0) || isequal(pathName, 0)
                app.showStatus('Selección de pesos cancelada.', 'aplicacion');
                return; % No hacer nada si se cancela
            end

            fullPath = fullfile(pathName, fileName);
            app.PesosEditField.Value = fullPath;
            app.WeightsFileApp = fullPath;
            app.showStatus(['Archivo pesos: ' fileName], 'aplicacion');
            app.LoadedAppNetwork = BP_neuronal_network(); % Invalidar red cargada previamente
        end

        % Button pushed function: SeleccionarrutaButton
        function SeleccionarRutaButtonPushed(app, event)
            defaultName = app.GuardarPesosenEditField.Value;
            if isempty(defaultName); defaultName = 'pesos_entrenados.txt'; end

            filter = {'*.txt', 'Archivos de Texto (*.txt)'};
            dialogTitle = 'Guardar pesos entrenados como...';
            [fileName, pathName] = uiputfile(filter, dialogTitle, defaultName);

            if isequal(fileName, 0) || isequal(pathName, 0)
                app.showStatus('Guardado de pesos cancelado.', 'entrenamiento');
                return; % No hacer nada si se cancela
            end

            fullPath = fullfile(pathName, fileName);
            app.GuardarPesosenEditField.Value = fullPath;
            app.WeightsOutputFileTrain = fullPath;
            app.showStatus(['Guardar pesos en: ' fileName], 'entrenamiento');
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            mode = app.CasoaEstudiarrDropDown.Value;
            inputVector = [];

            % Obtener vector de entrada preprocesado correcto según el modo
            if strcmpi(mode, 'Imagenes') && ~isempty(app.AppInputVector)
                inputVector = app.AppInputVector;
            elseif strcmpi(mode, 'Matriz') && ~isempty(app.AppMatrixVector)
                inputVector = app.AppMatrixVector;
            end

            if isempty(inputVector)
                app.showErrorAlert(struct('message','Carga una entrada (Imagen o Matriz) y asegúrate que se procesó correctamente.'),'Entrada Faltante'); return;
            end

            % Determinar qué archivo de pesos usar
            pesosAUsar = '';
            if ~isempty(app.WeightsFileApp) && isfile(app.WeightsFileApp)
                pesosAUsar = app.WeightsFileApp;
                app.setStatus(app.Label_5, 'Usando pesos seleccionados...');
            elseif ~isempty(app.WeightsOutputFileTrain) && isfile(app.WeightsOutputFileTrain)
                pesosAUsar = app.WeightsOutputFileTrain;
                app.setStatus(app.Label_5, 'Usando pesos del último entrenamiento...');
            else
                app.showErrorAlert(struct('message','No se encontró archivo de pesos entrenados (entrena o selecciona uno).'),'Pesos Faltantes');
                return;
            end
            drawnow;
            % Cargar Red y Ejecutar
            try
                app.setStatus(app.Label_5, 'Cargando red...'); drawnow;
                tempNet = BP_neuronal_network(); % Instancia vacía
                [tempNet, loadedArch] = tempNet.cargarPesos(pesosAUsar); % Cargar pesos y obtener arquitectura
                try
                    numFuncLayers = length(loadedArch) - 1; % Capas ocultas + salida
                    if numFuncLayers >= 1 % Solo si hay capas a las que asignar funciones
                        basePath = fileparts(mfilename('fullpath'));
                        funcFilePath = fullfile(basePath, 'funciones_activacion.txt');
                        derivFilePath = fullfile(basePath, 'derivadas_activacion.txt');

                        % Usar el helper de la App para leer los strings desde los archivos .txt
                        [funcStringsRaw, derivStringsRaw, successReadFuncs] = app.readFunctionsFromFiles(funcFilePath, derivFilePath, numFuncLayers);
                        if ~successReadFuncs
                            % Lanza un error si no se pueden leer las funciones
                            error('No se pudieron leer funciones/derivadas desde archivos txt necesarios para ejecución.');
                        end

                        % Asignar los handles de función a la red cargada 'tempNet'
                        % Es necesario recrear los handles porque no se guardan en el archivo de pesos
                        tempNet.FuncionesActivacion = cell(1, numFuncLayers);
                        tempNet.DerivadasActivacion = cell(1, numFuncLayers);
                        for ii = 1:numFuncLayers
                            try % Intenta convertir cada string a handle
                                tempNet.FuncionesActivacion{ii} = str2func(['@(net) ' funcStringsRaw{ii}]);
                                tempNet.DerivadasActivacion{ii} = str2func(['@(act) ' derivStringsRaw{ii}]);
                            catch ME_str2func % Error si el string no es válido
                                error('Error convirtiendo string a función (Capa %d -> %s): %s', ...
                                    ii+1, funcStringsRaw{ii}, ME_str2func.message);
                            end
                        end
                        app.setStatus(app.Label_5, sprintf('Red cargada (Arq: [%s]). Funciones leídas. Ejecutando...', num2str(loadedArch)));
                        fprintf('INFO Ejecución: Usando funciones de activación leídas desde archivos .txt.\n');
                    else
                        % No hay capas ocultas/salida, no se necesitan funciones (poco probable)
                        app.setStatus(app.Label_5, sprintf('Red cargada (Arq: [%s]). Sin capas ocultas/salida. Ejecutando...', num2str(loadedArch)));
                        fprintf('INFO Ejecución: No se asignaron funciones (red sin capas ocultas/salida).\n');
                    end
                    drawnow;

                catch ME_func_load % Capturar errores al leer/asignar funciones
                    % Mostrar error y detener la ejecución, ya que sin las funciones correctas
                    % los resultados no serían válidos.
                    app.showErrorAlert(ME_func_load, 'Error Crítico Cargando Funciones Activación');
                    app.setStatus(app.Label_5, 'Error al cargar funciones de activación. Ejecución cancelada.');
                    return; % Detener la ejecución del botón
                end

                if size(inputVector, 1) ~= loadedArch(1)
                    error('Entrada (%d caract) != 1ra capa red cargada (%d).', size(inputVector, 1), loadedArch(1));
                end
                outputVector = tempNet.feedforward(inputVector); % Ejecutar pasada adelante
                % Interpretar y mostrar resultados
                [letra, colorStr] = app.interpretOutput(outputVector); % Usar helper para interpretar
                app.Label_4.Text = ['Letra: ', letra];
                app.Label_3.Text = ['Color: ', colorStr];
                app.setStatus(app.Label_5, 'Ejecución completada.');

            catch ME
                app.setStatus(app.Label_5, ['Error ejecución/carga: ' ME.message]);
                app.showErrorAlert(ME, 'Error en Aplicación');
                app.Label_4.Text = 'Letra: Error';
                app.Label_3.Text = 'Color: Error';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EntrenamientoTab = uitab(app.TabGroup);
            app.EntrenamientoTab.Title = 'Entrenamiento';
            app.UIAxes3 = uiaxes(app.EntrenamientoTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.EntrenamientoTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.VerArquitectura = uiaxes(app.EntrenamientoTab);
            title(app.VerArquitectura, 'Title')
            xlabel(app.VerArquitectura, 'X')
            ylabel(app.VerArquitectura, 'Y')
            zlabel(app.VerArquitectura, 'Z')
            app.EntrenamientoLaboratorio3InteligenciaArtificialLabel = uilabel(app.EntrenamientoTab);
            app.EntrenamientoLaboratorio3InteligenciaArtificialLabel.Text = 'Entrenamiento Laboratorio #3 Inteligencia Artificial';
            app.Image = uiimage(app.EntrenamientoTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMAGOTIPO VERTICAL COLOR.png');
            app.Label = uilabel(app.EntrenamientoTab);
            app.Label.Text = {'Presentado por Juan Esteban Fuentes Rojas, Duvan Santiago Matallana Jiménez y'; ' Laura Estefania Latorre Pachon'};
            app.UniversidaddeCundinamarcaLabel = uilabel(app.EntrenamientoTab);
            app.UniversidaddeCundinamarcaLabel.Text = 'Universidad de Cundinamarca';
            app.DatosdeentrenamientoLabel = uilabel(app.EntrenamientoTab);
            app.DatosdeentrenamientoLabel.Text = 'Datos de entrenamiento';
            app.CargadearchivosporfavorseleccioneunarchivoLabel = uilabel(app.EntrenamientoTab);
            app.CargadearchivosporfavorseleccioneunarchivoLabel.Text = 'Carga de archivos, por favor seleccione un archivo';
            app.SeleccionararchivoButton = uibutton(app.EntrenamientoTab, 'push');
            app.SeleccionararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButtonPushed, true);
            app.SeleccionararchivoButton.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_2 = uibutton(app.EntrenamientoTab, 'push');
            app.SeleccionararchivoButton_2.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_2Pushed, true);
            app.SeleccionararchivoButton_2.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_3 = uibutton(app.EntrenamientoTab, 'push');
            app.SeleccionararchivoButton_3.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_3Pushed, true);
            app.SeleccionararchivoButton_3.Text = 'Seleccionar archivo';
            app.EntrenarButton = uibutton(app.EntrenamientoTab, 'push');
            app.EntrenarButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarButtonPushed, true);
            app.EntrenarButton.Text = 'Entrenar';
            app.ArquitecturaLabel = uilabel(app.EntrenamientoTab);
            app.ArquitecturaLabel.Text = 'Arquitectura';
            app.PesosvspocasLabel = uilabel(app.EntrenamientoTab);
            app.PesosvspocasLabel.Text = 'Pesos vs Épocas';
            app.ErrorvspocasLabel = uilabel(app.EntrenamientoTab);
            app.ErrorvspocasLabel.Text = 'Error vs Épocas';
            app.ArquitecturaEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.ArquitecturaEditFieldLabel.Text = 'Arquitectura';
            app.ArquitecturaEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.ArquitecturaEditField.Placeholder = '35 15 5';
            app.PesosInicialesEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.PesosInicialesEditField.Placeholder = 'C:/Users/folder';
            app.PesosInicialesEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.PesosInicialesEditFieldLabel.Text = {'Pesos'; 'Iniciales'};
            app.GuardarPesosenEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.GuardarPesosenEditFieldLabel.Text = {'Guardar '; 'Pesos en'};
            app.GuardarPesosenEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.GuardarPesosenEditField.Value = 'pesos_entrenados_vocales.txt';
            app.MensajesdeestadoLabel = uilabel(app.EntrenamientoTab);
            app.MensajesdeestadoLabel.Text = '';
            app.CasoaEntrenarDropDownLabel = uilabel(app.EntrenamientoTab);
            app.CasoaEntrenarDropDownLabel.Text = 'Caso a Entrenar';
            app.CasoaEntrenarDropDown = uidropdown(app.EntrenamientoTab);
            app.CasoaEntrenarDropDown.Items = {'Imagenes', 'Matriz'};
            app.CasoaEntrenarDropDown.Value = 'Imagenes';
            app.pocasEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.pocasEditFieldLabel.Text = 'Épocas';
            app.pocasEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.pocasEditField.Editable = 'off';
            app.ErrorFinalEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.ErrorFinalEditFieldLabel.Text = 'Error Final';
            app.ErrorFinalEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.ErrorFinalEditField.Editable = 'off';
            app.EntradasEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.EntradasEditFieldLabel.Text = 'Entradas';
            app.EntradasEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.EntradasEditField.Placeholder = 'C:/Users/folder';
            app.SalidasdeseadasEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.SalidasdeseadasEditFieldLabel.Text = {'Salidas'; 'deseadas'};
            app.SalidasdeseadasEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.SalidasdeseadasEditField.Placeholder = 'C:/Users/folder';
            app.AlphaEditField_2Label = uilabel(app.EntrenamientoTab);
            app.AlphaEditField_2Label.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.AlphaEditField.Value = 0.01;
            app.BetaEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.BetaEditField.Value = 0.01;
            app.BetaEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.PrecisinEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.PrecisinEditFieldLabel.Text = 'Precisión';
            app.PrecisinEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.PrecisinEditField.Value = 0.01;
            app.SeleccionarrutaButton = uibutton(app.EntrenamientoTab, 'push');
            app.SeleccionarrutaButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarRutaButtonPushed, true);
            app.SeleccionarrutaButton.Text = 'Seleccionar ruta';
            app.PrecisionClaficicacinLabel = uilabel(app.EntrenamientoTab);
            app.PrecisionClaficicacinLabel.Text = {'Precision '; 'Clasificación %'};
            app.ErrorGeneralEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.ErrorGeneralEditField.Editable = 'off';
            app.AplicacinTab = uitab(app.TabGroup);
            app.AplicacinTab.Title = 'Aplicación';
            app.ImagenCargada = uiaxes(app.AplicacinTab);
            title(app.ImagenCargada, 'Title')
            zlabel(app.ImagenCargada, 'Z')
            app.AplicacinLaboratorio3InteligenciaArtificialLabel = uilabel(app.AplicacinTab);
            app.AplicacinLaboratorio3InteligenciaArtificialLabel.Text = 'Aplicación Laboratorio #3 Inteligencia Artificial';
            app.Image_2 = uiimage(app.AplicacinTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'IMAGOTIPO VERTICAL COLOR.png');
            app.Label_2 = uilabel(app.AplicacinTab);
            app.Label_2.Text = {'Presentado por Juan Esteban Fuentes Rojas, Duvan Santiago Matallana Jiménez y'; ' Laura Estefania Latorre Pachon'};
            app.UniversidaddeCundinamarcaLabel_2 = uilabel(app.AplicacinTab);
            app.UniversidaddeCundinamarcaLabel_2.Text = 'Universidad de Cundinamarca';
            app.SeleccionararchivoButton_4 = uibutton(app.AplicacinTab, 'push');
            app.SeleccionararchivoButton_4.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_4Pushed, true);
            app.SeleccionararchivoButton_4.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_5 = uibutton(app.AplicacinTab, 'push');
            app.SeleccionararchivoButton_5.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_5Pushed, true);
            app.SeleccionararchivoButton_5.Text = 'Seleccionar archivo';
            app.EjecutarButton = uibutton(app.AplicacinTab, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.ImagencargadaLabel = uilabel(app.AplicacinTab);
            app.ImagencargadaLabel.Text = 'Imagen cargada';
            app.Label_4 = uilabel(app.AplicacinTab);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.AplicacinTab);
            app.Label_3.Text = '';
            app.Label_5 = uilabel(app.AplicacinTab);
            app.Label_5.Text = '';
            app.EntradasEditField_2Label = uilabel(app.AplicacinTab);
            app.EntradasEditField_2Label.Text = 'Entradas';
            app.EntradasEditField_2 = uieditfield(app.AplicacinTab, 'text');
            app.PesosEditFieldLabel = uilabel(app.AplicacinTab);
            app.PesosEditFieldLabel.Text = 'Pesos';
            app.PesosEditField = uieditfield(app.AplicacinTab, 'text');
            app.CasoaEstudiarrDropDownLabel = uilabel(app.AplicacinTab);
            app.CasoaEstudiarrDropDownLabel.Text = 'Caso a Estudiarr';
            app.CasoaEstudiarrDropDown = uidropdown(app.AplicacinTab);
            app.CasoaEstudiarrDropDown.Items = {'Imagenes', 'Matriz'};
            app.CasoaEstudiarrDropDown.Value = 'Imagenes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Ent_Backpropagation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end