classdef App_Mariposas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TratamientoTab                  matlab.ui.container.Tab
        BajarButton                     matlab.ui.control.Button
        SubirButton                     matlab.ui.control.Button
        GuardarsecuenciadeTratamientoButton  matlab.ui.control.Button
        AddedKernelsListBox             matlab.ui.control.ListBox
        KernelsAadidosLabel             matlab.ui.control.Label
        DatosparaentrenamientoLabel     matlab.ui.control.Label
        EliminarKernelButton            matlab.ui.control.Button
        MostrarKernelListaButton        matlab.ui.control.Button
        AgregarKernelButton             matlab.ui.control.Button
        FiltrosaAplicarLabel            matlab.ui.control.Label
        FiltrosdeColorDropDown          matlab.ui.control.DropDown
        FiltrosdeColorDropDownLabel     matlab.ui.control.Label
        Kernel5x5DropDown               matlab.ui.control.DropDown
        Kernel5x5DropDownLabel          matlab.ui.control.Label
        Rutaimagenkernel                matlab.ui.control.EditField
        EntradasEditField_2Label        matlab.ui.control.Label
        Seleccionarimagenkernel         matlab.ui.control.Button
        DeshacerButton                  matlab.ui.control.Button
        Kernel3x3DropDown               matlab.ui.control.DropDown
        Kernel3x3DropDownLabel          matlab.ui.control.Label
        UniversidaddeCundinamarcaLabel_3  matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        EntrenamientoLaboratorio4InteligenciaArtificialLabel_2  matlab.ui.control.Label
        UIImagenTratada                 matlab.ui.control.UIAxes
        UIImagenOriginal                matlab.ui.control.UIAxes
        EntrenamientoTab                matlab.ui.container.Tab
        MaxEpochsEditField              matlab.ui.control.NumericEditField
        pocasMximasEditFieldLabel       matlab.ui.control.Label
        ThetaEditField                  matlab.ui.control.NumericEditField
        AlphaEditField_2Label_2         matlab.ui.control.Label
        ErrorActualEditField            matlab.ui.control.NumericEditField
        ErrorActualEditFieldLabel       matlab.ui.control.Label
        EpocaActualEditField            matlab.ui.control.NumericEditField
        pocaactualEditFieldLabel        matlab.ui.control.Label
        DetenerButton                   matlab.ui.control.Button
        ErrorGeneralEditField           matlab.ui.control.NumericEditField
        PrecisionClaficicacinLabel      matlab.ui.control.Label
        SeleccionarrutaButton           matlab.ui.control.Button
        PrecisinEditField               matlab.ui.control.NumericEditField
        PrecisinEditFieldLabel          matlab.ui.control.Label
        BetaEditFieldLabel              matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.NumericEditField
        AlphaEditField                  matlab.ui.control.NumericEditField
        AlphaEditField_2Label           matlab.ui.control.Label
        SalidasdeseadasEditField        matlab.ui.control.EditField
        SalidasdeseadasEditFieldLabel   matlab.ui.control.Label
        EntradasEditField               matlab.ui.control.EditField
        EntradasEditFieldLabel          matlab.ui.control.Label
        ErrorFinalEditField             matlab.ui.control.NumericEditField
        ErrorFinalEditFieldLabel        matlab.ui.control.Label
        pocasEditField                  matlab.ui.control.NumericEditField
        pocasEditFieldLabel             matlab.ui.control.Label
        MensajesdeestadoLabel           matlab.ui.control.Label
        GuardarPesosenEditField         matlab.ui.control.EditField
        GuardarPesosenEditFieldLabel    matlab.ui.control.Label
        PesosInicialesEditFieldLabel    matlab.ui.control.Label
        PesosInicialesEditField         matlab.ui.control.EditField
        ArquitecturaEditField           matlab.ui.control.EditField
        ArquitecturaEditFieldLabel      matlab.ui.control.Label
        ErrorvspocasLabel               matlab.ui.control.Label
        PesosvspocasLabel               matlab.ui.control.Label
        ArquitecturaLabel               matlab.ui.control.Label
        EntrenarButton                  matlab.ui.control.Button
        SeleccionararchivoButton_3      matlab.ui.control.Button
        SeleccionararchivoButton_2      matlab.ui.control.Button
        SeleccionararchivoButton        matlab.ui.control.Button
        CargadearchivosporfavorseleccioneunarchivoLabel  matlab.ui.control.Label
        DatosdeentrenamientoLabel       matlab.ui.control.Label
        UniversidaddeCundinamarcaLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        EntrenamientoLaboratorio4InteligenciaArtificialLabel  matlab.ui.control.Label
        VerArquitectura                 matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        AplicacinTab                    matlab.ui.container.Tab
        TamaoEditField                  matlab.ui.control.EditField
        TamaoEditFieldLabel             matlab.ui.control.Label
        DimensionesEditField            matlab.ui.control.EditField
        DimensionesEditFieldLabel       matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        BButton                         matlab.ui.control.ToggleButton
        GButton                         matlab.ui.control.ToggleButton
        RButton                         matlab.ui.control.ToggleButton
        RGBButton_2                     matlab.ui.control.ToggleButton
        BrilloButton_2                  matlab.ui.control.ToggleButton
        HistogramaRGBLabel              matlab.ui.control.Label
        TipomariposaEditField           matlab.ui.control.EditField
        TipomariposaEditFieldLabel      matlab.ui.control.Label
        PesosEditField                  matlab.ui.control.EditField
        PesosEditFieldLabel             matlab.ui.control.Label
        EntradaEditField                matlab.ui.control.EditField
        EntradaEditFieldLabel           matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        ImagencargadaLabel              matlab.ui.control.Label
        EjecutarButton                  matlab.ui.control.Button
        SeleccionararchivoButton_5      matlab.ui.control.Button
        SeleccionararchivoButton_4      matlab.ui.control.Button
        UniversidaddeCundinamarcaLabel_2  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        AplicacinLaboratorio4InteligenciaArtificialLabel  matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        ImagenCargada                   matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)

        OriginalImageData % Almacena la imagen cargada en Tratamiento
        AddedKernels = {}; % Cell array de strings o structs {Name, Type} para los filtros/kernels de Tratamiento
        PlottedArchitecture = [];
        InputFileApp
        OriginalImageDataApp = [];

        % Propiedades para el dataset de mariposas y preprocesamiento
        DatasetRootPath = '';
        SpeciesNames = {'Danaus_plexippus', 'Euides_isabella'}; % Nombres de las subcarpetas
        LabelsMap; % Se inicializa en startupFcn: containers.Map(app.SpeciesNames, {0, 1})

        LoadedTreatmentSequence = {}; % Para almacenar una secuencia cargada de un archivo
        TrainImagePaths = {};
        TrainRawLabels = [];       % Etiquetas numéricas (0 o 1) para entrenamiento
        TestImagePaths = {};
        TestRawLabels = [];

        DefaultPreprocessingSequence = {'Escala_de_Grises', 'GaussianBlur_3x3'}; % Nombres que Aplicar_filtros.m entienda

        % Propiedades para las características y la red MLP
        XTrainFeatures = [];       % Características [N_features x N_samples_train]
        YTrainMLPLabels = [];      % Etiquetas [1 x N_samples_train] para MLP
        XTestFeatures = [];
        YTestMLPLabels = [];

        TargetImageSize = [64 64]; % Tamaño al que se redimensionarán las imágenes

        MLPArchitectureDefinition = '35'; % String para capas ocultas del MLP
        DefaultMLPHiddenLayers = 35;
        FullMLPArchitecture = [];  % Completa [entradas, ocultas..., salidas=1]

        TrainedMLPNetwork = [];    % Objeto BP_neuronal_network entrenado
        StopTrainingRequested = false;

        InitialWeightsFileTrainMLP = '';
        OutputWeightsFileTrainMLP = 'pesos_mariposas_mlp.txt';

        TrainingHistory_WeightsNorm = []; % Para gráfica de norma de pesos
        TrainingHistory_EpochsForWeightsNorm = []; % Épocas correspondientes a la norma

        WeightsFileApp = '';
        LoadedAppNetwork = [];
    end

    methods (Access = private)

        function showErrorAlert(app, errorInfo, title)
            if nargin < 3; title = 'Error'; end

            errorMessage = 'Ocurrió un error desconocido.';
            stackTraceAvailable = false;
            stackInfo = struct('name', 'N/A', 'line', 0);

            if isa(errorInfo, 'MException')
                if isprop(errorInfo, 'message') && ~isempty(errorInfo.message) % Verificar propiedad message
                    errorMessage = errorInfo.message;
                else
                    fprintf('WARN: MException no tiene mensaje o está vacío!\n');
                end
                % Verificar stack
                if isprop(errorInfo, 'stack') && ~isempty(errorInfo.stack)
                    stackTraceAvailable = true;
                    stackInfo = errorInfo.stack(1);
                end
            elseif isstruct(errorInfo) && isfield(errorInfo, 'message') % SI ES EL STRUCT MANUAL
                if ~isempty(errorInfo.message)
                    errorMessage = errorInfo.message;
                end
                % No hay stack para structs manuales
            else % Otro tipo, intentar convertir
                try errorMessage = char(errorInfo); catch; end
            end

            fprintf('ERROR DETECTADO (%s): %s\n', title, errorMessage); % Imprimir error real
            if stackTraceAvailable
                try fprintf('  en %s (línea %d)\n', stackInfo.name, stackInfo.line); catch; fprintf('  (Stack info error)\n'); end
            else
                fprintf('  (Sin stack disponible)\n');
            end

            % Actualizar etiquetas de estado
            app.setStatus(app.MensajesdeestadoLabel, ['Error: ' errorMessage]); % Actualiza MensajesdeestadoLabel
            app.setStatus(app.Label_5, ['Error: ' errorMessage]); % Actualiza Label_5 (puede ser redundante, pero seguro)
            try % Mostrar uialert con el mensaje correcto
                if isvalid(app.UIFigure); uialert(app.UIFigure, errorMessage, title, 'Icon','error', 'Modal', true); else; errordlg(errorMessage, title); end
            catch; errordlg(errorMessage, title);
            end

            % Actualizar etiquetas de estado
            try if isprop(app, 'MensajesdeestadoLabel') && isvalid(app.MensajesdeestadoLabel); app.setStatus(app.MensajesdeestadoLabel, ['Error: ' errorMessage]); end; catch; end
            try if isprop(app, 'Label_5') && isvalid(app.Label_5); app.setStatus(app.Label_5, ['Error: ' errorMessage]); end; catch; end

        end

        % --- Helper: Actualizar Status Label --- %%% ASEGÚRATE DE TENER ESTA TAMBIÉN %%%
        function setStatus(~, labelHandle, message)
            % labelHandle aquí DEBE ser el objeto componente real (e.g., app.MensajesdeestadoLabel)
            if isgraphics(labelHandle) && isvalid(labelHandle)
                labelHandle.Text = message;
            else
                disp(['Status (Label no válido): ', message]);
            end
            drawnow('limitrate');
        end

        % --- Helper: Mostrar Alertas de Error ---
        function showError(app, message, title)
            if nargin < 3; title = 'Error'; end
            uialert(app.UIFigure, message, title, 'Icon', 'error');
            app.MensajesdeestadoLabel.Text = ['Error: ' message]; % Mostrar en etiqueta de estado
            drawnow; % Asegurar que se muestre
        end

        % --- Helper: Mostrar Mensajes de Estado ---
        function showStatus(app, message, tab) % tab es 'entrenamiento' o 'aplicacion'
            if strcmpi(tab, 'entrenamiento')
                labelHandle = app.MensajesdeestadoLabel; %%% USA MensajesdeestadoLabel
            elseif strcmpi(tab, 'aplicacion')
                labelHandle = app.Label_5; %%% USA Label_5
            else
                disp(message); return; % Fallback
            end

            if isgraphics(labelHandle) && isvalid(labelHandle)
                labelHandle.Text = message;
            else
                disp(['Status (Label no válido): ', message]);
            end
            drawnow('limitrate');
        end

        function [image_paths_list, numeric_labels] = internal_load_and_label_images(~, dataset_root_path, species_names_list, labels_mapping_map)
            image_paths_list = {}; labels_temp_list = [];
            for i = 1:length(species_names_list)
                s_name = species_names_list{i}; s_folder = fullfile(dataset_root_path, s_name);
                if ~isfolder(s_folder), warning('Carpeta no encontrada: %s', s_folder); continue; end
                img_files = [dir(fullfile(s_folder, '*.jpg')); dir(fullfile(s_folder, '*.jpeg')); dir(fullfile(s_folder, '*.png'))];
                for j = 1:length(img_files)
                    image_paths_list{end+1} = fullfile(s_folder, img_files(j).name); %#ok<AGROW>
                    labels_temp_list(end+1) = labels_mapping_map(s_name); %#ok<AGROW>
                end
            end
            if ~isempty(image_paths_list) % Aleatorizar
                rand_idx = randperm(length(image_paths_list));
                image_paths_list = image_paths_list(rand_idx); numeric_labels = labels_temp_list(rand_idx);
            else, numeric_labels = [];
            end
        end

        function img_out_seq = aplicarSecuenciaTratamiento(~, raw_img_in, sequence_struct_array)
            img_out_seq = raw_img_in; % Inicia con la imagen original
            if isempty(sequence_struct_array), return; end

            for i_filt = 1:length(sequence_struct_array)
                item_s = sequence_struct_array{i_filt};
                filter_name = item_s.Name;
                filter_type = item_s.Type; % 'Kernel', 'Color', o 'Auto' para predefinidos

                kernel_to_apply_str = ''; color_filter_to_apply_str = '';

                if strcmp(filter_type, 'Kernel')
                    kernel_to_apply_str = filter_name;
                elseif strcmp(filter_type, 'Color')
                    color_filter_to_apply_str = filter_name;
                elseif strcmp(filter_type, 'Auto') % Para DefaultPreprocessingSequence si no especificas tipo
                    % Intenta adivinar o usa una lista conocida para los predefinidos
                    if any(strcmpi(filter_name, {'Escala_de_Grises', 'Sepia', 'Invertir'})) % Añade más filtros de color aquí
                        color_filter_to_apply_str = filter_name;
                    else % Asume kernel
                        kernel_to_apply_str = filter_name;
                    end
                end

                if ~isempty(kernel_to_apply_str) || ~isempty(color_filter_to_apply_str)
                    % fprintf('Aplicando (Sec): K="%s", C="%s"\n', kernel_to_apply_str, color_filter_to_apply_str); % Debug
                    img_out_seq = Aplicar_filtros(img_out_seq, kernel_to_apply_str, color_filter_to_apply_str);
                else
                    warning('Filtro/Kernel "%s" (Tipo: %s) de secuencia no reconocido para Aplicar_filtros.', filter_name, filter_type);
                end
            end
        end

        function [features_mat, valid_indices_out] = internal_preprocess_images_for_mlp(app, image_paths_list, filter_sequence_structs, resize_dims_vec)
            num_images_total = length(image_paths_list);
            if num_images_total == 0, features_mat = []; valid_indices_out = []; return; end

            num_flattened_features = -1;
            % Determinar dimensiones con la primera imagen procesable
            for i_dim_check = 1:min(num_images_total, 5) % Probar algunas para encontrar una válida
                try
                    img_sample = imread(image_paths_list{i_dim_check});
                    processed_sample = img_sample;
                    if ~isempty(filter_sequence_structs)
                        processed_sample = app.aplicarSecuenciaTratamiento(processed_sample, filter_sequence_structs);
                    end
                    if size(processed_sample, 3) == 3, processed_sample = rgb2gray(processed_sample); end % MLP espera gris
                    img_resized_sample = imresize(processed_sample, resize_dims_vec);
                    num_flattened_features = numel(img_resized_sample);
                    break;
                catch ME_dim
                    warning('Preproc-DimCheck: Falló %s (%s)',image_paths_list{i_dim_check}, ME_dim.message);
                end
            end
            if num_flattened_features == -1
                app.showErrorAlert(struct('message','No se pudo determinar el tamaño de características del MLP. Revise las imágenes/filtros.'),'Error Preproc');
                features_mat = []; valid_indices_out = []; return;
            end

            features_mat_all = zeros(num_flattened_features, num_images_total);
            is_processed_ok = false(1, num_images_total);

            for i_img = 1:num_images_total
                try
                    current_img = imread(image_paths_list{i_img});
                    processed_img_current = current_img;
                    if ~isempty(filter_sequence_structs)
                        processed_img_current = app.aplicarSecuenciaTratamiento(processed_img_current, filter_sequence_structs);
                    end
                    if size(processed_img_current, 3) == 3, processed_img_current = rgb2gray(processed_img_current); end
                    img_resized_final = imresize(processed_img_current, resize_dims_vec);
                    features_mat_all(:, i_img) = double(img_resized_final(:)) / 255.0; % Normalizar
                    is_processed_ok(i_img) = true;
                catch ME_img_proc
                    warning('Preproc-MLP: Falló %s (%s). Excluida.', image_paths_list{i_img}, ME_img_proc.message);
                end
            end
            valid_indices_out = find(is_processed_ok);
            features_mat = features_mat_all(:, valid_indices_out);
        end

        function plotNetworkArchitecture(~, uiAxesHandle, architecture_vector_plot, num_original_inputs)
            cla(uiAxesHandle);
            if isempty(architecture_vector_plot), title(uiAxesHandle, 'Arquitectura MLP No Definida'); axis(uiAxesHandle, 'off'); return; end

            title_str_arch = sprintf('Arquitectura MLP: [%s]', num2str(architecture_vector_plot));
            title(uiAxesHandle, title_str_arch);

            hold(uiAxesHandle, 'on');
            num_total_layers = length(architecture_vector_plot);
            layer_x_pos = linspace(0.1, 0.9, num_total_layers);
            max_nodes_to_plot_per_layer = 8;

            for i_layer = 1:num_total_layers
                num_nodes_in_layer = architecture_vector_plot(i_layer);
                is_input_layer_very_large = (i_layer == 1 && num_original_inputs > 1000);

                nodes_actually_plotted = min(num_nodes_in_layer, max_nodes_to_plot_per_layer);
                if is_input_layer_very_large
                    nodes_actually_plotted = 1;
                    rectangle(uiAxesHandle, 'Position', [layer_x_pos(i_layer)-0.03, 0.3, 0.06, 0.4], 'FaceColor', [0.7 0.85 1], 'Curvature', 0.2);
                    text(uiAxesHandle, layer_x_pos(i_layer), 0.5, sprintf('%d\nInputs', num_original_inputs), 'HorizontalAlignment', 'center', 'FontSize', 7, 'FontWeight', 'bold');
                else
                    node_y_pos = linspace(0.15, 0.85, nodes_actually_plotted);
                    for j_node = 1:nodes_actually_plotted
                        plot(uiAxesHandle, layer_x_pos(i_layer), node_y_pos(j_node), 'o', 'MarkerFaceColor', 'cyan', 'MarkerEdgeColor', 'blue', 'MarkerSize', 8);
                    end
                    if num_nodes_in_layer > nodes_actually_plotted
                        text(uiAxesHandle, layer_x_pos(i_layer), 0.5, '...', 'HorizontalAlignment', 'center', 'VerticalAlignment','middle', 'FontSize', 10, 'FontWeight','bold');
                    end
                end
                text(uiAxesHandle, layer_x_pos(i_layer), 0.95, sprintf('L%d: %dN', i_layer, num_nodes_in_layer), 'HorizontalAlignment', 'center', 'FontSize', 8);

                % Conexiones (solo ocultas a salida y entre ocultas si input > 1000)
                if i_layer > 1
                    prev_layer_nodes_plotted = min(architecture_vector_plot(i_layer-1), max_nodes_to_plot_per_layer);
                    if i_layer-1 == 1 && architecture_vector_plot(1) > 1000 % Si la anterior era la entrada grande
                        prev_layer_nodes_plotted = 1; % Solo conectar desde el "rectángulo"
                    end
                    prev_node_y_pos = linspace(0.15, 0.85, prev_layer_nodes_plotted);

                    % Condición del prompt original: si entradas > 1000, solo conexiones de ocultas a salida
                    % Si es MLP, esto significa: si la capa de entrada (L0) era >1000, no dibujar conexiones de L0 a L1.
                    % Sí dibujar de L1 a L2, L2 a L3 (salida).
                    should_draw_connections = true;
                    if num_original_inputs > 1000 && i_layer == 2 % Conexiones de entrada (grande) a primera oculta
                        should_draw_connections = false; % NO dibujar estas si la entrada es muy grande
                    end

                    if should_draw_connections
                        current_nodes_y = linspace(0.15, 0.85, min(num_nodes_in_layer,max_nodes_to_plot_per_layer) );
                        if is_input_layer_very_large && i_layer == 2 % Conectando desde el rectangulo de entrada
                            prev_node_y_pos = 0.5; % Centro del rectangulo
                        end

                        for k_curr = 1:length(current_nodes_y)
                            for m_prev = 1:length(prev_node_y_pos)
                                plot(uiAxesHandle, [layer_x_pos(i_layer-1), layer_x_pos(i_layer)], [prev_node_y_pos(m_prev), current_nodes_y(k_curr)], 'Color', [0.7 0.7 0.7]);
                            end
                        end
                    end
                end
            end
            hold(uiAxesHandle, 'off'); axis(uiAxesHandle, [0 1 0 1]); axis(uiAxesHandle, 'off');
        end

        % Helper para startupFcn (ternario)
        function val = ifelse(~, condition, trueVal, falseVal)
            if condition
                val = trueVal;
            else
                val = falseVal;
            end
        end

        % --- Función para Graficar Histograma RGB (Adaptada de histograma.m) ---
        function plotRGBHistogram(~, img_data_input, target_axes)
            cla(target_axes);
            legend(target_axes, 'off'); % Limpiar leyenda
            title(target_axes, 'Histograma RGB');
            xlabel(target_axes, 'Intensidad');
            ylabel(target_axes, 'Num Píxeles');
            grid(target_axes, 'on');

            if isempty(img_data_input)
                title(target_axes, 'Histograma RGB (No hay imagen)');
                return;
            end

            is_rgb = (ndims(img_data_input) == 3 && size(img_data_input, 3) == 3);
            is_uint8_data = isa(img_data_input, 'uint8');

            if is_rgb
                try
                    hold(target_axes, 'on');

                    [countsRojo, binsRojo] = imhist(img_data_input(:,:,1));
                    bar(target_axes, binsRojo, countsRojo, 'FaceColor', 'r', 'EdgeColor', 'none', 'FaceAlpha', 0.6, 'BarWidth', 1);

                    [countsVerde, binsVerde] = imhist(img_data_input(:,:,2));
                    bar(target_axes, binsVerde, countsVerde, 'FaceColor', 'g', 'EdgeColor', 'none', 'FaceAlpha', 0.6, 'BarWidth', 1);

                    [countsAzul, binsAzul] = imhist(img_data_input(:,:,3));
                    bar(target_axes, binsAzul, countsAzul, 'FaceColor', 'b', 'EdgeColor', 'none', 'FaceAlpha', 0.6, 'BarWidth', 1);

                    hold(target_axes, 'off');
                    legend(target_axes, 'Rojo', 'Verde', 'Azul', 'Location', 'northeast', 'FontSize', 8);
                    if is_uint8_data, xlim(target_axes, [-5 260]); else, xlim(target_axes, 'auto'); end
                catch ME_hist
                    cla(target_axes);
                    title(target_axes, 'Error al generar histograma RGB');
                    text(target_axes, 0.1, 0.5, {'Error:', ME_hist.message}, 'Interpreter', 'none');

                    % Forma completa y robusta:
                    warning('App:HistogramRGBError', 'Error generando histograma RGB: %s', ME_hist.message);
                end
            else % Imagen Monocromática
                try
                    [countsGray, binsGray] = imhist(img_data_input);
                    bar(target_axes, binsGray, countsGray, 'FaceColor', [0.3 0.3 0.3], 'EdgeColor', 'k', 'BarWidth', 1);
                    title(target_axes, 'Histograma (Monocromático)');
                    if is_uint8_data, xlim(target_axes, [-5 260]); else, xlim(target_axes, 'auto'); end
                catch ME_hist_gray
                    cla(target_axes); title(target_axes, 'Error al generar hist. (Monocromático)');
                    text(target_axes, 0.1, 0.5, {'Error:', ME_hist_gray.message}, 'Interpreter', 'none');
                    warning('App:MonocromaticError','Error generando hist. monocromático: %s', ME_hist_gray.message);
                end
            end

        end

        function display_text = getDisplayStructText(~, item_struct)
            display_text = '';
            if strcmp(item_struct.Type, 'Kernel')
                display_text = sprintf('Kernel %s: %s', item_struct.SourceDropDown, item_struct.Name);
            elseif strcmp(item_struct.Type, 'Color')
                display_text = sprintf('Filtro Color: %s', item_struct.Name);
            elseif strcmp(item_struct.Type, 'Auto') % Para los predefinidos
                display_text = sprintf('Predf.: %s', item_struct.Name);
            else
                display_text = sprintf('%s (Tipo Desconocido)', item_struct.Name);
            end
        end

        % --- Helper para actualizar el ListBox desde app.AddedKernels ---
        function updateAddedKernelsListBox(app)
            if isempty(app.AddedKernels)
                app.AddedKernelsListBox.Items = {''}; % Placeholder
                app.AddedKernelsListBox.Value = {};
                return;
            end

            new_display_items = cell(1, length(app.AddedKernels));
            for k = 1:length(app.AddedKernels)
                new_display_items{k} = app.getDisplayStructText(app.AddedKernels{k});
            end
            app.AddedKernelsListBox.Items = new_display_items;
        end

    end

    methods (Access = public)

        % Método público para que clases externas (como BP_neuronal_network)
        % puedan consultar si se debe detener el entrenamiento.
        function stopStatus = getStopTrainingStatus(app)
            stopStatus = app.StopTrainingRequested; % Accede a la propiedad privada interna
        end

        % Callback para BP_neuronal_network (actualiza UIAxes3 para norma de pesos)
        function update_live_training_metrics(app, epoch, current_mse_error, current_weights_norm_val)
            fprintf('APP DEBUG: Llamada a update_live_training_metrics - Época: %d, Error: %.7g\n', epoch, current_mse_error);

            if isvalid(app.UIFigure)
                % --- Actualizar campos de texto ---
                app.EpocaActualEditField.Value = epoch;
                app.ErrorActualEditField.Value = current_mse_error;

                % --- Actualizar gráfica de Norma de Pesos (UIAxes3) ---
                if ~isempty(current_weights_norm_val)
                    app.TrainingHistory_EpochsForWeightsNorm(end+1) = epoch;
                    app.TrainingHistory_WeightsNorm(end+1) = current_weights_norm_val;

                    hLineNorma = findobj(app.UIAxes3, 'Type', 'matlab.graphics.chart.primitive.Line', 'Tag', 'NormaPesosLine');
                    if isempty(hLineNorma) && epoch > 0 % Solo crear si no existe y hay datos
                        cla(app.UIAxes3); % Limpiar si se va a crear una nueva línea
                        plot(app.UIAxes3, app.TrainingHistory_EpochsForWeightsNorm, app.TrainingHistory_WeightsNorm, '-r', 'LineWidth', 1.5, 'Tag', 'NormaPesosLine');
                        title(app.UIAxes3, 'Norma Pesos vs. Épocas');
                        xlabel(app.UIAxes3, 'Época');
                        ylabel(app.UIAxes3, 'Norma Pesos');
                        grid(app.UIAxes3, 'on');
                        app.UIAxes3.XAxis.Visible = 'on'; app.UIAxes3.YAxis.Visible = 'on';
                        axis(app.UIAxes3, 'tight');
                    elseif ~isempty(hLineNorma)
                        set(hLineNorma, 'XData', app.TrainingHistory_EpochsForWeightsNorm, 'YData', app.TrainingHistory_WeightsNorm);
                        % Ajustar límites dinámicamente para la línea de norma de pesos
                        if numel(app.TrainingHistory_EpochsForWeightsNorm) > 1
                            axis(app.UIAxes3, 'tight'); % 'tight' suele ser suficiente
                            % Opcional: añadir un pequeño padding a Y si es necesario
                            current_ylim = ylim(app.UIAxes3);
                            padding_y = 0.05 * (current_ylim(2) - current_ylim(1));
                            if padding_y == 0, padding_y = 0.1; end
                            if current_ylim(2) > current_ylim(1) % Evitar error si los límites son iguales
                                ylim(app.UIAxes3, [max(0, current_ylim(1)-padding_y), current_ylim(2)+padding_y] );
                            end
                        end
                    end
                end
                pause(0.001);
                drawnow expose;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cla(app.VerArquitectura); axis(app.VerArquitectura, 'off'); title(app.VerArquitectura, 'Arquitectura MLP');
            cla(app.UIAxes2);title(app.UIAxes2, 'Error MSE/2 vs. Épocas');xlabel(app.UIAxes2, 'Época');ylabel(app.UIAxes2, 'MSE/2');grid(app.UIAxes2, 'on');app.UIAxes2.XAxis.Visible = 'on'; app.UIAxes2.YAxis.Visible = 'on';app.UIAxes2.FontName = 'Arial';
            cla(app.UIAxes3);title(app.UIAxes3, 'Norma Pesos vs. Épocas');xlabel(app.UIAxes3, 'Época');ylabel(app.UIAxes3, 'Norma Pesos');grid(app.UIAxes3, 'on');app.UIAxes3.XAxis.Visible = 'on'; app.UIAxes3.YAxis.Visible = 'on';app.UIAxes3.FontName = 'Arial';
            cla(app.ImagenCargada); axis(app.ImagenCargada, 'off'); title(app.ImagenCargada, 'Imagen Aplicación');
            cla(app.UIImagenOriginal); axis(app.UIImagenOriginal, 'off'); title(app.UIImagenOriginal, 'Imagen Original');
            cla(app.UIImagenTratada); axis(app.UIImagenTratada, 'off'); title(app.UIImagenTratada, 'Imagen Tratada');

            app.LabelsMap = containers.Map(app.SpeciesNames, {0, 1}); % 0 para Danaus, 1 para Euides

            configFilePath = fullfile(fileparts(mfilename('fullpath')), 'config.txt');
            config = BP_neuronal_network.leerConfig(configFilePath);

            app.AlphaEditField.Value = ifelse(app, isfield(config, 'alpha'), config.alpha, 0.01);
            app.BetaEditField.Value = ifelse(app, isfield(config, 'beta'), config.beta, 0.1); % Momentum
            app.PrecisinEditField.Value = ifelse(app, isfield(config, 'precision'), config.precision, 0.005); % MSE deseado
            app.ThetaEditField.Value = ifelse(app, isfield(config, 'theta'), config.theta, 0.5); % No usado directamente en MLP, pero mantenlo. Quizás para umbral de activación en otro contexto.

            % Arquitectura para capas ocultas del MLP

            mlp_hidden_layers_to_set = app.DefaultMLPHiddenLayers; % Empezar con el default numérico

            % Intentar leer desde config.txt
            if isfield(config, 'arquitectura_mlp_ocultas') && isnumeric(config.arquitectura_mlp_ocultas) && ~isempty(config.arquitectura_mlp_ocultas)
                mlp_hidden_layers_to_set = config.arquitectura_mlp_ocultas; % Usar valor numérico de config si es válido
            end

            % Asignar al campo de texto usando num2str (sin corchetes)
            app.ArquitecturaEditField.Value = num2str(mlp_hidden_layers_to_set);

            app.MaxEpochsEditField.Value = ifelse(app, isfield(config,'max_epochs'), config.max_epochs, 2000); % Max épocas

            app.GuardarPesosenEditField.Value = app.OutputWeightsFileTrainMLP;
            app.PesosEditField.Value = app.OutputWeightsFileTrainMLP; % Para pestaña aplicación

            % Cargar imagen por defecto en Tratamiento (tu código existente)
            defaultImagePathTratamiento = 'Isabella_default.jpeg';
            try
                defaultImagePathTratamientoFull = fullfile(fileparts(mfilename('fullpath')), defaultImagePathTratamiento);
                if isfile(defaultImagePathTratamientoFull)
                    imgDataTratamiento = imread(defaultImagePathTratamientoFull);
                    app.OriginalImageData = imgDataTratamiento; % Guardar para Deshacer
                    imshow(imgDataTratamiento, 'Parent', app.UIImagenOriginal); axis(app.UIImagenOriginal, 'image', 'off'); title(app.UIImagenOriginal, 'Original');
                    imshow(imgDataTratamiento, 'Parent', app.UIImagenTratada); axis(app.UIImagenTratada, 'image', 'off'); title(app.UIImagenTratada, 'Tratada (Inicial)');
                else
                    title(app.UIImagenOriginal, 'Img Defecto No Encontrada'); title(app.UIImagenTratada, 'Img Defecto No Encontrada');
                end
            catch ME_tratamiento
                warning('App:DefaultImageError','Error al cargar la imagen por defecto para Tratamiento: %s', ME_tratamiento.message);
            end

            % Cargar imagen por defecto en Aplicación (tu código existente)
            try
                defaultImagePathApp = fullfile(fileparts(mfilename('fullpath')), 'VOCALES_IMAGE.png'); % Cambia si es necesario
                if isfile(defaultImagePathApp)
                    imshow(imread(defaultImagePathApp), 'Parent', app.ImagenCargada); axis(app.ImagenCargada, 'image', 'off'); title(app.ImagenCargada, 'Ref. Aplicación');
                else
                    title(app.ImagenCargada, 'Img Defecto App No Encontrada');
                end
            catch % Ignorar
            end

            app.showStatus('Listo. Pestaña Tratamiento: defina filtros. Pestaña Entrenamiento: cargue dataset.', 'entrenamiento');
            app.showStatus('Listo.', 'aplicacion');
        end

        % Button pushed function: SeleccionararchivoButton
        function SeleccionararchivoButtonPushed(app, event)
            app.showStatus('Seleccionando carpeta raíz del dataset...', 'entrenamiento');
            try
                folderName = uigetdir(app.DatasetRootPath, 'Seleccione la carpeta raíz del dataset (debe contener Danaus_plexippus y Euides_isabella)');
                if isequal(folderName, 0)
                    app.showStatus('Selección de carpeta cancelada.', 'entrenamiento'); return;
                end
                app.DatasetRootPath = folderName;
                app.EntradasEditField.Value = folderName;

                app.showStatus('Cargando y dividiendo datos...', 'entrenamiento'); drawnow;
                [all_paths, all_num_labels] = app.internal_load_and_label_images(app.DatasetRootPath, app.SpeciesNames, app.LabelsMap);

                if isempty(all_paths)
                    app.showErrorAlert(struct('message', 'No se cargaron imágenes. Verifique la ruta y estructura de subcarpetas.'), 'Error Carga Datos');
                    return;
                end

                num_total_images = length(all_paths);
                train_ratio = 0.8;
                cv = cvpartition(num_total_images, 'HoldOut', 1 - train_ratio);

                app.TrainImagePaths = all_paths(training(cv));
                app.TrainRawLabels = all_num_labels(training(cv));
                app.TestImagePaths = all_paths(test(cv));
                app.TestRawLabels = all_num_labels(test(cv));

                status_msg = sprintf('%d imágenes cargadas. Entrenamiento: %d, Prueba: %d.', num_total_images, length(app.TrainImagePaths), length(app.TestImagePaths));
                app.showStatus(status_msg, 'entrenamiento');

                app.XTrainFeatures = []; app.YTrainMLPLabels = []; app.XTestFeatures = []; app.YTestMLPLabels = [];
                cla(app.VerArquitectura); title(app.VerArquitectura, 'Arquitectura MLP (Definir y Entrenar)');

            catch ME_load
                app.showErrorAlert(ME_load, 'Error al cargar datos de mariposas');
            end
        end

        % Button pushed function: SeleccionararchivoButton_2
        function SeleccionararchivoButton_2Pushed(app, event)
            filter = {'*.txt', 'Archivos de Texto (*.txt)'};
            dialogTitle = 'Selecciona el archivo .TXT con las salidas deseadas';
            [fileName, pathName] = uigetfile(filter, dialogTitle);
            if isequal(fileName, 0)
                app.showStatus('Selección cancelada.', 'entrenamiento');
                app.SalidasdeseadasEditField.Value = '';
                app.TargetFileTrain = '';
            else
                fullPath = fullfile(pathName, fileName);
                app.SalidasdeseadasEditField.Value = fullPath;
                app.TargetFileTrain = fullPath;
                app.showStatus(['Archivo de salidas deseadas: ' fullPath], 'entrenamiento');
            end
        end

        % Button pushed function: SeleccionararchivoButton_3
        function SeleccionararchivoButton_3Pushed(app, event)
            filter = {'*.txt', 'Archivos de Texto (*.txt)'};
            dialogTitle = 'Selecciona el archivo .TXT con los pesos iniciales (Opcional)';
            [fileName, pathName] = uigetfile(filter, dialogTitle);
            if isequal(fileName, 0)
                app.showStatus('Selección de pesos iniciales cancelada.', 'entrenamiento');
                app.PesosInicialesEditField.Value = '';
                app.InitialWeightsFileTrainMLP = '';
            else
                fullPath = fullfile(pathName, fileName);
                app.PesosInicialesEditField.Value = fullPath;
                app.InitialWeightsFileTrainMLP = fullPath;
                app.showStatus(['Archivo de pesos iniciales: ' fullPath], 'entrenamiento');
            end
        end

        % Button pushed function: SeleccionararchivoButton_5
        function SeleccionararchivoButton_5Pushed(app, event)
            filter = {'*.png;*.jpg;*.jpeg;*.bmp;*.gif;*.tif', 'Archivos de Imagen (*.png;*.jpg;*.jpeg;*.bmp;*.gif;*.tif)'};
            dialogTitle = 'Selecciona la IMAGEN de entrada';

            persistent lastPath_AppImg;
            if isempty(lastPath_AppImg) || ~isfolder(lastPath_AppImg)
                lastPath_AppImg = pwd;
            end
            [fileName, pathName] = uigetfile(filter, dialogTitle, lastPath_AppImg);

            if isequal(fileName, 0) || isequal(pathName, 0)
                app.showStatus('Selección de entrada cancelada.', 'aplicacion');
                app.EntradaEditField.Value = ''; % Opcional limpiar
                app.OriginalImageDataApp = [];  % Opcional limpiar
                cla(app.ImagenCargada); axis(app.ImagenCargada, 'off'); title(app.ImagenCargada, 'Imagen/Entrada');
                cla(app.UIAxes4); title(app.UIAxes4, 'Histograma RGB');
                app.DimensionesEditField.Value = '';
                app.TamaoEditField.Value = '';
            else
                lastPath_AppImg = pathName;
                fullPath = fullfile(pathName, fileName);
                app.EntradaEditField.Value = fullPath;
                app.showStatus(['Archivo de entrada listo: ' fullPath], 'aplicacion');

                try
                    imgData = imread(fullPath);
                    app.OriginalImageDataApp = imgData;

                    imshow(imgData, 'Parent', app.ImagenCargada);
                    title(app.ImagenCargada, fileName, 'Interpreter', 'none');
                    axis(app.ImagenCargada, 'image', 'off');

                    % --- MOSTRAR DIMENSIONES Y TAMAÑO ---
                    try
                        imgInfo = imfinfo(fullPath);
                        fileSizeKB = imgInfo.FileSize / 1024;
                        if fileSizeKB < 1024
                            app.TamaoEditField.Value = sprintf('%.2f KB', fileSizeKB);
                        else
                            fileSizeMB = fileSizeKB / 1024;
                            app.TamaoEditField.Value = sprintf('%.2f MB', fileSizeMB);
                        end
                    catch ME_info
                        app.TamaoEditField.Value = 'N/A';
                        warning('App:ImginfoError','No se pudo obtener info del archivo para tamaño: %s', ME_info.message);
                    end

                    dims = size(imgData);
                    if length(dims) == 2
                        app.DimensionesEditField.Value = sprintf('%d x %d (Gris)', dims(1), dims(2));
                    elseif length(dims) == 3
                        app.DimensionesEditField.Value = sprintf('%d x %d x %d (Color)', dims(1), dims(2), dims(3));
                    else
                        app.DimensionesEditField.Value = sprintf('Dims: %s', mat2str(dims));
                    end
                    % -----------------------------------

                    % Generar y mostrar el histograma RGB inicial
                    app.plotRGBHistogram(app.OriginalImageDataApp, app.UIAxes4);

                    % Seleccionar 'RGB' o 'Brillo' en el buttongroup por defecto
                    if (ndims(imgData) == 3 && size(imgData,3) == 3)
                        if isprop(app, 'RGBButton_2') && isvalid(app.RGBButton_2) % Chequeo de existencia
                            app.ButtonGroup.SelectedObject = app.RGBButton_2;
                        end
                    else
                        if isprop(app, 'BrilloButton_2') && isvalid(app.BrilloButton_2) % Chequeo de existencia
                            app.ButtonGroup.SelectedObject = app.BrilloButton_2;
                        end
                    end

                catch ME_disp
                    app.showError(['Error cargando o mostrando imagen: ' ME_disp.message], 'aplicacion');
                    app.OriginalImageDataApp = []; % Limpiar
                    cla(app.ImagenCargada); axis(app.ImagenCargada, 'off'); title(app.ImagenCargada, 'Error al Visualizar');
                    cla(app.UIAxes4); title(app.UIAxes4, 'Histograma RGB (Error)');
                    app.DimensionesEditField.Value = 'Error';
                    app.TamaoEditField.Value = 'Error';
                end
            end
        end

        % Button pushed function: SeleccionararchivoButton_4
        function SeleccionararchivoButton_4Pushed(app, event)
            filter = {'*.txt', 'Archivos de Texto (*.txt)'};
            dialogTitle = 'Selecciona el archivo .TXT con los pesos entrenados';
            [fileName, pathName] = uigetfile(filter, dialogTitle);
            if isequal(fileName, 0)
                app.showStatus('Selección de pesos cancelada.', 'aplicacion');
                % No limpiar campo, podría querer usar el default
            else
                fullPath = fullfile(pathName, fileName);
                app.PesosEditField.Value = fullPath; % Actualizar campo UI
                app.WeightsFileApp = fullPath;       % Actualizar propiedad
                app.showStatus(['Archivo de pesos seleccionado: ' fullPath], 'aplicacion');
                app.LoadedAppNetwork = BP_neuronal_network();
            end
        end

        % Button pushed function: SeleccionarrutaButton
        function SeleccionarRutaButtonPushed(app, event)
            defaultName = app.GuardarPesosenEditField.Value;
            if isempty(defaultName); defaultName = 'pesos_entrenados.txt'; end

            filter = {'*.txt', 'Archivos de Texto (*.txt)'};
            dialogTitle = 'Guardar pesos entrenados como...';
            [fileName, pathName] = uiputfile(filter, dialogTitle, defaultName);

            if isequal(fileName, 0)
                app.showStatus('Guardado de pesos cancelado.', 'entrenamiento');
                % No limpiar el campo, mantener el valor anterior o por defecto
            else
                fullPath = fullfile(pathName, fileName);
                app.GuardarPesosenEditField.Value = fullPath;
                app.WeightsOutputFileTrain = fullPath;
                app.showStatus(['Archivo para guardar pesos: ' fullPath], 'entrenamiento');
            end
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            app.showStatus('Solicitud de detención recibida...', 'entrenamiento');
            app.StopTrainingRequested = true;
            % Opcional: Deshabilitar el botón detener para evitar clics múltiples
            app.DetenerButton.Enable = 'off';
            drawnow; % Asegurar que el mensaje se muestre
        end

        % Button pushed function: Seleccionarimagenkernel
        function SeleccionarimagenkernelButtonPushed(app, event)
            % filter para seleccionar archivos de imagen
            filter = {'*.png;*.jpg;*.jpeg;*.bmp;*.gif', 'Archivos de Imagen (*.png, *.jpg, *.jpeg, *.bmp, *.gif)'};
            dialogTitle = 'Seleccionar un archivo de imagen';
            [fileName, pathName] = uigetfile(filter, dialogTitle);
            axis(app.UIImagenOriginal, 'off'); % Desactivar los ejes

            if isequal(fileName, 0)
                % El usuario canceló la selección
                app.showStatus('Selección de archivo cancelada.', 'tratamiento'); % Asumiendo que tienes una función showStatus
                app.Rutaimagenkernel.Value = ''; % Limpiar el EditField de la ruta (si lo tienes)
            else
                % Se seleccionó un archivo
                fullPath = fullfile(pathName, fileName);
                app.Rutaimagenkernel.Value = fullPath; %


                % --- Leer y mostrar la imagen en el UIAxes ---
                try
                    imagenOriginal = imread(fullPath);
                    app.OriginalImageData = imagenOriginal; % Guardar la imagen original (importante para el botón Deshacer)
                    imshow(imagenOriginal, 'Parent', app.UIImagenTratada); % Mostrar la imagen original en UIImagenTratada

                    imshow(imagenOriginal, 'Parent', app.UIImagenOriginal);
                catch ME
                    app.showErrorAlert( ME, 'Error de carga');
                    cla(app.UIImagenTratada); % Limpiar el axes en caso de error
                    title(app.UIImagenTratada, 'Error al cargar');

                    cla(app.UIImagenOriginal);
                    title(app.UIImagenOriginal, 'Error al cargar');
                end
            end
        end

        % Button pushed function: DeshacerButton
        function DeshacerButtonPushed(app, event)
            % Verificar si hay una imagen original guardada
            if isprop(app, 'OriginalImageData') && ~isempty(app.OriginalImageData)
                % Restaurar la imagen original en UIImagenOriginal
                imshow(app.OriginalImageData, 'Parent', app.UIImagenOriginal);
                axis(app.UIImagenOriginal, 'image');
                axis(app.UIImagenOriginal, 'off');
                title(app.UIImagenOriginal, 'Imagen Original');

                % Limpiar UIImagenTratada y su título
                cla(app.UIImagenTratada);
                axis(app.UIImagenTratada, 'off');
                title(app.UIImagenTratada, 'Imagen Tratada');

                % Resetear las selecciones de los desplegables
                if isprop(app, 'Kernel3x3DropDown')
                    app.Kernel3x3DropDown.Value = 'Ninguno';
                end
                if isprop(app, 'Kernel5x5DropDown')
                    app.Kernel5x5DropDown.Value = 'Ninguno';
                end
                if isprop(app, 'FiltrosdeColorDropDown')
                    app.FiltrosdeColorDropDown.Value = 'Ninguno';
                end
            else
                uialert(app.UIFigure, 'No hay una imagen original para deshacer.', 'Advertencia');
            end
        end

        % Value changed function: Kernel3x3DropDown
        function Kernel3x3DropDownValueChanged(app, event)
            % Función de callback para los desplegables de kernel y filtro de color
            hImageOriginal = findobj(app.UIImagenOriginal, 'Type', 'image');
            if ~isempty(hImageOriginal)
                imagen_original = hImageOriginal.CData;
            else
                uialert(app.UIFigure, 'No se encontró ninguna imagen cargada.', 'Advertencia');
                return; % Salir de la función si no hay imagen
            end

            kernel_seleccionado = '';
            filtro_color_seleccionado = '';

            % Verificar si se seleccionó "Ninguno" en algún desplegable
            if strcmp(app.Kernel3x3DropDown.Value, 'Ninguno') && strcmp(app.Kernel5x5DropDown.Value, 'Ninguno') && strcmp(app.FiltrosdeColorDropDown.Value, 'Ninguno')
                % Mostrar la imagen original en UIImagenTratada
                if isprop(app, 'OriginalImageData') && ~isempty(app.OriginalImageData)
                    imshow(app.OriginalImageData, 'Parent', app.UIImagenTratada);
                    axis(app.UIImagenTratada, 'image');
                    axis(app.UIImagenTratada, 'off');
                    title(app.UIImagenTratada, 'Imagen Tratada');
                else
                    cla(app.UIImagenTratada);
                    axis(app.UIImagenTratada, 'off');
                    title(app.UIImagenTratada, 'Imagen Tratada (Sin filtro)');
                end
                return; % Salir de la función si no se selecciona ningún filtro
            end

            % Determinar qué kernel está seleccionado (prioridad a 3x3, luego 5x5)
            if isprop(app, 'Kernel3x3DropDown') && ~strcmp(app.Kernel3x3DropDown.Value, 'Ninguno')
                kernel_seleccionado = app.Kernel3x3DropDown.Value;
                kernel_seleccionado = strrep(kernel_seleccionado, ' ', '_');
            elseif isprop(app, 'Kernel5x5DropDown') && ~strcmp(app.Kernel5x5DropDown.Value, 'Ninguno')
                kernel_seleccionado = app.Kernel5x5DropDown.Value;
                kernel_seleccionado = strrep(kernel_seleccionado, ' ', '_');
            end

            if isprop(app, 'FiltrosdeColorDropDown') && ~strcmp(app.FiltrosdeColorDropDown.Value, 'Ninguno')
                filtro_color_seleccionado = app.FiltrosdeColorDropDown.Value;
            end

            if ~isempty(imagen_original) && (~isempty(kernel_seleccionado) || ~isempty(filtro_color_seleccionado))
                imagen_tratada = Aplicar_filtros(imagen_original, kernel_seleccionado, filtro_color_seleccionado);
                imshow(imagen_tratada, 'Parent', app.UIImagenTratada);
                axis(app.UIImagenTratada, 'image');
                axis(app.UIImagenTratada, 'off');
                title(app.UIImagenTratada, 'Imagen Tratada');
            elseif isempty(imagen_original)
                uialert(app.UIFigure, 'Por favor, cargue una imagen primero.', 'Advertencia');
            end
        end

        % Value changed function: Kernel5x5DropDown
        function Kernel5x5DropDownValueChanged(app, event)
            Kernel3x3DropDownValueChanged(app, event);
        end

        % Value changed function: FiltrosdeColorDropDown
        function FiltrosdeColorDropDownValueChanged(app, event)
            Kernel3x3DropDownValueChanged(app, event);
        end

        % Callback function
        function AadirKernelButtonPushed(app, event)

        end

        % Button pushed function: AgregarKernelButton
        function AgregarKernelButtonPushed(app, event)
            items_added_count = 0; % Contador para los ítems añadidos en este clic

            selKernel3x3 = app.Kernel3x3DropDown.Value;
            selKernel5x5 = app.Kernel5x5DropDown.Value;
            selFiltroColor = app.FiltrosdeColorDropDown.Value;

            % Revisar Kernel 3x3
            if ~strcmp(selKernel3x3, 'Ninguno')
                newItem = struct('Name', selKernel3x3, 'Type', 'Kernel', 'SourceDropDown', '3x3');
                app.AddedKernels{end+1} = newItem;
                app.showStatus(sprintf('Kernel 3x3: "%s" añadido a la secuencia.', selKernel3x3), 'tratamiento');
                items_added_count = items_added_count + 1;
            end

            % Revisar Kernel 5x5
            if ~strcmp(selKernel5x5, 'Ninguno')
                newItem = struct('Name', selKernel5x5, 'Type', 'Kernel', 'SourceDropDown', '5x5');
                app.AddedKernels{end+1} = newItem;
                app.showStatus(sprintf('Kernel 5x5: "%s" añadido a la secuencia.', selKernel5x5), 'tratamiento');
                items_added_count = items_added_count + 1;
            end

            % Revisar Filtro de Color
            if ~strcmp(selFiltroColor, 'Ninguno')
                newItem = struct('Name', selFiltroColor, 'Type', 'Color', 'SourceDropDown', 'Color');
                app.AddedKernels{end+1} = newItem;
                app.showStatus(sprintf('Filtro Color: "%s" añadido a la secuencia.', selFiltroColor), 'tratamiento');
                items_added_count = items_added_count + 1;
            end

            if items_added_count > 0
                app.updateAddedKernelsListBox(); % Actualizar el ListBox una vez
                % Resetear todos los DropDowns a "Ninguno"
                app.Kernel3x3DropDown.Value = 'Ninguno';
                app.Kernel5x5DropDown.Value = 'Ninguno';
                app.FiltrosdeColorDropDown.Value = 'Ninguno';
            else
                uialert(app.UIFigure, 'No se seleccionó ningún kernel o filtro nuevo para añadir (deben ser diferentes de "Ninguno").', 'Advertencia');
            end
        end

        % Button pushed function: MostrarKernelListaButton
        function MostrarKernelListaButtonPushed(app, event)
            if isempty(app.AddedKernels)
                uialert(app.UIFigure, 'No se han añadido filtros/kernels a la secuencia.', 'Información');
            else
                lista_formateada = cell(1, length(app.AddedKernels));
                for k=1:length(app.AddedKernels)
                    item_struct = app.AddedKernels{k};
                    if strcmp(item_struct.Type, 'Kernel')
                        lista_formateada{k} = sprintf('Paso %d: Kernel (%s) - %s', k, item_struct.SourceDropDown, item_struct.Name);
                    elseif strcmp(item_struct.Type, 'Color')
                        lista_formateada{k} = sprintf('Paso %d: Filtro Color - %s', k, item_struct.Name);
                    else
                        lista_formateada{k} = sprintf('Paso %d: %s (Tipo desconocido)', k, item_struct.Name);
                    end
                end
                uialert(app.UIFigure, strjoin(lista_formateada, '\n'), 'Secuencia de Tratamiento Definida');
            end
        end

        % Button pushed function: EliminarKernelButton
        function EliminarKernelButtonPushed(app, event)
            selected_display_texts = app.AddedKernelsListBox.Value; % Puede ser múltiple si ListBox lo permite, o uno solo
            if isempty(selected_display_texts)
                uialert(app.UIFigure, 'Seleccione un ítem de la lista para borrar.', 'Advertencia');
                return;
            end
            if ~iscell(selected_display_texts), selected_display_texts = {selected_display_texts}; end

            items_to_remove_indices = [];
            % Buscar los structs correspondientes en app.AddedKernels
            for i_sel = 1:length(selected_display_texts)
                display_text_to_find = selected_display_texts{i_sel};
                for k = 1:length(app.AddedKernels)
                    item_struct = app.AddedKernels{k};
                    % Reconstruir el texto de display que se usó para el ListBox
                    current_item_display_text = '';
                    if strcmp(item_struct.Type, 'Kernel')
                        current_item_display_text = sprintf('Kernel %s: %s', item_struct.SourceDropDown, item_struct.Name);
                    elseif strcmp(item_struct.Type, 'Color')
                        current_item_display_text = sprintf('Filtro Color: %s', item_struct.Name);
                    end
                    if strcmp(display_text_to_find, current_item_display_text)
                        items_to_remove_indices(end+1) = k; %#ok<AGROW>
                        break; % Encontrado, ir al siguiente texto seleccionado
                    end
                end
            end

            if ~isempty(items_to_remove_indices)
                app.AddedKernels(unique(items_to_remove_indices)) = []; % Eliminar del cell array de structs
                % Reconstruir los items del ListBox
                new_display_items = {};
                for k=1:length(app.AddedKernels)
                    item_struct = app.AddedKernels{k};
                    display_text = '';
                    if strcmp(item_struct.Type, 'Kernel')
                        display_text = sprintf('Kernel %s: %s', item_struct.SourceDropDown, item_struct.Name);
                    elseif strcmp(item_struct.Type, 'Color')
                        display_text = sprintf('Filtro Color: %s', item_struct.Name);
                    end
                    new_display_items{end+1} = display_text; %#ok<AGROW>
                end
                if isempty(new_display_items), new_display_items = {''}; end % Placeholder si está vacío
                app.AddedKernelsListBox.Items = new_display_items;
                app.AddedKernelsListBox.Value = {}; % Limpiar selección
                app.showStatus('Ítem(s) borrado(s) de la secuencia.', 'tratamiento');
            end
        end

        % Button pushed function: EntrenarButton
        function EntrenarButtonPushed(app, event)
            app.showStatus('Iniciando proceso de entrenamiento...', 'entrenamiento'); drawnow;
            % Deshabilitar botones durante el entrenamiento
            app.EntrenarButton.Enable = 'off';
            app.DetenerButton.Enable = 'on';
            app.StopTrainingRequested = false; % Resetear flag de parada

            % --- Validaciones Previas ---
            if isempty(app.TrainImagePaths)
                app.showErrorAlert(struct('message', 'No hay datos de entrenamiento cargados. Por favor, cargue un dataset primero.'), 'Error Entrenamiento');
                app.EntrenarButton.Enable = 'on'; app.DetenerButton.Enable = 'off'; % Reactivar/Desactivar
                return;
            end

            try
                % --- 1. Decidir Secuencia de Preprocesamiento ---
                active_preprocessing_sequence_structs = {};
                sequence_source_message = '';

                opciones_preproc = {'Usar secuencia actual de "Tratamiento"', ...
                    'Cargar secuencia desde archivo .mat', ...
                    'Usar secuencia predefinida por la app', ...
                    'No usar filtros (solo preproc. básico)'};

                % Deshabilitar opciones si no aplican
                if isempty(app.AddedKernels)
                    opciones_preproc{1} = sprintf('%s (Vacía)', opciones_preproc{1}); % Marcar como vacía
                end
                prompt_string = {'Seleccione la secuencia de filtros/kernels a aplicar:', ''};
                [choice_idx, tf] = listdlg('ListString', opciones_preproc, ...
                    'SelectionMode', 'single', ...
                    'Name', 'Seleccionar Preprocesamiento', ...
                    'PromptString', prompt_string, ...
                    'ListSize', [350, 100], ...
                    'OKString', 'Seleccionar', ...
                    'CancelString', 'Cancelar');
                if ~tf % Usuario canceló
                    app.showStatus('Selección de preprocesamiento cancelada. Entrenamiento detenido.', 'entrenamiento');
                    app.EntrenarButton.Enable = 'on'; app.DetenerButton.Enable = 'off'; return;
                end

                user_choice = opciones_preproc{choice_idx};

                if startsWith(user_choice, 'Usar secuencia actual de "Tratamiento"')
                    if isempty(app.AddedKernels)
                        uialert(app.UIFigure, 'La secuencia de "Tratamiento" está vacía. Se usará preprocesamiento básico.', 'Advertencia');
                        active_preprocessing_sequence_structs = {}; % Vacío
                        sequence_source_message = 'Preprocesamiento básico (secuencia de Tratamiento vacía).';
                    else
                        active_preprocessing_sequence_structs = app.AddedKernels;
                        sequence_source_message = 'Usando secuencia actual de "Tratamiento".';
                    end
                elseif startsWith(user_choice, 'Cargar secuencia desde archivo .mat')
                    filter = {'*.mat', 'Archivo de Secuencia (*.mat)'};
                    [fileName, pathName] = uigetfile(filter, 'Cargar Secuencia de Tratamiento');
                    if isequal(fileName, 0) || isequal(pathName, 0)
                        app.showStatus('Carga de secuencia cancelada. Entrenamiento detenido.', 'entrenamiento');
                        app.EntrenarButton.Enable = 'on'; app.DetenerButton.Enable = 'off'; return;
                    else
                        fullPath = fullfile(pathName, fileName);
                        try
                            loaded_data = load(fullPath);
                            if isfield(loaded_data, 'secuenciaParaGuardar') && iscell(loaded_data.secuenciaParaGuardar)
                                app.LoadedTreatmentSequence = loaded_data.secuenciaParaGuardar;
                                active_preprocessing_sequence_structs = app.LoadedTreatmentSequence;
                                sequence_source_message = sprintf('Usando secuencia cargada de: %s', fileName);
                                app.AddedKernels = app.LoadedTreatmentSequence;
                                app.updateAddedKernelsListBox();
                                uialert(app.UIFigure, 'La secuencia cargada ahora es la activa en la pestaña "Tratamiento".', 'Info');
                            else
                                app.showErrorAlert(struct('message', 'El archivo .mat no contiene la variable "secuenciaParaGuardar" o no es válida.'), 'Error Carga Secuencia');
                                app.EntrenarButton.Enable = 'on'; app.DetenerButton.Enable = 'off'; return;
                            end
                        catch ME_load_seq
                            app.showErrorAlert(ME_load_seq, 'Error al cargar archivo de secuencia.');
                            app.EntrenarButton.Enable = 'on'; app.DetenerButton.Enable = 'off'; return;
                        end
                    end
                elseif startsWith(user_choice, 'Usar secuencia predefinida por la app')
                    num_default_items = length(app.DefaultPreprocessingSequence);
                    active_preprocessing_sequence_structs = cell(1, num_default_items);
                    for i_def = 1:num_default_items % Usar el tamaño ya calculado
                        def_name = app.DefaultPreprocessingSequence{i_def};
                        active_preprocessing_sequence_structs{i_def} = struct('Name', def_name, 'Type', 'Auto', 'SourceDropDown', 'Default');
                    end

                    sequence_source_message = 'Usando secuencia de preprocesamiento predefinida por la app.';
                elseif startsWith(user_choice, 'No usar filtros')
                    active_preprocessing_sequence_structs = {}; % Vacío
                    sequence_source_message = 'Solo preprocesamiento básico (sin filtros/kernels).';
                end
                app.showStatus(sequence_source_message, 'entrenamiento'); drawnow;

                % --- 2. Preprocesamiento y Extracción de Características ---
                app.showStatus('Preprocesando datos de entrenamiento...', 'entrenamiento'); drawnow;
                % >> Capturar los índices válidos devueltos <<
                [app.XTrainFeatures, valid_train_idx] = app.internal_preprocess_images_for_mlp( ...
                    app.TrainImagePaths, ...
                    active_preprocessing_sequence_structs, ... % Pasar la secuencia decidida
                    app.TargetImageSize);

                app.showStatus('Preprocesando datos de prueba...', 'entrenamiento'); drawnow;
                % >> Capturar los índices válidos devueltos <<
                [app.XTestFeatures, valid_test_idx] = app.internal_preprocess_images_for_mlp( ...
                    app.TestImagePaths, ...
                    active_preprocessing_sequence_structs, ... % Pasar la misma secuencia
                    app.TargetImageSize);

                % --- Filtrar Etiquetas y Asegurar Dimensiones CORRECTAS [1, NumMuestras] ---
                if isempty(valid_train_idx) || isempty(valid_test_idx)
                    app.showErrorAlert(struct('message', 'No se pudieron procesar imágenes válidas para entrenamiento o prueba.'), 'Error Preprocesamiento');
                    app.EntrenarButton.Enable = 'on'; app.DetenerButton.Enable = 'off'; return;
                end

                temp_labels_train = app.TrainRawLabels(valid_train_idx); % Filtrar usando los índices OK
                app.YTrainMLPLabels = reshape(temp_labels_train, 1, []); % Forzar a [1 x N_train_valid]

                temp_labels_test = app.TestRawLabels(valid_test_idx); % Filtrar usando los índices OK
                app.YTestMLPLabels = reshape(temp_labels_test, 1, []); % Forzar a [1 x N_test_valid]

                % --- Verificación de Consistencia (Post-Filtrado) ---
                if size(app.XTrainFeatures, 2) ~= size(app.YTrainMLPLabels, 2)
                    app.showErrorAlert(struct('message', sprintf('Inconsistencia post-filtrado Ent.: Features %d Muestras vs Labels %d Muestras', size(app.XTrainFeatures, 2), size(app.YTrainMLPLabels, 2))), 'Error Interno Datos');
                    app.EntrenarButton.Enable = 'on'; app.DetenerButton.Enable = 'off'; return;
                end
                if size(app.XTestFeatures, 2) ~= size(app.YTestMLPLabels, 2)
                    app.showErrorAlert(struct('message', sprintf('Inconsistencia post-filtrado Pru.: Features %d Muestras vs Labels %d Muestras', size(app.XTestFeatures, 2), size(app.YTestMLPLabels, 2))), 'Error Interno Datos');
                    app.EntrenarButton.Enable = 'on'; app.DetenerButton.Enable = 'off'; return;
                end

                app.showStatus(sprintf('Preproc. completo. Ent: %d feat x %d muest. Pru: %d feat x %d muest.', ...
                    size(app.XTrainFeatures,1), size(app.XTrainFeatures,2), size(app.XTestFeatures,1), size(app.XTestFeatures,2) ), 'entrenamiento'); drawnow;

                % --- 3. Configurar Arquitectura MLP y Funciones de Activación ---
                num_input_features = size(app.XTrainFeatures, 1);
                hidden_fc_neurons_str = app.ArquitecturaEditField.Value;
                hidden_fc_neurons = str2num(hidden_fc_neurons_str); %#ok<ST2NM>

                % Validar y usar default si es necesario
                if isempty(hidden_fc_neurons) && ~isempty(hidden_fc_neurons_str)
                    app.showErrorAlert(struct('message', 'Formato de Arquitectura MLP inválido. Use números separados por espacios (ej: 64 32).'), 'Error Configuración');
                    app.EntrenarButton.Enable = 'on'; app.DetenerButton.Enable = 'off'; return;
                elseif isempty(hidden_fc_neurons_str) || isempty(hidden_fc_neurons) % Si campo vacío o str2num falló
                    hidden_fc_neurons = app.DefaultMLPHiddenLayers; % Usar default de propiedades
                    app.ArquitecturaEditField.Value = num2str(hidden_fc_neurons); % Actualizar campo
                    app.showStatus('Advertencia: Arquitectura MLP inválida o vacía, usando valor por defecto.', 'entrenamiento');
                end

                num_output_neurons = 1; % Salida binaria con sigmoide
                app.FullMLPArchitecture = [num_input_features, hidden_fc_neurons, num_output_neurons];
                app.plotNetworkArchitecture(app.VerArquitectura, app.FullMLPArchitecture, num_input_features); % Dibujar arquitectura

                % Definir funciones de activación como strings para BP_neuronal_network
                num_mlp_layers_act = length(hidden_fc_neurons) + 1; % Capas ocultas + capa de salida
                mlp_act_fn_str = cell(1, num_mlp_layers_act);
                mlp_deriv_fn_str = cell(1, num_mlp_layers_act);
                relu_act = 'max(0, net)'; relu_deriv = 'double(act > 0)';
                sigmoid_act = '1./(1+exp(-net))'; sigmoid_deriv = 'act.*(1-act)';
                for l_idx = 1:length(hidden_fc_neurons) % ReLU para capas ocultas
                    mlp_act_fn_str{l_idx} = relu_act;
                    mlp_deriv_fn_str{l_idx} = relu_deriv;
                end
                mlp_act_fn_str{end} = sigmoid_act; % Sigmoide para capa de salida
                mlp_deriv_fn_str{end} = sigmoid_deriv;

                % --- 4. Inicializar o Cargar Red MLP ---
                mlp_net = BP_neuronal_network(app.FullMLPArchitecture, mlp_act_fn_str, mlp_deriv_fn_str);
                % --- Línea para HABILITAR USO DE GPU (Añadir/Modificar) ---
                mlp_net.UseGPU = true;
                app.InitialWeightsFileTrainMLP = app.PesosInicialesEditField.Value; % Obtener path del campo UI
                if ~isempty(app.InitialWeightsFileTrainMLP) && isfile(app.InitialWeightsFileTrainMLP)
                    try
                        app.showStatus('Cargando pesos iniciales MLP...', 'entrenamiento');
                        [mlp_net, loaded_arch] = mlp_net.cargarPesos(app.InitialWeightsFileTrainMLP); % Cargar pesos
                        % Verificar si la arquitectura cargada coincide con la esperada
                        if ~isequal(loaded_arch, app.FullMLPArchitecture)
                            warning('La arquitectura cargada [%s] no coincide con la definida [%s]. Se usarán los pesos cargados pero podría haber errores.', num2str(loaded_arch), num2str(app.FullMLPArchitecture));
                            app.showStatus(sprintf('Advertencia: Arq. cargada [%s] != Arq. definida [%s].', num2str(loaded_arch), num2str(app.FullMLPArchitecture)), 'entrenamiento');
                            % Actualizar la arquitectura de la red con la cargada por si acaso
                            app.FullMLPArchitecture = loaded_arch;
                            % Actualizar UI (opcional, puede confundir al usuario)
                            % app.ArquitecturaEditField.Value = num2str(loaded_arch(2:end-1));
                            app.plotNetworkArchitecture(app.VerArquitectura, app.FullMLPArchitecture, loaded_arch(1));
                        else
                            app.showStatus('Pesos iniciales MLP cargados correctamente.', 'entrenamiento');
                        end
                    catch ME_load_w
                        app.showErrorAlert(ME_load_w, 'Error al cargar pesos MLP. Se usarán pesos aleatorios.');
                        % Si falla la carga, mlp_net ya está inicializado con pesos aleatorios
                    end
                else
                    app.showStatus('Iniciando con pesos MLP aleatorios.', 'entrenamiento');
                end

                % --- 5. Configurar Parámetros de Entrenamiento y Entrenar ---
                alpha = app.AlphaEditField.Value;
                beta_mom = app.BetaEditField.Value;
                target_mse = app.PrecisinEditField.Value;
                max_epochs = app.MaxEpochsEditField.Value;

                app.showStatus('Iniciando entrenamiento MLP...', 'entrenamiento'); drawnow;
                cla(app.UIAxes2); cla(app.UIAxes3); % Limpiar gráficas
                app.TrainingHistory_WeightsNorm = []; app.TrainingHistory_EpochsForWeightsNorm = []; % Resetear historial

                % Llamar a la función de entrenamiento (asegúrate que BP_neuronal_network.m esté actualizada)
                [app.TrainedMLPNetwork, epochs_final, mse_final, mse_hist, norm_pesos_hist, epocas_norm_hist, ~] = ...
                    mlp_net.entrenar_con_gui_update(app.XTrainFeatures, app.YTrainMLPLabels, alpha, target_mse, beta_mom, app.UIAxes2, max_epochs, app);

                app.TrainingHistory_WeightsNorm = norm_pesos_hist; % Guardar historial para graficar
                app.TrainingHistory_EpochsForWeightsNorm = epocas_norm_hist;

                % --- 6. Resultados Post-Entrenamiento ---
                app.showStatus('Entrenamiento finalizado. Evaluando...', 'entrenamiento'); drawnow;
                app.ErrorFinalEditField.Value = mse_final;
                app.pocasEditField.Value = epochs_final;
                app.EpocaActualEditField.Value = epochs_final; % Mostrar valores finales
                app.ErrorActualEditField.Value = mse_final;

                if ~isempty(mse_hist) && epochs_final > 0
                    cla(app.UIAxes2); % Limpiar
                    plot(app.UIAxes2, 1:epochs_final, mse_hist(1:epochs_final), '-b.', 'LineWidth', 1); % Usar plot normal
                    title(app.UIAxes2, 'Error MSE/2 vs. Épocas (Final)');
                    xlabel(app.UIAxes2, 'Época'); 
                    ylabel(app.UIAxes2, 'MSE/2');
                    grid(app.UIAxes2, 'on');
                    axis(app.UIAxes2, 'tight'); % Ajustar ejes
                    app.UIAxes2.XAxis.Visible = 'on'; app.UIAxes2.YAxis.Visible = 'on';
                    % Ajustar YLim para mejor visualización si hay mucha variación
                    if numel(mse_hist(1:epochs_final)) > 1
                        min_e = min(mse_hist(1:epochs_final)); max_e = max(mse_hist(1:epochs_final));
                        padding_e = 0.05 * (max_e-min_e); if padding_e == 0, padding_e = 0.1*max(abs(min_e),0.001); end
                        ylim(app.UIAxes2, [min_e-padding_e, max_e+padding_e]);
                    end
                else
                    cla(app.UIAxes2); title(app.UIAxes2, 'Error MSE/2 vs. Épocas (Sin datos)');
                    grid(app.UIAxes2, 'on'); app.UIAxes2.XAxis.Visible = 'on'; app.UIAxes2.YAxis.Visible = 'on';
                end

                % Graficar Norma de Pesos vs Épocas en UIAxes3 (si hay datos)
                if ~isempty(app.TrainingHistory_WeightsNorm) && ~isempty(app.TrainingHistory_EpochsForWeightsNorm) && numel(app.TrainingHistory_WeightsNorm) == numel(app.TrainingHistory_EpochsForWeightsNorm)
                    cla(app.UIAxes3); % Limpiar
                    plot(app.UIAxes3, app.TrainingHistory_EpochsForWeightsNorm, app.TrainingHistory_WeightsNorm, '-r', 'LineWidth', 1.5);
                    xlabel(app.UIAxes3, 'Época');
                    ylabel(app.UIAxes3, 'Norma Total Pesos');
                    title(app.UIAxes3, 'Norma de Pesos vs Épocas');
                    grid(app.UIAxes3, 'on');
                    axis(app.UIAxes3, 'tight');
                    app.UIAxes3.XAxis.Visible = 'on'; app.UIAxes3.YAxis.Visible = 'on';
                    if numel(app.TrainingHistory_WeightsNorm) > 1
                        min_n = min(app.TrainingHistory_WeightsNorm); max_n = max(app.TrainingHistory_WeightsNorm);
                        padding_n = 0.05 * (max_n-min_n); if padding_n == 0, padding_n = 0.1*max(abs(min_n),0.1); end
                        ylim(app.UIAxes3, [min_n-padding_n, max_n+padding_n]);
                    end
                else
                    cla(app.UIAxes3);
                    title(app.UIAxes3, 'Norma Pesos vs Épocas (No hay datos)');
                    grid(app.UIAxes3, 'on');
                end
                drawnow;
                % Calcular Precisión (Accuracy) en el conjunto de prueba
                accuracy = 0; % Valor por defecto si algo falla
                if ~isempty(app.XTestFeatures) && ~isempty(app.YTestMLPLabels)
                    Y_pred_raw = app.TrainedMLPNetwork.feedforward(app.XTestFeatures);
                    Y_pred_binary = round(Y_pred_raw); % Umbral 0.5 para salida sigmoide

                    % Asegurarse de que YTestMLPLabels sea [1 x N] para comparación
                    if size(app.YTestMLPLabels,1) > 1, app.YTestMLPLabels = app.YTestMLPLabels'; end

                    accuracy = sum(Y_pred_binary == app.YTestMLPLabels) / length(app.YTestMLPLabels);
                    if isnan(accuracy), accuracy = 0; end
                end
                app.ErrorGeneralEditField.Value = accuracy * 100; % Campo para Precisión %

                % Guardar pesos entrenados
                app.OutputWeightsFileTrainMLP = app.GuardarPesosenEditField.Value;
                weights_saved_msg = 'No se especificó archivo para guardar pesos.';
                if ~isempty(app.OutputWeightsFileTrainMLP) && ~isempty(app.TrainedMLPNetwork)
                    try
                        app.TrainedMLPNetwork.guardarPesos(app.OutputWeightsFileTrainMLP);
                        weights_saved_msg = sprintf('Pesos guardados en %s', app.OutputWeightsFileTrainMLP);
                    catch ME_save
                        weights_saved_msg = 'Error al guardar pesos.';
                        warning('BP_NN:SaveError', 'Error guardando pesos: %s', ME_save.message);
                    end
                end
                app.showStatus(sprintf('Entrenamiento finalizado. Precisión Prueba: %.2f%%. %s', accuracy * 100, weights_saved_msg), 'entrenamiento');

                % --- Matriz de Confusión y Resumen en Nueva VENTANA DE UI (CORREGIDO) ---
                summary_text_lines = {}; % Predefinir por si falla la figura
                try
                    % >> USAR uifigure <<
                    summary_ui_fig = uifigure('Name', 'Resultados del Entrenamiento de Mariposas', 'Scrollable','on', 'Position', [250 200 850 450]);

                    gl = uigridlayout(summary_ui_fig, [1, 2]); % 1 fila, 2 columnas
                    gl.ColumnWidth = {'1x', '1x'};
                    gl.Padding = [10 10 10 10];

                    % --- Matriz de Confusión (columna 1 del grid) ---
                    % Convertir etiquetas a categóricas
                    if ~isempty(app.YTestMLPLabels) % Solo si hay datos de prueba
                        actual_cat = categorical(app.YTestMLPLabels, [0 1], strrep(app.SpeciesNames, '_', ' '));
                        Y_pred_binary_clamped = max(0, min(1, Y_pred_binary));
                        pred_cat = categorical(Y_pred_binary_clamped, [0 1], strrep(app.SpeciesNames, '_', ' '));

                        try
                            cm = confusionchart(gl, actual_cat, pred_cat, ... % Parentar a gl
                                'Title', 'Matriz de Confusión (Prueba)', ...
                                'RowSummary','row-normalized', ...
                                'ColumnSummary','column-normalized');
                            cm.Layout.Row = 1;     % Especificar posición en el grid
                            cm.Layout.Column = 1;  % Especificar posición en el grid
                        catch ME_cm
                            warning('App:confusionError','Error generando confusionchart: %s', ME_cm.message);
                            % Mostrar mensaje de error en lugar del gráfico
                            error_label_cm = uilabel(gl, 'Text', sprintf('Error al generar Matriz de Confusión:\n%s', ME_cm.message), 'WordWrap', 'on', 'HorizontalAlignment','center');
                            error_label_cm.Layout.Row = 1;
                            error_label_cm.Layout.Column = 1;
                        end
                    else
                        no_data_label_cm = uilabel(gl, 'Text', 'Matriz de Confusión (Sin datos de prueba válidos)', 'HorizontalAlignment', 'center');
                        no_data_label_cm.Layout.Row = 1;
                        no_data_label_cm.Layout.Column = 1;
                    end

                    % --- Resumen de Texto (columna 2 del grid) ---
                    active_seq_display = 'Ninguna (Básico)';
                    if ~isempty(active_preprocessing_sequence_structs) && iscell(active_preprocessing_sequence_structs) && isfield(active_preprocessing_sequence_structs{1}, 'Name')
                        active_seq_names = cellfun(@(s) s.Name, active_preprocessing_sequence_structs, 'UniformOutput',false);
                        active_seq_display = strjoin(active_seq_names, ' -> ');
                    end

                    summary_text_lines = { % Cell array de strings
                        '--- RESUMEN DEL ENTRENAMIENTO ---'...
                        sprintf('Dataset: %s', app.DatasetRootPath)...
                        sprintf('Arquitectura MLP: [%s]', num2str(app.FullMLPArchitecture))...
                        sprintf('Secuencia Preproc.: %s', sequence_source_message)...
                        sprintf('  (Pasos: %s)', active_seq_display)...
                        sprintf('Alfa (LR): %.4f, Beta (Mom.): %.2f, Error MSE Obj.: %.5f', alpha, beta_mom, target_mse)...
                        '--- RESULTADOS ---'...
                        sprintf('Épocas: %d / %d', epochs_final, max_epochs)...
                        sprintf('Error MSE Final (Ent.): %.6f', mse_final)...
                        sprintf('Precisión (Prueba): %.2f %%', accuracy * 100)
                        };

                    % Crear TextArea en la segunda columna del layout
                    summary_text_area = uitextarea(gl, 'Value', summary_text_lines, ...
                        'HorizontalAlignment', 'left', 'Editable', 'off', 'FontSize', 10, 'WordWrap', 'on');
                    summary_text_area.Layout.Row = 1;
                    summary_text_area.Layout.Column = 2;

                catch ME_summary_fig % Si falla toda la creación de la ventana UI
                    app.showErrorAlert(ME_summary_fig, 'Error al generar ventana de resumen UI');
                    % Mostrar resumen en consola como fallback
                    fprintf('\n--- RESUMEN DEL ENTRENAMIENTO (Error al crear ventana UI) ---\n');
                    % Volver a generar texto por si falló antes de crearlo
                    if isempty(summary_text_lines)
                        summary_text_lines = {'Error al generar texto de resumen.'};
                    end
                    fprintf('%s\n', summary_text_lines{:});
                    fprintf('-----------------------------------------------------------\n');
                end

            catch ME_train_main % Capturar cualquier error no previsto durante todo el proceso
                app.showErrorAlert(ME_train_main, 'Error Mayor Durante el Entrenamiento o Post-Proceso');
            end
            % Reactivar/Desactivar botones al final (incluso si hubo error)
            app.EntrenarButton.Enable = 'on';
            app.DetenerButton.Enable = 'off';
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            app.showStatus('Iniciando proceso de aplicación...', 'aplicacion');
            app.TipomariposaEditField.Value = 'Procesando...'; % Limpiar predicción anterior
            drawnow;

            % --- 1. Validar Entradas ---
            weights_filepath = app.PesosEditField.Value;
            image_filepath = app.EntradaEditField.Value; % Ruta de la imagen a clasificar

            if isempty(weights_filepath) || ~isfile(weights_filepath)
                app.showError('Por favor, seleccione un archivo de pesos entrenados válido.', 'aplicacion');
                app.TipomariposaEditField.Value = 'Error: Falta archivo de pesos';
                return;
            end
            if isempty(image_filepath) || ~isfile(image_filepath)
                app.showError('Por favor, seleccione un archivo de imagen de entrada válido.', 'aplicacion');
                app.TipomariposaEditField.Value = 'Error: Falta imagen de entrada';
                return;
            end

            try
                % --- 2. Cargar Red Neuronal Entrenada (CORREGIDO) ---
                app.showStatus('Cargando red neuronal...', 'aplicacion'); drawnow;

                % --- Paso 2.1: Leer SOLO la arquitectura del archivo ---
                weights_filepath = app.PesosEditField.Value; % Ya validado que existe
                fid = fopen(weights_filepath,'r');
                if fid == -1
                    app.showError(sprintf('No se pudo abrir el archivo de pesos: %s', weights_filepath), 'aplicacion');
                    app.TipomariposaEditField.Value = 'Error carga pesos'; return;
                end
                header = fgetl(fid); % Leer primera línea (arquitectura)
                fclose(fid);
                loaded_arch = str2num(header); %#ok<ST2NM>
                if isempty(loaded_arch) || ~isnumeric(loaded_arch) || numel(loaded_arch) < 2
                    app.showError('Archivo de pesos no contiene arquitectura válida en la primera línea.', 'aplicacion');
                    app.TipomariposaEditField.Value = 'Error formato pesos'; return;
                end
                fprintf('INFO (Aplicación): Arquitectura leída del archivo [%s]\n', num2str(loaded_arch));

                % --- Paso 2.2: Definir las funciones de activación (las mismas usadas al entrenar) ---
                % Asumimos ReLU para ocultas y Sigmoide para salida (ajusta si usaste otras)
                num_hidden_layers_in_loaded = numel(loaded_arch) - 2;
                num_total_layers_act_loaded = num_hidden_layers_in_loaded + 1;
                app_act_fn_str = cell(1, num_total_layers_act_loaded);
                app_deriv_fn_str = cell(1, num_total_layers_act_loaded); % Aunque no se usa en feedforward, el constructor lo necesita
                relu_act_str = 'max(0, net)'; relu_deriv_str = 'double(act > 0)';
                sigmoid_act_str = '1./(1+exp(-net))'; sigmoid_deriv_str = 'act.*(1-act)';
                for l_idx = 1:num_hidden_layers_in_loaded
                    app_act_fn_str{l_idx} = relu_act_str;
                    app_deriv_fn_str{l_idx} = relu_deriv_str;
                end
                app_act_fn_str{end} = sigmoid_act_str;
                app_deriv_fn_str{end} = sigmoid_deriv_str;

                % --- Paso 2.3: Crear el objeto de red CON la arquitectura y funciones ---
                try
                    net = BP_neuronal_network(loaded_arch, app_act_fn_str, app_deriv_fn_str);
                catch ME_const
                    app.showError(sprintf('Error al inicializar red con arq. [%s]: %s', num2str(loaded_arch), ME_const.message), 'aplicacion');
                    app.TipomariposaEditField.Value = 'Error red'; return;
                end

                % --- Paso 2.4: Cargar los pesos y sesgos en el objeto ya inicializado ---
                try
                    [net, ~] = net.cargarPesos(weights_filepath); % cargarPesos ahora solo actualiza Pesos/Sesgos
                catch ME_load
                    app.showError(sprintf('Error al cargar pesos/sesgos del archivo: %s', ME_load.message), 'aplicacion');
                    app.TipomariposaEditField.Value = 'Error carga pesos'; return;
                end

                app.LoadedAppNetwork = net; % Guardar la red completamente inicializada y cargada
                app.showStatus('Red neuronal cargada.', 'aplicacion'); drawnow;

                % --- 3. Cargar y Preprocesar Imagen de Entrada (YA ESTABA BIEN) ---
                app.showStatus('Cargando y preprocesando imagen...', 'aplicacion'); drawnow;
                image_filepath = app.EntradaEditField.Value; % Ya validado que existe
                img_raw = imread(image_filepath);

                % Mostrar imagen original
                imshow(img_raw, 'Parent', app.ImagenCargada);
                img_filename_only = extractAfter(image_filepath, filesep);
                title(app.ImagenCargada, img_filename_only, 'Interpreter', 'none');
                app.OriginalImageDataApp = img_raw; % Guardar para histograma

                % >> Aplicar la secuencia de preprocesamiento definida en la PESTAÑA TRATAMIENTO <<
                current_treatment_sequence = app.AddedKernels;
                img_processed = app.aplicarSecuenciaTratamiento(img_raw, current_treatment_sequence);

                % >> Asegurar Escala de Grises y Redimensionar <<
                if size(img_processed, 3) == 3
                    img_processed_gray = rgb2gray(img_processed);
                else
                    img_processed_gray = img_processed;
                end
                if isempty(app.TargetImageSize), app.TargetImageSize = [64 64]; end % Asegurar default si no viene de entrenamiento
                img_resized = imresize(img_processed_gray, app.TargetImageSize);

                % >> Aplanar y Normalizar para la Red <<
                input_vector = double(img_resized(:)) / 255.0; % Vector columna normalizado [0,1]

                % Verificar si la entrada coincide con la red cargada
                if size(input_vector, 1) ~= net.Arquitectura(1) % Usar net.Arquitectura
                    app.showError(sprintf('Inconsistencia: Imagen procesada (%d caract.) != Entrada de red (%d).', size(input_vector,1), net.Arquitectura(1)), 'aplicacion');
                    app.TipomariposaEditField.Value = 'Error: Inconsistencia'; return;
                end

                % --- 4. Ejecutar Feedforward (Ahora debería funcionar)---
                app.showStatus('Clasificando imagen...', 'aplicacion'); drawnow;
                predicted_output_raw = net.feedforward(input_vector);

                % --- 5. Interpretar Resultado ---
                threshold = 0.5;
                predicted_label = double(predicted_output_raw >= threshold); % 0 o 1

                predicted_species = 'Desconocida';
                map_keys = keys(app.LabelsMap);
                map_values = values(app.LabelsMap);
                for i_map = 1:length(map_values)
                    if map_values{i_map} == predicted_label
                        predicted_species = map_keys{i_map}; break;
                    end
                end

                % --- 6. Mostrar Predicción ---
                app.TipomariposaEditField.Value = strrep(predicted_species, '_', ' ');
                app.showStatus(sprintf('Predicción: %s (Salida raw: %.4f)', app.TipomariposaEditField.Value, predicted_output_raw), 'aplicacion');

                % --- 7. Generar y Mostrar Histograma RGB de la Imagen ORIGINAL ---
                app.plotRGBHistogram(app.OriginalImageDataApp, app.UIAxes4); % Usar imagen guardada


            catch ME_ejecutar
                app.showErrorAlert(ME_ejecutar, 'Error durante la aplicación');
                app.TipomariposaEditField.Value = 'Error';
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = event.NewValue;
            target_axes = app.UIAxes4;
            img_data = app.OriginalImageDataApp;

            cla(target_axes);
            legend(target_axes, 'off');
            title(target_axes, 'Histograma');
            xlabel(target_axes, 'Intensidad');
            ylabel(target_axes, 'Num Píxeles');
            grid(target_axes, 'on');

            if isempty(img_data)
                title(target_axes, 'Histograma (No hay imagen cargada)');
                return;
            end

            try
                plotTitle = 'Histograma';
                is_rgb = (ndims(img_data) == 3 && size(img_data, 3) == 3);
                is_uint8_data = isa(img_data, 'uint8');

                current_xlim = 'auto'; % Default xlim
                if is_uint8_data, current_xlim = [-5 260]; end

                switch selectedButton.Text
                    case 'RGB'
                        if is_rgb
                            app.plotRGBHistogram(img_data, target_axes); % Llama a la función centralizada
                            return;
                        else
                            plotTitle = 'Histograma (Imagen no es RGB)';
                            [counts, bins] = imhist(img_data);
                            bar(target_axes, bins, counts, 'FaceColor', [0.3 0.3 0.3], 'EdgeColor', 'k', 'BarWidth', 1);
                        end

                    case 'R'
                        if is_rgb
                            plotTitle = 'Histograma - Canal Rojo';
                            [counts, bins] = imhist(img_data(:,:,1));
                            bar(target_axes, bins, counts, 'FaceColor', 'r', 'EdgeColor', 'none', 'FaceAlpha', 0.7, 'BarWidth', 1);
                        else
                            plotTitle = 'Histograma (Imagen no es RGB)';
                            [counts, bins] = imhist(img_data);
                            bar(target_axes, bins, counts, 'FaceColor', [0.3 0.3 0.3], 'EdgeColor', 'k', 'BarWidth', 1);
                        end

                    case 'G'
                        if is_rgb
                            plotTitle = 'Histograma - Canal Verde';
                            [counts, bins] = imhist(img_data(:,:,2));
                            bar(target_axes, bins, counts, 'FaceColor', 'g', 'EdgeColor', 'none', 'FaceAlpha', 0.7, 'BarWidth', 1);
                        else
                            plotTitle = 'Histograma (Imagen no es RGB)';
                            [counts, bins] = imhist(img_data);
                            bar(target_axes, bins, counts, 'FaceColor', [0.3 0.3 0.3], 'EdgeColor', 'k', 'BarWidth', 1);
                        end

                    case 'B'
                        if is_rgb
                            plotTitle = 'Histograma - Canal Azul';
                            [counts, bins] = imhist(img_data(:,:,3));
                            bar(target_axes, bins, counts, 'FaceColor', 'b', 'EdgeColor', 'none', 'FaceAlpha', 0.7, 'BarWidth', 1);
                        else
                            plotTitle = 'Histograma (Imagen no es RGB)';
                            [counts, bins] = imhist(img_data);
                            bar(target_axes, bins, counts, 'FaceColor', [0.3 0.3 0.3], 'EdgeColor', 'k', 'BarWidth', 1);
                        end

                    case 'Brillo'
                        plotTitle = 'Histograma - Brillo (Luminancia)';
                        img_to_hist = img_data;
                        if is_rgb
                            img_to_hist = rgb2gray(img_data);
                        end
                        [counts, bins] = imhist(img_to_hist);
                        bar(target_axes, bins, counts, 'FaceColor', [0.3 0.3 0.3], 'EdgeColor', 'k', 'BarWidth', 1);
                        if isa(img_to_hist, 'uint8'), current_xlim = [-5 260]; end

                    otherwise
                        plotTitle = 'Histograma (Modo desconocido)';
                end

                title(target_axes, plotTitle);
                xlim(target_axes, current_xlim);

            catch ME_hist_mode
                cla(target_axes); title(target_axes, 'Error al generar histograma');
                text(target_axes, 0.1, 0.5, {'Error:', ME_hist_mode.message}, 'Interpreter', 'none');
                warning('App:histogramError','Error en modo histograma: %s', ME_hist_mode.message);
            end
        end

        % Button pushed function: SubirButton
        function SubirButtonPushed(app, event)
            selected_value_from_listbox = app.AddedKernelsListBox.Value;

            % Validar la selección
            if isempty(selected_value_from_listbox) || (ischar(selected_value_from_listbox) && isempty(strtrim(selected_value_from_listbox))) || (isstring(selected_value_from_listbox) && strlength(selected_value_from_listbox)==0)
                uialert(app.UIFigure, 'Seleccione un ítem de la lista para mover.', 'Advertencia');
                return;
            end

            % Asegurarse de que selected_display_text sea un char/string escalar
            selected_display_text = '';
            if iscell(selected_value_from_listbox) % Caso de Multiselect (aunque no es default)
                if ~isempty(selected_value_from_listbox)
                    selected_display_text = char(selected_value_from_listbox{1}); % Tomar el primero
                    if numel(selected_value_from_listbox) > 1
                        uialert(app.UIFigure, 'Por favor, mueva un ítem a la vez.', 'Advertencia');
                        return;
                    end
                else % Cell vacío
                    uialert(app.UIFigure, 'Seleccione un ítem de la lista para mover.', 'Advertencia');
                    return;
                end
            else % Caso de single select (char o string)
                selected_display_text = char(selected_value_from_listbox);
            end


            % Encontrar el índice del struct correspondiente en app.AddedKernels
            idx_to_move = -1;
            for k = 1:length(app.AddedKernels)
                item_struct = app.AddedKernels{k};
                % Usar la función helper para obtener el texto de display del struct
                current_item_display_text_from_struct = app.getDisplayStructText(item_struct);
                if strcmp(selected_display_text, current_item_display_text_from_struct)
                    idx_to_move = k;
                    break;
                end
            end

            if idx_to_move > 1 % Solo se puede subir si no es el primero
                % Intercambiar elementos en app.AddedKernels
                temp_item = app.AddedKernels{idx_to_move - 1};
                app.AddedKernels{idx_to_move - 1} = app.AddedKernels{idx_to_move};
                app.AddedKernels{idx_to_move} = temp_item;

                app.updateAddedKernelsListBox(); % Actualizar ListBox
                % Mantener la selección en el ítem movido (ahora en nueva posición)
                app.AddedKernelsListBox.Value = app.getDisplayStructText(app.AddedKernels{idx_to_move - 1});
            elseif idx_to_move == -1
                uialert(app.UIFigure, 'No se encontró el ítem seleccionado en la lista interna (Subir).', 'Error');
            elseif idx_to_move == 1
                uialert(app.UIFigure, 'El ítem ya está al inicio de la lista.', 'Información');
            end
        end

        % Button pushed function: BajarButton
        function BajarButtonPushed(app, event)
            selected_value_from_listbox = app.AddedKernelsListBox.Value;

            % Validar la selección
            if isempty(selected_value_from_listbox) || (ischar(selected_value_from_listbox) && isempty(strtrim(selected_value_from_listbox))) || (isstring(selected_value_from_listbox) && strlength(selected_value_from_listbox)==0)
                uialert(app.UIFigure, 'Seleccione un ítem de la lista para mover.', 'Advertencia');
                return;
            end

            selected_display_text = '';
            if iscell(selected_value_from_listbox)
                if ~isempty(selected_value_from_listbox)
                    selected_display_text = char(selected_value_from_listbox{1});
                    if numel(selected_value_from_listbox) > 1
                        uialert(app.UIFigure, 'Por favor, mueva un ítem a la vez.', 'Advertencia');
                        return;
                    end
                else
                    uialert(app.UIFigure, 'Seleccione un ítem de la lista para mover.', 'Advertencia');
                    return;
                end
            else
                selected_display_text = char(selected_value_from_listbox);
            end

            idx_to_move = -1;
            for k = 1:length(app.AddedKernels)
                item_struct = app.AddedKernels{k};
                current_item_display_text_from_struct = app.getDisplayStructText(item_struct);
                if strcmp(selected_display_text, current_item_display_text_from_struct)
                    idx_to_move = k;
                    break;
                end
            end

            if idx_to_move > 0 && idx_to_move < length(app.AddedKernels) % Solo se puede bajar si no es el último
                temp_item = app.AddedKernels{idx_to_move + 1};
                app.AddedKernels{idx_to_move + 1} = app.AddedKernels{idx_to_move};
                app.AddedKernels{idx_to_move} = temp_item;

                app.updateAddedKernelsListBox(); % Actualizar ListBox
                app.AddedKernelsListBox.Value = app.getDisplayStructText(app.AddedKernels{idx_to_move + 1});
            elseif idx_to_move == -1
                uialert(app.UIFigure, 'No se encontró el ítem seleccionado en la lista interna (Bajar).', 'Error');
            elseif idx_to_move == length(app.AddedKernels) && idx_to_move > 0
                uialert(app.UIFigure, 'El ítem ya está al final de la lista.', 'Información');
            end
        end

        % Button pushed function: GuardarsecuenciadeTratamientoButton
        function GuardarsecuenciadeTratamientoButtonPushed(app, event)
            if isempty(app.AddedKernels)
                uialert(app.UIFigure, 'La lista de filtros/kernels está vacía. No hay nada que guardar.', 'Secuencia Vacía', 'Icon', 'warning');
                return;
            end

            % Pedir al usuario dónde guardar el archivo
            filter = {'*.mat', 'Archivo de Secuencia de Tratamiento (*.mat)'};
            dialogTitle = 'Guardar Secuencia de Tratamiento Como';
            [fileName, pathName] = uiputfile(filter, dialogTitle, 'mi_secuencia_tratamiento.mat');

            if isequal(fileName, 0) || isequal(pathName, 0)
                app.showStatus('Guardado de secuencia cancelado.', 'tratamiento');
                return;
            end

            fullPath = fullfile(pathName, fileName);

            try
                % Guardar la variable app.AddedKernels (que es un cell array de structs)
                secuenciaParaGuardar = app.AddedKernels;
                save(fullPath, 'secuenciaParaGuardar'); % Guarda la variable con su nombre
                app.showStatus(sprintf('Secuencia de tratamiento guardada en: %s', fullPath), 'tratamiento');
                uialert(app.UIFigure, sprintf('Secuencia de %d pasos guardada con éxito.', numel(app.AddedKernels)), 'Guardado Exitoso');
            catch ME_save
                app.showErrorAlert(ME_save, 'Error al Guardar Secuencia');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TratamientoTab = uitab(app.TabGroup);
            app.TratamientoTab.Title = 'Tratamiento';
            app.UIImagenOriginal = uiaxes(app.TratamientoTab);
            title(app.UIImagenOriginal, 'Title')
            app.UIImagenTratada = uiaxes(app.TratamientoTab);
            title(app.UIImagenTratada, 'Title')
            app.EntrenamientoLaboratorio4InteligenciaArtificialLabel_2 = uilabel(app.TratamientoTab);
            app.EntrenamientoLaboratorio4InteligenciaArtificialLabel_2.Text = 'Entrenamiento Laboratorio #4 Inteligencia Artificial';
            app.Image_3 = uiimage(app.TratamientoTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'IMAGOTIPO VERTICAL COLOR.png');
            app.Label_6 = uilabel(app.TratamientoTab);
            app.Label_6.Text = {'Presentado por Juan Esteban Fuentes Rojas, Duvan Santiago Matallana Jiménez y'; ' Laura Estefania Latorre Pachon'};
            app.UniversidaddeCundinamarcaLabel_3 = uilabel(app.TratamientoTab);
            app.UniversidaddeCundinamarcaLabel_3.Text = 'Universidad de Cundinamarca';
            app.Kernel3x3DropDownLabel = uilabel(app.TratamientoTab);
            app.Kernel3x3DropDownLabel.Text = 'Kernel 3x3';
            app.Kernel3x3DropDown = uidropdown(app.TratamientoTab);
            app.Kernel3x3DropDown.Items = {'Enfoque Sharpen', 'Deteccion de bordes Laplaciano', 'Deteccion de Bordes Fuerte', 'Desenfoque Box Blur', 'Repujado Emboss 1', 'Repujado Emboss 2', 'Prewitt X', 'Prewitt Y', 'Sobel X', 'Sobel Y', 'Realce Laplaciano', 'Ninguno'};
            app.Kernel3x3DropDown.ValueChangedFcn = createCallbackFcn(app, @Kernel3x3DropDownValueChanged, true);
            app.Kernel3x3DropDown.Value = 'Enfoque Sharpen';
            app.DeshacerButton = uibutton(app.TratamientoTab, 'push');
            app.DeshacerButton.ButtonPushedFcn = createCallbackFcn(app, @DeshacerButtonPushed, true);
            app.DeshacerButton.Text = 'Deshacer ';
            app.Seleccionarimagenkernel = uibutton(app.TratamientoTab, 'push');
            app.Seleccionarimagenkernel.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarimagenkernelButtonPushed, true);
            app.Seleccionarimagenkernel.Text = 'Seleccionar archivo';
            app.EntradasEditField_2Label = uilabel(app.TratamientoTab);
            app.EntradasEditField_2Label.Text = 'Entradas';
            app.Rutaimagenkernel = uieditfield(app.TratamientoTab, 'text');
            app.Rutaimagenkernel.Placeholder = 'C:/Users/folder';
            app.Kernel5x5DropDownLabel = uilabel(app.TratamientoTab);
            app.Kernel5x5DropDownLabel.Text = 'Kernel 5x5';
            app.Kernel5x5DropDown = uidropdown(app.TratamientoTab);
            app.Kernel5x5DropDown.Items = {'Desenfoque Gaussiano', 'Desenfoque Medio', 'Deteccion Bordes Laplaciano', 'Realce Sharpen 5x5', 'Relieve Emboss 5x5', 'Prewitt X 5x5', 'Prewitt Y 5x5', 'Ninguno'};
            app.Kernel5x5DropDown.ValueChangedFcn = createCallbackFcn(app, @Kernel5x5DropDownValueChanged, true);
            app.Kernel5x5DropDown.Value = 'Desenfoque Gaussiano';
            app.FiltrosdeColorDropDownLabel = uilabel(app.TratamientoTab);
            app.FiltrosdeColorDropDownLabel.Text = 'Filtros de Color';
            app.FiltrosdeColorDropDown = uidropdown(app.TratamientoTab);
            app.FiltrosdeColorDropDown.Items = {'Escala de Grises', 'Sepia', 'Invertir', 'Blanco y Negro', 'Cálido', 'Frío', 'Ajustar Saturación', 'Disminuir Saturación', 'Ninguno'};
            app.FiltrosdeColorDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltrosdeColorDropDownValueChanged, true);
            app.FiltrosdeColorDropDown.Value = 'Sepia';
            app.FiltrosaAplicarLabel = uilabel(app.TratamientoTab);
            app.FiltrosaAplicarLabel.Text = 'Filtros a Aplicar';
            app.AgregarKernelButton = uibutton(app.TratamientoTab, 'push');
            app.AgregarKernelButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarKernelButtonPushed, true);
            app.AgregarKernelButton.Text = 'Añadir Kernel';
            app.MostrarKernelListaButton = uibutton(app.TratamientoTab, 'push');
            app.MostrarKernelListaButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarKernelListaButtonPushed, true);
            app.MostrarKernelListaButton.Text = 'Mostrar Kernels Añadidos';
            app.EliminarKernelButton = uibutton(app.TratamientoTab, 'push');
            app.EliminarKernelButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarKernelButtonPushed, true);
            app.EliminarKernelButton.Text = 'Eliminar Kernel';
            app.DatosparaentrenamientoLabel = uilabel(app.TratamientoTab);
            app.DatosparaentrenamientoLabel.Text = 'Datos para entrenamiento';
            app.KernelsAadidosLabel = uilabel(app.TratamientoTab);
            app.KernelsAadidosLabel.Text = 'Kernels Añadidos';
            app.AddedKernelsListBox = uilistbox(app.TratamientoTab);
            app.AddedKernelsListBox.Items = {''};
            app.AddedKernelsListBox.Value = '';
            app.GuardarsecuenciadeTratamientoButton = uibutton(app.TratamientoTab, 'push');
            app.GuardarsecuenciadeTratamientoButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarsecuenciadeTratamientoButtonPushed, true);
            app.GuardarsecuenciadeTratamientoButton.Text = 'Guardar secuencia de Tratamiento';
            app.SubirButton = uibutton(app.TratamientoTab, 'push');
            app.SubirButton.ButtonPushedFcn = createCallbackFcn(app, @SubirButtonPushed, true);
            app.SubirButton.Text = 'Subir';
            app.BajarButton = uibutton(app.TratamientoTab, 'push');
            app.BajarButton.ButtonPushedFcn = createCallbackFcn(app, @BajarButtonPushed, true);
            app.BajarButton.Text = 'Bajar';
            app.EntrenamientoTab = uitab(app.TabGroup);
            app.EntrenamientoTab.Title = 'Entrenamiento';
            app.UIAxes3 = uiaxes(app.EntrenamientoTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.EntrenamientoTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.VerArquitectura = uiaxes(app.EntrenamientoTab);
            title(app.VerArquitectura, 'Title')
            xlabel(app.VerArquitectura, 'X')
            ylabel(app.VerArquitectura, 'Y')
            zlabel(app.VerArquitectura, 'Z')
            app.EntrenamientoLaboratorio4InteligenciaArtificialLabel = uilabel(app.EntrenamientoTab);
            app.EntrenamientoLaboratorio4InteligenciaArtificialLabel.Text = 'Entrenamiento Laboratorio #4 Inteligencia Artificial';
            app.Image = uiimage(app.EntrenamientoTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMAGOTIPO VERTICAL COLOR.png');
            app.Label = uilabel(app.EntrenamientoTab);
            app.Label.Text = {'Presentado por Juan Esteban Fuentes Rojas, Duvan Santiago Matallana Jiménez y'; ' Laura Estefania Latorre Pachon'};
            app.UniversidaddeCundinamarcaLabel = uilabel(app.EntrenamientoTab);
            app.UniversidaddeCundinamarcaLabel.Text = 'Universidad de Cundinamarca';
            app.DatosdeentrenamientoLabel = uilabel(app.EntrenamientoTab);
            app.DatosdeentrenamientoLabel.Text = 'Datos de entrenamiento';
            app.CargadearchivosporfavorseleccioneunarchivoLabel = uilabel(app.EntrenamientoTab);
            app.CargadearchivosporfavorseleccioneunarchivoLabel.Text = 'Carga de archivos, por favor seleccione un archivo';
            app.SeleccionararchivoButton = uibutton(app.EntrenamientoTab, 'push');
            app.SeleccionararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButtonPushed, true);
            app.SeleccionararchivoButton.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_2 = uibutton(app.EntrenamientoTab, 'push');
            app.SeleccionararchivoButton_2.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_2Pushed, true);
            app.SeleccionararchivoButton_2.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_3 = uibutton(app.EntrenamientoTab, 'push');
            app.SeleccionararchivoButton_3.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_3Pushed, true);
            app.SeleccionararchivoButton_3.Text = 'Seleccionar archivo';
            app.EntrenarButton = uibutton(app.EntrenamientoTab, 'push');
            app.EntrenarButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarButtonPushed, true);
            app.EntrenarButton.Text = 'Entrenar';
            app.ArquitecturaLabel = uilabel(app.EntrenamientoTab);
            app.ArquitecturaLabel.Text = 'Arquitectura';
            app.PesosvspocasLabel = uilabel(app.EntrenamientoTab);
            app.PesosvspocasLabel.Text = 'Pesos vs Épocas';
            app.ErrorvspocasLabel = uilabel(app.EntrenamientoTab);
            app.ErrorvspocasLabel.Text = 'Error vs Épocas';
            app.ArquitecturaEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.ArquitecturaEditFieldLabel.Text = 'Arquitectura';
            app.ArquitecturaEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.ArquitecturaEditField.Placeholder = '5 4 2';
            app.PesosInicialesEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.PesosInicialesEditField.Placeholder = 'C:/Users/folder';
            app.PesosInicialesEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.PesosInicialesEditFieldLabel.Text = {'Pesos'; 'Iniciales'};
            app.GuardarPesosenEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.GuardarPesosenEditFieldLabel.Text = {'Guardar '; 'Pesos en'};
            app.GuardarPesosenEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.GuardarPesosenEditField.Value = 'pesos_entrenados_vocales.txt';
            app.MensajesdeestadoLabel = uilabel(app.EntrenamientoTab);
            app.MensajesdeestadoLabel.Text = '';
            app.pocasEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.pocasEditFieldLabel.Text = 'Épocas';
            app.pocasEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.pocasEditField.Editable = 'off';
            app.ErrorFinalEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.ErrorFinalEditFieldLabel.Text = 'Error Final';
            app.ErrorFinalEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.ErrorFinalEditField.Editable = 'off';
            app.EntradasEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.EntradasEditFieldLabel.Text = 'Entradas';
            app.EntradasEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.EntradasEditField.Placeholder = 'C:/Users/folder';
            app.SalidasdeseadasEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.SalidasdeseadasEditFieldLabel.Text = {'Salidas'; 'deseadas'};
            app.SalidasdeseadasEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.SalidasdeseadasEditField.Placeholder = 'C:/Users/folder';
            app.AlphaEditField_2Label = uilabel(app.EntrenamientoTab);
            app.AlphaEditField_2Label.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.AlphaEditField.Value = 0.01;
            app.BetaEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.BetaEditField.Value = 0.01;
            app.BetaEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.PrecisinEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.PrecisinEditFieldLabel.Text = 'Precisión';
            app.PrecisinEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.PrecisinEditField.Value = 0.01;
            app.SeleccionarrutaButton = uibutton(app.EntrenamientoTab, 'push');
            app.SeleccionarrutaButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarRutaButtonPushed, true);
            app.SeleccionarrutaButton.Text = 'Seleccionar ruta';
            app.PrecisionClaficicacinLabel = uilabel(app.EntrenamientoTab);
            app.PrecisionClaficicacinLabel.Text = {'Precision '; 'Clasificación %'};
            app.ErrorGeneralEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.ErrorGeneralEditField.Editable = 'off';
            app.DetenerButton = uibutton(app.EntrenamientoTab, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.pocaactualEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.pocaactualEditFieldLabel.Text = 'Época actual';
            app.EpocaActualEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.EpocaActualEditField.Editable = 'off';
            app.ErrorActualEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.ErrorActualEditFieldLabel.Text = 'Error Actual';
            app.ErrorActualEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.ErrorActualEditField.Editable = 'off';
            app.AlphaEditField_2Label_2 = uilabel(app.EntrenamientoTab);
            app.AlphaEditField_2Label_2.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.ThetaEditField.Value = 0.01;
            app.pocasMximasEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.pocasMximasEditFieldLabel.Text = 'Épocas Máximas';
            app.MaxEpochsEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.MaxEpochsEditField.Value = 1;
            app.AplicacinTab = uitab(app.TabGroup);
            app.AplicacinTab.Title = 'Aplicación';
            app.ImagenCargada = uiaxes(app.AplicacinTab);
            title(app.ImagenCargada, 'Title')
            zlabel(app.ImagenCargada, 'Z')
            app.UIAxes4 = uiaxes(app.AplicacinTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.AplicacinLaboratorio4InteligenciaArtificialLabel = uilabel(app.AplicacinTab);
            app.AplicacinLaboratorio4InteligenciaArtificialLabel.Text = 'Aplicación Laboratorio #4 Inteligencia Artificial';
            app.Image_2 = uiimage(app.AplicacinTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'IMAGOTIPO VERTICAL COLOR.png');
            app.Label_2 = uilabel(app.AplicacinTab);
            app.Label_2.Text = {'Presentado por Juan Esteban Fuentes Rojas, Duvan Santiago Matallana Jiménez y'; ' Laura Estefania Latorre Pachon'};
            app.UniversidaddeCundinamarcaLabel_2 = uilabel(app.AplicacinTab);
            app.UniversidaddeCundinamarcaLabel_2.Text = 'Universidad de Cundinamarca';
            app.SeleccionararchivoButton_4 = uibutton(app.AplicacinTab, 'push');
            app.SeleccionararchivoButton_4.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_4Pushed, true);
            app.SeleccionararchivoButton_4.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_5 = uibutton(app.AplicacinTab, 'push');
            app.SeleccionararchivoButton_5.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_5Pushed, true);
            app.SeleccionararchivoButton_5.Text = 'Seleccionar archivo';
            app.EjecutarButton = uibutton(app.AplicacinTab, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.ImagencargadaLabel = uilabel(app.AplicacinTab);
            app.ImagencargadaLabel.Text = 'Imagen cargada';
            app.Label_5 = uilabel(app.AplicacinTab);
            app.Label_5.Text = '';
            app.EntradaEditFieldLabel = uilabel(app.AplicacinTab);
            app.EntradaEditFieldLabel.Text = 'Entrada';
            app.EntradaEditField = uieditfield(app.AplicacinTab, 'text');
            app.PesosEditFieldLabel = uilabel(app.AplicacinTab);
            app.PesosEditFieldLabel.Text = 'Pesos';
            app.PesosEditField = uieditfield(app.AplicacinTab, 'text');
            app.TipomariposaEditFieldLabel = uilabel(app.AplicacinTab);
            app.TipomariposaEditFieldLabel.Text = 'Tipo mariposa';
            app.TipomariposaEditField = uieditfield(app.AplicacinTab, 'text');
            app.HistogramaRGBLabel = uilabel(app.AplicacinTab);
            app.HistogramaRGBLabel.Text = 'Histograma RGB';
            app.ButtonGroup = uibuttongroup(app.AplicacinTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.BrilloButton_2 = uitogglebutton(app.ButtonGroup);
            app.BrilloButton_2.Text = 'Brillo';
            app.BrilloButton_2.Value = true;
            app.RGBButton_2 = uitogglebutton(app.ButtonGroup);
            app.RGBButton_2.Text = 'RGB';
            app.RButton = uitogglebutton(app.ButtonGroup);
            app.RButton.Text = 'R';
            app.GButton = uitogglebutton(app.ButtonGroup);
            app.GButton.Text = 'G';
            app.BButton = uitogglebutton(app.ButtonGroup);
            app.BButton.Text = 'B';
            app.DimensionesEditFieldLabel = uilabel(app.AplicacinTab);
            app.DimensionesEditFieldLabel.Text = 'Dimensiones';
            app.DimensionesEditField = uieditfield(app.AplicacinTab, 'text');
            app.TamaoEditFieldLabel = uilabel(app.AplicacinTab);
            app.TamaoEditFieldLabel.Text = 'Tamaño';
            app.TamaoEditField = uieditfield(app.AplicacinTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Mariposas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end