classdef HistogramaRGB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TamaoEditField             matlab.ui.control.EditField
        TamaoEditFieldLabel        matlab.ui.control.Label
        DimensionesEditField       matlab.ui.control.EditField
        DimensionesEditFieldLabel  matlab.ui.control.Label
        ButtonGroup                matlab.ui.container.ButtonGroup
        BButton                    matlab.ui.control.ToggleButton
        GButton                    matlab.ui.control.ToggleButton
        RButton                    matlab.ui.control.ToggleButton
        RGBButton                  matlab.ui.control.ToggleButton
        BrilloButton               matlab.ui.control.ToggleButton
        CargarImagenButton         matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CurrentImage      % Almacena la matriz de la imagen cargada
        ImageFilePath     % Ruta completa del archivo cargado
        HistogramBins     % Ubicaciones de los bins (normalmente 0:255)
        HistCountsR       % Conteos del histograma Rojo
        HistCountsG       % Conteos del histograma Verde
        HistCountsB       % Conteos del histograma Azul
        HistCountsL       % Conteos del histograma de Luminancia ('Brillo')
    end

    methods (Access = private)
        % Function to update the histogram plot based on selection
        function updateHistogramPlot(app)
            % --- Nombres de componentes (Asegúrate que coincidan) ---
            histogramAxesName = 'UIAxes';
            histogramButtonGroup = 'ButtonGroup';
            % ------------------------------------

            % Verificar componentes y datos necesarios
            if ~isprop(app, histogramAxesName) || ~isvalid(app.(histogramAxesName)); disp(['Error: Ejes "', histogramAxesName, '" no válidos.']); return; end
            targetAxes = app.(histogramAxesName);
            if ~isprop(app, histogramButtonGroup) || ~isvalid(app.(histogramButtonGroup)); disp(['Error: Grupo Botones "', histogramButtonGroup, '" no válido.']); cla(targetAxes); title(targetAxes, 'Hist. (Error Grupo)'); legend(targetAxes, 'off'); return; end
            buttonGroup = app.(histogramButtonGroup);

            if isempty(app.CurrentImage); cla(targetAxes); title(targetAxes, 'Histograma'); legend(targetAxes, 'off'); return; end % Salir si no hay imagen

            selectedButton = buttonGroup.SelectedObject;
            if isempty(selectedButton); cla(targetAxes); title(targetAxes, 'Histograma (Seleccione)'); legend(targetAxes, 'off'); return; end % Salir si no hay botón seleccionado

            % Obtener el Tag del botón seleccionado (¡IMPORTANTE: Asignar Tags en App Designer!)
            if isprop(selectedButton,'Tag') && ~isempty(selectedButton.Tag)
                selectedTag = selectedButton.Tag;
            else
                selectedTag = ''; % Tag vacío si no se asignó
                disp(['Advertencia: Botón "', selectedButton.Text, '" no tiene Tag asignado en App Designer.']);
            end

            % Limpiar y preparar ejes
            cla(targetAxes);
            hold(targetAxes, 'on');

            % Dibujar histograma según el Tag del botón seleccionado
            try
                switch selectedTag
                    case 'RGB'
                        if ~isempty(app.HistCountsR)
                            plot(targetAxes, app.HistogramBins, app.HistCountsR, 'r', 'LineWidth', 1); % Línea más fina puede verse mejor
                            plot(targetAxes, app.HistogramBins, app.HistCountsG, 'g', 'LineWidth', 1);
                            plot(targetAxes, app.HistogramBins, app.HistCountsB, 'b', 'LineWidth', 1);
                            legend(targetAxes, 'Rojo', 'Verde', 'Azul', 'Location', 'northeast');
                            title(targetAxes, 'Histograma RGB');
                        else; title(targetAxes, 'Histograma (No es RGB)'); legend(targetAxes,'off'); end
                    case 'R'
                        if ~isempty(app.HistCountsR); plot(targetAxes, app.HistogramBins, app.HistCountsR, 'r', 'LineWidth', 1.5); legend(targetAxes, 'Rojo', 'Location', 'northeast'); title(targetAxes, 'Hist. Rojo'); else; title(targetAxes, 'Hist. (No disponible)'); legend(targetAxes,'off'); end
                    case 'G'
                        if ~isempty(app.HistCountsG); plot(targetAxes, app.HistogramBins, app.HistCountsG, 'g', 'LineWidth', 1.5); legend(targetAxes, 'Verde', 'Location', 'northeast'); title(targetAxes, 'Hist. Verde'); else; title(targetAxes, 'Hist. (No disponible)'); legend(targetAxes,'off'); end
                    case 'B'
                        if ~isempty(app.HistCountsB); plot(targetAxes, app.HistogramBins, app.HistCountsB, 'b', 'LineWidth', 1.5); legend(targetAxes, 'Azul', 'Location', 'northeast'); title(targetAxes, 'Hist. Azul'); else; title(targetAxes, 'Hist. (No disponible)'); legend(targetAxes,'off'); end
                    case 'Brillo'
                        if ~isempty(app.HistCountsL); plot(targetAxes, app.HistogramBins, app.HistCountsL, 'k', 'LineWidth', 1.5); legend(targetAxes, 'Luminancia', 'Location', 'northeast'); title(targetAxes, 'Hist. Brillo'); else; title(targetAxes, 'Hist. (No disponible)'); legend(targetAxes,'off'); end
                    otherwise
                        title(targetAxes, 'Histograma (Tag ?)'); legend(targetAxes,'off');
                end
            catch ME_plot
                uialert(app.UIFigure, sprintf('Error al dibujar histograma: %s', ME_plot.message), 'Error de Dibujo');
                title(targetAxes, 'Error al dibujar'); legend(targetAxes,'off');
            end

            % Configuración final de ejes
            hold(targetAxes, 'off');
            grid(targetAxes, 'on');
            xlabel(targetAxes, 'Nivel de Intensidad');
            ylabel(targetAxes, 'Num. Píxeles');
            xlim(targetAxes, [0 255]);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarImagenButton
        function CargarImagenButtonPushed(app, event)
            % --- Nombres de componentes (Asegúrate que coincidan) ---
            imageAxesName = 'UIAxes2';
            dimensionsFieldName = 'DimensionesEditField'; % Corregido para EditField
            sizeFieldName = 'TamaoEditField';          % Corregido para EditField
            histogramAxesName = 'UIAxes';
            histogramButtonGroup = 'ButtonGroup';
            buttonGroupEnableCheck = {'BrilloButton', 'RGBButton', 'RButton', 'GButton', 'BButton'};
            % -------------------------------------------- %

            % Selección de archivo
            filterSpec = {'*.jpg;*.jpeg;*.png;*.tif;*.tiff;*.bmp;*.gif','Archivos de Imagen'; '*.*', 'Todos los Archivos'};
            dialogTitle = 'Selecciona un archivo de imagen';
            [fileName, pathName] = uigetfile(filterSpec, dialogTitle);
            if isequal(fileName, 0) || isequal(pathName, 0); disp('Operación cancelada.'); return; end % Cancelado por usuario
            app.ImageFilePath = fullfile(pathName, fileName);

            % Carga de imagen
            try
                app.CurrentImage = imread(app.ImageFilePath);
            catch ME
                app.CurrentImage = []; % Limpiar si falla
                uialert(app.UIFigure, sprintf('Error al cargar imagen "%s": %s', fileName, ME.message), 'Error de Lectura');
                % Limpiar UI si falla la carga
                if isprop(app, imageAxesName) && isvalid(app.(imageAxesName)); cla(app.(imageAxesName)); app.(imageAxesName).Title.String = ''; end
                if isprop(app, dimensionsFieldName); app.(dimensionsFieldName).Value = '-'; end % Usa .Value
                if isprop(app, sizeFieldName); app.(sizeFieldName).Value = '-'; end             % Usa .Value
                if isprop(app, histogramAxesName) && isvalid(app.(histogramAxesName)); cla(app.(histogramAxesName)); legend(app.(histogramAxesName), 'off'); app.(histogramAxesName).Title.String = 'Histograma'; end
                for i = 1:length(buttonGroupEnableCheck); if isprop(app, buttonGroupEnableCheck{i}); app.(buttonGroupEnableCheck{i}).Enable = 'off'; end; end
                return;
            end

            % Verificaciones básicas de la imagen cargada
            if isempty(app.CurrentImage); uialert(app.UIFigure, 'Error Interno: Imagen vacía.', 'Error'); return; end
            if ~isnumeric(app.CurrentImage) && ~islogical(app.CurrentImage); uialert(app.UIFigure, 'Error Interno: Datos no numéricos.', 'Error'); return; end
            if ~isprop(app, imageAxesName) || ~isvalid(app.(imageAxesName)); uialert(app.UIFigure, sprintf('Error Interfaz: Ejes "%s" no válidos.', imageAxesName), 'Error'); return; end

            % Mostrar imagen en la App usando imagesc
            try
                imagesc(app.(imageAxesName), app.CurrentImage);
                axis(app.(imageAxesName), 'image', 'off'); % Ajustar aspecto y quitar ejes numerados
                drawnow; % Forzar actualización gráfica

                app.(imageAxesName).Title.String = fileName; % Poner título
                app.(imageAxesName).Title.Interpreter = 'none';
            catch ME_display
                uialert(app.UIFigure, sprintf('Error al mostrar con imagesc: %s\nArchivo: %s (Línea: %d)', ...
                     ME_display.message, ME_display.stack(1).file, ME_display.stack(1).line), 'Error al Mostrar');
                cla(app.(imageAxesName));
                app.(imageAxesName).Title.String = 'Error al mostrar';
                return; % Salir si falla la visualización
            end

            % Mostrar dimensiones y tamaño
            try
                imgSize = size(app.CurrentImage);
                dimsStr = sprintf('%d x %d', imgSize(2), imgSize(1)); % Ancho x Alto
                if numel(imgSize) == 3; dimsStr = sprintf('%s (%d canales)', dimsStr, imgSize(3)); end
                if isprop(app, dimensionsFieldName); app.(dimensionsFieldName).Value = dimsStr; else; disp(['Adv: No se encontró ', dimensionsFieldName]); end % Usa .Value

                fileInfo = dir(app.ImageFilePath);
                fileSizeKB = fileInfo.bytes / 1024;
                if isprop(app, sizeFieldName); app.(sizeFieldName).Value = sprintf('%.2f KB', fileSizeKB); else; disp(['Adv: No se encontró ', sizeFieldName]); end % Usa .Value
            catch ME_info
                disp(['Adv: Error obteniendo info: ', ME_info.message]);
                if isprop(app, dimensionsFieldName); app.(dimensionsFieldName).Value = 'Error'; end
                if isprop(app, sizeFieldName); app.(sizeFieldName).Value = 'Error'; end
            end

            % Calcular y almacenar histogramas
            app.HistogramBins = (0:255)'; % Definir bins (vector columna)
            try
                 if size(app.CurrentImage, 3) == 3 % Imagen a color
                    [app.HistCountsR, ~] = imhist(app.CurrentImage(:,:,1));
                    [app.HistCountsG, ~] = imhist(app.CurrentImage(:,:,2));
                    [app.HistCountsB, ~] = imhist(app.CurrentImage(:,:,3));
                    try imgGray = im2gray(app.CurrentImage); [app.HistCountsL, ~] = imhist(imgGray);
                    catch ME_gray; disp(['Adv: No se pudo convertir a gris: ', ME_gray.message]); app.HistCountsL = []; end % Calcular luminancia
                    % Habilitar todos los botones (excepto brillo si falló la conversión)
                    for i = 1:length(buttonGroupEnableCheck); if isprop(app, buttonGroupEnableCheck{i}); app.(buttonGroupEnableCheck{i}).Enable = 'on'; end; end
                    if isempty(app.HistCountsL) && isprop(app, 'BrilloButton'); app.BrilloButton.Enable = 'off'; end
                else % Imagen en escala de grises
                    [app.HistCountsL, ~] = imhist(app.CurrentImage);
                    app.HistCountsR = []; app.HistCountsG = []; app.HistCountsB = []; % Vaciar histogramas de color
                    % Habilitar solo Brillo, deshabilitar los demás
                    if isprop(app, 'BrilloButton'); app.BrilloButton.Enable = 'on'; end
                    if isprop(app, 'RGBButton'); app.RGBButton.Enable = 'off'; end
                    if isprop(app, 'RButton'); app.RButton.Enable = 'off'; end
                    if isprop(app, 'GButton'); app.GButton.Enable = 'off'; end
                    if isprop(app, 'BButton'); app.BButton.Enable = 'off'; end
                    % Seleccionar 'Brillo' por defecto
                    if isprop(app, histogramButtonGroup) && isprop(app, 'BrilloButton'); app.(histogramButtonGroup).SelectedObject = app.BrilloButton; end
                end
            catch ME_hist
                uialert(app.UIFigure, sprintf('Error calculando histogramas: %s', ME_hist.message), 'Error de Cálculo');
                 % Deshabilitar botones si falla el cálculo
                 for i = 1:length(buttonGroupEnableCheck); if isprop(app, buttonGroupEnableCheck{i}); app.(buttonGroupEnableCheck{i}).Enable = 'off'; end; end
            end


            % Actualizar la vista del histograma inicial
            updateHistogramPlot(app); % Llama a la función auxiliar
            disp('Debug: Carga y cálculo completados.'); % Mensaje final simplificado
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % SelectionChangedFcn callback for HistogramButtonGroup
            updateHistogramPlot(app); % Llama a la función que redibuja
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CargarImagenButton = uibutton(app.UIFigure, 'push');
            app.CargarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagenButton.Text = 'Cargar Imagen';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.BrilloButton = uitogglebutton(app.ButtonGroup);
            app.BrilloButton.Tag = 'Brillo';
            app.BrilloButton.Text = 'Brillo';
            app.BrilloButton.Value = true;
            app.RGBButton = uitogglebutton(app.ButtonGroup);
            app.RGBButton.Tag = 'RGB';
            app.RGBButton.Text = 'RGB';
            app.RButton = uitogglebutton(app.ButtonGroup);
            app.RButton.Tag = 'R';
            app.RButton.Text = 'R';
            app.GButton = uitogglebutton(app.ButtonGroup);
            app.GButton.Tag = 'G';
            app.GButton.Text = 'G';
            app.BButton = uitogglebutton(app.ButtonGroup);
            app.BButton.Tag = 'B';
            app.BButton.Text = 'B';
            app.DimensionesEditFieldLabel = uilabel(app.UIFigure);
            app.DimensionesEditFieldLabel.Text = 'Dimensiones';
            app.DimensionesEditField = uieditfield(app.UIFigure, 'text');
            app.TamaoEditFieldLabel = uilabel(app.UIFigure);
            app.TamaoEditFieldLabel.Text = 'Tamaño';
            app.TamaoEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistogramaRGB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end