classdef appEntrene_y_mas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        EntrenarLnTab                   matlab.ui.container.Tab
        SalidadeseadaEditField_8        matlab.ui.control.EditField
        SalidadeseadaEditField_8Label   matlab.ui.control.Label
        PesosEditField_5                matlab.ui.control.EditField
        PesosEditField_5Label           matlab.ui.control.Label
        EntradaEditField_5              matlab.ui.control.EditField
        EntradaEditField_5Label         matlab.ui.control.Label
        ArquitecturaEditField_5         matlab.ui.control.EditField
        ArquitecturaEditField_5Label    matlab.ui.control.Label
        PrecisinEditField_5             matlab.ui.control.NumericEditField
        PrecisinEditField_5Label        matlab.ui.control.Label
        BetaEditField_5                 matlab.ui.control.NumericEditField
        BetaEditField_5Label            matlab.ui.control.Label
        AlphaEditField_5                matlab.ui.control.NumericEditField
        AlphaEditField_5Label           matlab.ui.control.Label
        ArquitecturaselectaPanel_5      matlab.ui.container.Panel
        VisualizararquitecturaButton_5  matlab.ui.control.Button
        SeleccionararchivoButton_25     matlab.ui.control.Button
        SeleccionararchivoButton_24     matlab.ui.control.Button
        SeleccionararchivoButton_23     matlab.ui.control.Button
        SeleccionararchivoButton_22     matlab.ui.control.Button
        SeleccionararchivoButton_21     matlab.ui.control.Button
        FuncionesDerivadasLabel_5       matlab.ui.control.Label
        FuncionesLabel_5                matlab.ui.control.Label
        EntrenarButton_5                matlab.ui.control.Button
        EntrenarTabla                   matlab.ui.container.Tab
        SalidadeseadaEditField_7        matlab.ui.control.EditField
        SalidadeseadaEditField_7Label   matlab.ui.control.Label
        PesosEditField_6                matlab.ui.control.EditField
        PesosEditField_6Label           matlab.ui.control.Label
        EntradaEditField_6              matlab.ui.control.EditField
        EntradaEditField_6Label         matlab.ui.control.Label
        ArquitecturaEditField_6         matlab.ui.control.EditField
        ArquitecturaEditField_6Label    matlab.ui.control.Label
        PrecisinEditField_6             matlab.ui.control.NumericEditField
        PrecisinEditField_6Label        matlab.ui.control.Label
        BetaEditField_6                 matlab.ui.control.NumericEditField
        BetaEditField_6Label            matlab.ui.control.Label
        AlphaEditField_6                matlab.ui.control.NumericEditField
        AlphaEditField_6Label           matlab.ui.control.Label
        ArquitecturaselectaPanel_6      matlab.ui.container.Panel
        VisualizararquitecturaButton_6  matlab.ui.control.Button
        SeleccionararchivoButton_30     matlab.ui.control.Button
        SeleccionararchivoButton_29     matlab.ui.control.Button
        SeleccionararchivoButton_28     matlab.ui.control.Button
        SeleccionararchivoButton_27     matlab.ui.control.Button
        SeleccionararchivoButton_26     matlab.ui.control.Button
        FuncionesDerivadasLabel_6       matlab.ui.control.Label
        FuncionesLabel_6                matlab.ui.control.Label
        EntrenarButton_6                matlab.ui.control.Button
        EntrenarOtracosaTab             matlab.ui.container.Tab
        PesosEditField_3                matlab.ui.control.EditField
        PesosEditField_3Label           matlab.ui.control.Label
        SalidadeseadaEditField_3        matlab.ui.control.EditField
        SalidadeseadaEditField_3Label   matlab.ui.control.Label
        EntradaEditField_3              matlab.ui.control.EditField
        EntradaEditField_3Label         matlab.ui.control.Label
        ArquitecturaEditField_3         matlab.ui.control.EditField
        ArquitecturaEditField_3Label    matlab.ui.control.Label
        PrecisinEditField_3             matlab.ui.control.NumericEditField
        PrecisinEditField_3Label        matlab.ui.control.Label
        BetaEditField_3                 matlab.ui.control.NumericEditField
        BetaEditField_3Label            matlab.ui.control.Label
        AlphaEditField_3                matlab.ui.control.NumericEditField
        AlphaEditField_3Label           matlab.ui.control.Label
        ArquitecturaselectaPanel_3      matlab.ui.container.Panel
        VisualizararquitecturaButton_3  matlab.ui.control.Button
        SeleccionararchivoButton_15     matlab.ui.control.Button
        SeleccionararchivoButton_14     matlab.ui.control.Button
        SeleccionararchivoButton_13     matlab.ui.control.Button
        SeleccionararchivoButton_12     matlab.ui.control.Button
        SeleccionararchivoButton_11     matlab.ui.control.Button
        FuncionesDerivadasLabel_3       matlab.ui.control.Label
        FuncionesLabel_3                matlab.ui.control.Label
        EntrenarButton_3                matlab.ui.control.Button
        AplicacionTab                   matlab.ui.container.Tab
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        RnaBpLn     BP_neuronal_network
        RnaBpTabla  BP_neuronal_network
        RnaBpOtra   BP_neuronal_network

        % --- Datos Entrenamiento (Separados por tipo) ---
        XTrainLn            
        YTrainLn            
        FuncStringsLn       % Cell array de strings {'fun1'; 'fun2'}
        DerivStringsLn      % Cell array de strings {'deriv1'; 'deriv2'}
        XTrainTabla         
        YTrainTabla         
        FuncStringsTabla    
        DerivStringsTabla   
        XTrainOtra          
        YTrainOtra          
        FuncStringsOtra     
        DerivStringsOtra    
    
        % --- Paths Archivos (Guardados desde la UI) ---
        OutputPathLn = 'pesos_Entrenamiento_Ln.txt' % Valor inicial ejemplo
        OutputPathTabla = 'pesos_Entrenamiento_Tabla.txt'
        OutputPathOtra = 'pesos_Entrenamiento_Otra.txt'
    
        % --- Arquitectura (Guardada como string para recargar) ---
        ArchLnStr = '[2 2 1]' % Valor inicial de tu screenshot
        ArchTablaStr = '[2 3 1]' % Ejemplo
        ArchOtraStr = '[4 5 2]' % Ejemplo
    
        % --- Estado Aplicación ---
        SelectedNetworkApp = 'Ln' % 'Ln', 'Tabla', 'Otra'
        WeightsLoadedApp = false
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EntrenarLnTab = uitab(app.TabGroup);
            app.EntrenarLnTab.Title = 'Entrenar Ln';
            app.EntrenarButton_5 = uibutton(app.EntrenarLnTab, 'push');
            app.EntrenarButton_5.Text = 'Entrenar';
            app.FuncionesLabel_5 = uilabel(app.EntrenarLnTab);
            app.FuncionesLabel_5.Text = 'Funciones';
            app.FuncionesDerivadasLabel_5 = uilabel(app.EntrenarLnTab);
            app.FuncionesDerivadasLabel_5.Text = {'Funciones '; 'Derivadas'};
            app.SeleccionararchivoButton_21 = uibutton(app.EntrenarLnTab, 'push');
            app.SeleccionararchivoButton_21.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_22 = uibutton(app.EntrenarLnTab, 'push');
            app.SeleccionararchivoButton_22.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_23 = uibutton(app.EntrenarLnTab, 'push');
            app.SeleccionararchivoButton_23.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_24 = uibutton(app.EntrenarLnTab, 'push');
            app.SeleccionararchivoButton_24.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_25 = uibutton(app.EntrenarLnTab, 'push');
            app.SeleccionararchivoButton_25.Text = 'Seleccionar archivo';
            app.VisualizararquitecturaButton_5 = uibutton(app.EntrenarLnTab, 'push');
            app.VisualizararquitecturaButton_5.Text = 'Visualizar arquitectura';
            app.ArquitecturaselectaPanel_5 = uipanel(app.EntrenarLnTab);
            app.ArquitecturaselectaPanel_5.Title = 'Arquitectura selecta';
            app.AlphaEditField_5Label = uilabel(app.EntrenarLnTab);
            app.AlphaEditField_5Label.Text = 'Alpha';
            app.AlphaEditField_5 = uieditfield(app.EntrenarLnTab, 'numeric');
            app.AlphaEditField_5.Placeholder = '0';
            app.BetaEditField_5Label = uilabel(app.EntrenarLnTab);
            app.BetaEditField_5Label.Text = 'Beta';
            app.BetaEditField_5 = uieditfield(app.EntrenarLnTab, 'numeric');
            app.BetaEditField_5.Placeholder = '0';
            app.PrecisinEditField_5Label = uilabel(app.EntrenarLnTab);
            app.PrecisinEditField_5Label.Text = 'Precisión';
            app.PrecisinEditField_5 = uieditfield(app.EntrenarLnTab, 'numeric');
            app.PrecisinEditField_5.Placeholder = '0';
            app.ArquitecturaEditField_5Label = uilabel(app.EntrenarLnTab);
            app.ArquitecturaEditField_5Label.Text = 'Arquitectura';
            app.ArquitecturaEditField_5 = uieditfield(app.EntrenarLnTab, 'text');
            app.ArquitecturaEditField_5.Placeholder = '[1 1 1]';
            app.EntradaEditField_5Label = uilabel(app.EntrenarLnTab);
            app.EntradaEditField_5Label.Text = 'Entrada';
            app.EntradaEditField_5 = uieditfield(app.EntrenarLnTab, 'text');
            app.EntradaEditField_5.Placeholder = 'C:\User\Documents\ejemplo.txt';
            app.PesosEditField_5Label = uilabel(app.EntrenarLnTab);
            app.PesosEditField_5Label.Text = 'Pesos';
            app.PesosEditField_5 = uieditfield(app.EntrenarLnTab, 'text');
            app.PesosEditField_5.Placeholder = 'C:\User\Documents\ejemplo.txt';
            app.SalidadeseadaEditField_8Label = uilabel(app.EntrenarLnTab);
            app.SalidadeseadaEditField_8Label.Text = 'Salida deseada';
            app.SalidadeseadaEditField_8 = uieditfield(app.EntrenarLnTab, 'text');
            app.SalidadeseadaEditField_8.Placeholder = 'C:\User\Documents\ejemplo.txt';
            app.EntrenarTabla = uitab(app.TabGroup);
            app.EntrenarTabla.Title = 'Entrenar Tabla';
            app.EntrenarButton_6 = uibutton(app.EntrenarTabla, 'push');
            app.EntrenarButton_6.Text = 'Entrenar';
            app.FuncionesLabel_6 = uilabel(app.EntrenarTabla);
            app.FuncionesLabel_6.Text = 'Funciones';
            app.FuncionesDerivadasLabel_6 = uilabel(app.EntrenarTabla);
            app.FuncionesDerivadasLabel_6.Text = {'Funciones '; 'Derivadas'};
            app.SeleccionararchivoButton_26 = uibutton(app.EntrenarTabla, 'push');
            app.SeleccionararchivoButton_26.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_27 = uibutton(app.EntrenarTabla, 'push');
            app.SeleccionararchivoButton_27.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_28 = uibutton(app.EntrenarTabla, 'push');
            app.SeleccionararchivoButton_28.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_29 = uibutton(app.EntrenarTabla, 'push');
            app.SeleccionararchivoButton_29.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_30 = uibutton(app.EntrenarTabla, 'push');
            app.SeleccionararchivoButton_30.Text = 'Seleccionar archivo';
            app.VisualizararquitecturaButton_6 = uibutton(app.EntrenarTabla, 'push');
            app.VisualizararquitecturaButton_6.Text = 'Visualizar arquitectura';
            app.ArquitecturaselectaPanel_6 = uipanel(app.EntrenarTabla);
            app.ArquitecturaselectaPanel_6.Title = 'Arquitectura selecta';
            app.AlphaEditField_6Label = uilabel(app.EntrenarTabla);
            app.AlphaEditField_6Label.Text = 'Alpha';
            app.AlphaEditField_6 = uieditfield(app.EntrenarTabla, 'numeric');
            app.AlphaEditField_6.Placeholder = '0';
            app.BetaEditField_6Label = uilabel(app.EntrenarTabla);
            app.BetaEditField_6Label.Text = 'Beta';
            app.BetaEditField_6 = uieditfield(app.EntrenarTabla, 'numeric');
            app.BetaEditField_6.Placeholder = '0';
            app.PrecisinEditField_6Label = uilabel(app.EntrenarTabla);
            app.PrecisinEditField_6Label.Text = 'Precisión';
            app.PrecisinEditField_6 = uieditfield(app.EntrenarTabla, 'numeric');
            app.PrecisinEditField_6.Placeholder = '0';
            app.ArquitecturaEditField_6Label = uilabel(app.EntrenarTabla);
            app.ArquitecturaEditField_6Label.Text = 'Arquitectura';
            app.ArquitecturaEditField_6 = uieditfield(app.EntrenarTabla, 'text');
            app.ArquitecturaEditField_6.Placeholder = '[1 1 1]';
            app.EntradaEditField_6Label = uilabel(app.EntrenarTabla);
            app.EntradaEditField_6Label.Text = 'Entrada';
            app.EntradaEditField_6 = uieditfield(app.EntrenarTabla, 'text');
            app.EntradaEditField_6.Placeholder = 'C:\User\Documents\ejemplo.txt';
            app.PesosEditField_6Label = uilabel(app.EntrenarTabla);
            app.PesosEditField_6Label.Text = 'Pesos';
            app.PesosEditField_6 = uieditfield(app.EntrenarTabla, 'text');
            app.PesosEditField_6.Placeholder = 'C:\User\Documents\ejemplo.txt';
            app.SalidadeseadaEditField_7Label = uilabel(app.EntrenarTabla);
            app.SalidadeseadaEditField_7Label.Text = 'Salida deseada';
            app.SalidadeseadaEditField_7 = uieditfield(app.EntrenarTabla, 'text');
            app.SalidadeseadaEditField_7.Placeholder = 'C:\User\Documents\ejemplo.txt';
            app.EntrenarOtracosaTab = uitab(app.TabGroup);
            app.EntrenarOtracosaTab.Title = 'Entrenar Otra cosa';
            app.EntrenarButton_3 = uibutton(app.EntrenarOtracosaTab, 'push');
            app.EntrenarButton_3.Text = 'Entrenar';
            app.FuncionesLabel_3 = uilabel(app.EntrenarOtracosaTab);
            app.FuncionesLabel_3.Text = 'Funciones';
            app.FuncionesDerivadasLabel_3 = uilabel(app.EntrenarOtracosaTab);
            app.FuncionesDerivadasLabel_3.Text = {'Funciones '; 'Derivadas'};
            app.SeleccionararchivoButton_11 = uibutton(app.EntrenarOtracosaTab, 'push');
            app.SeleccionararchivoButton_11.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_12 = uibutton(app.EntrenarOtracosaTab, 'push');
            app.SeleccionararchivoButton_12.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_13 = uibutton(app.EntrenarOtracosaTab, 'push');
            app.SeleccionararchivoButton_13.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_14 = uibutton(app.EntrenarOtracosaTab, 'push');
            app.SeleccionararchivoButton_14.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_15 = uibutton(app.EntrenarOtracosaTab, 'push');
            app.SeleccionararchivoButton_15.Text = 'Seleccionar archivo';
            app.VisualizararquitecturaButton_3 = uibutton(app.EntrenarOtracosaTab, 'push');
            app.VisualizararquitecturaButton_3.Text = 'Visualizar arquitectura';
            app.ArquitecturaselectaPanel_3 = uipanel(app.EntrenarOtracosaTab);
            app.ArquitecturaselectaPanel_3.Title = 'Arquitectura selecta';
            app.AlphaEditField_3Label = uilabel(app.EntrenarOtracosaTab);
            app.AlphaEditField_3Label.Text = 'Alpha';
            app.AlphaEditField_3 = uieditfield(app.EntrenarOtracosaTab, 'numeric');
            app.AlphaEditField_3.Placeholder = '0';
            app.BetaEditField_3Label = uilabel(app.EntrenarOtracosaTab);
            app.BetaEditField_3Label.Text = 'Beta';
            app.BetaEditField_3 = uieditfield(app.EntrenarOtracosaTab, 'numeric');
            app.BetaEditField_3.Placeholder = '0';
            app.PrecisinEditField_3Label = uilabel(app.EntrenarOtracosaTab);
            app.PrecisinEditField_3Label.Text = 'Precisión';
            app.PrecisinEditField_3 = uieditfield(app.EntrenarOtracosaTab, 'numeric');
            app.PrecisinEditField_3.Placeholder = '0';
            app.ArquitecturaEditField_3Label = uilabel(app.EntrenarOtracosaTab);
            app.ArquitecturaEditField_3Label.Text = 'Arquitectura';
            app.ArquitecturaEditField_3 = uieditfield(app.EntrenarOtracosaTab, 'text');
            app.ArquitecturaEditField_3.Placeholder = '[1 1 1]';
            app.EntradaEditField_3Label = uilabel(app.EntrenarOtracosaTab);
            app.EntradaEditField_3Label.Text = 'Entrada';
            app.EntradaEditField_3 = uieditfield(app.EntrenarOtracosaTab, 'text');
            app.EntradaEditField_3.Placeholder = 'C:\User\Documents\ejemplo.txt';
            app.SalidadeseadaEditField_3Label = uilabel(app.EntrenarOtracosaTab);
            app.SalidadeseadaEditField_3Label.Text = 'Salida deseada';
            app.SalidadeseadaEditField_3 = uieditfield(app.EntrenarOtracosaTab, 'text');
            app.SalidadeseadaEditField_3.Placeholder = 'C:\User\Documents\ejemplo.txt';
            app.PesosEditField_3Label = uilabel(app.EntrenarOtracosaTab);
            app.PesosEditField_3Label.Text = 'Pesos';
            app.PesosEditField_3 = uieditfield(app.EntrenarOtracosaTab, 'text');
            app.PesosEditField_3.Placeholder = 'C:\User\Documents\ejemplo.txt';
            app.AplicacionTab = uitab(app.TabGroup);
            app.AplicacionTab.Title = 'Aplicacion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appEntrene_y_mas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end